classdef VDMMBESP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VDMMSMUIFigure                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SingleModelCalculationTab       matlab.ui.container.Tab
        ResultsPanel                    matlab.ui.container.Panel
        FCDistributionPanel_2           matlab.ui.container.Panel
        UIAxes_SFCD                     matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        VIntactVTab                     matlab.ui.container.Tab
        UITable_Data_SVW                matlab.ui.control.Table
        VPittedVTab                     matlab.ui.container.Tab
        UITable_Data_SV                 matlab.ui.control.Table
        FCValuesTab                     matlab.ui.container.Tab
        UITable_Data_SFC                matlab.ui.control.Table
        CurrentDensityDistributionPanel  matlab.ui.container.Panel
        UIAxes_SMFCD                    matlab.ui.control.UIAxes
        PotentialDistributionPanel      matlab.ui.container.Panel
        UIAxes_SVD                      matlab.ui.control.UIAxes
        ParameterSettingsPanel          matlab.ui.container.Panel
        PhysicalfieldparametersPanel    matlab.ui.container.Panel
        MeshsizeDropDown                matlab.ui.control.DropDown
        MeshsizeDropDownLabel           matlab.ui.control.Label
        CurrentSpinner                  matlab.ui.control.Spinner
        CurrentSpinnerLabel             matlab.ui.control.Label
        ConductivitySpinner             matlab.ui.control.Spinner
        ConductivitySpinnerLabel        matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        SmLabel                         matlab.ui.control.Label
        MatrixparametersPanel           matlab.ui.container.Panel
        SettingButton_2                 matlab.ui.control.StateButton
        LayoutDropDown                  matlab.ui.control.DropDown
        LayoutDropDownLabel             matlab.ui.control.Label
        SpacingSpinner                  matlab.ui.control.Spinner
        SpacingSpinnerLabel             matlab.ui.control.Label
        mmLabel_41                      matlab.ui.control.Label
        ColumnsSpinner                  matlab.ui.control.Spinner
        ColumnsSpinnerLabel             matlab.ui.control.Label
        mmLabel_40                      matlab.ui.control.Label
        RowsSpinner                     matlab.ui.control.Spinner
        RowsSpinnerLabel                matlab.ui.control.Label
        mmLabel_39                      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        PitparametersPanel_2            matlab.ui.container.Panel
        DepthSpinner                    matlab.ui.control.Spinner
        DepthSpinnerLabel               matlab.ui.control.Label
        RadiusSpinner                   matlab.ui.control.Spinner
        RadiusSpinnerLabel              matlab.ui.control.Label
        mmLabel_34                      matlab.ui.control.Label
        mmLabel_33                      matlab.ui.control.Label
        PipelineparametersPanel         matlab.ui.container.Panel
        ThicknessSpinner                matlab.ui.control.Spinner
        ThicknessSpinnerLabel           matlab.ui.control.Label
        mmLabel_38                      matlab.ui.control.Label
        RadiusSpinner_pipe              matlab.ui.control.Spinner
        RadiusSpinner_2Label            matlab.ui.control.Label
        mmLabel_37                      matlab.ui.control.Label
        LengthSpinner                   matlab.ui.control.Spinner
        LengthSpinnerLabel              matlab.ui.control.Label
        mmLabel_36                      matlab.ui.control.Label
        BatchModelCalculationTab        matlab.ui.container.Tab
        ResultsPanel_2                  matlab.ui.container.Panel
        UITable_showalldata             matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        Panel_matrix_2                  matlab.ui.container.Panel
        FileSavePathPanel               matlab.ui.container.Panel
        EditField_ReportName            matlab.ui.control.EditField
        ReportNameLabel                 matlab.ui.control.Label
        EditField_FileName              matlab.ui.control.EditField
        FileNameLabel                   matlab.ui.control.Label
        Button_openFolder               matlab.ui.control.Button
        EditField_folderPath            matlab.ui.control.EditField
        PathLabel                       matlab.ui.control.Label
        ExportOptionsPanel              matlab.ui.container.Panel
        SaveReportCheckBox              matlab.ui.control.CheckBox
        SelectAllCheckBox               matlab.ui.control.CheckBox
        SavetoDatabaseCheckBox          matlab.ui.control.CheckBox
        ExporttoMATfileCheckBox         matlab.ui.control.CheckBox
        ExporttoExcelCheckBox           matlab.ui.control.CheckBox
        DatabaseConfigurationPanel      matlab.ui.container.Panel
        StatusLabel_2                   matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        EditField_connectName_2         matlab.ui.control.EditField
        DataSourceLabel                 matlab.ui.control.Label
        EditField_pwd_2                 matlab.ui.control.EditField
        PasswordLabel                   matlab.ui.control.Label
        EditField_user_2                matlab.ui.control.EditField
        UsernameLabel                   matlab.ui.control.Label
        Button_ConnectMySQL_2           matlab.ui.control.Button
        Panel_par1                      matlab.ui.container.Panel
        Button_startCalculate           matlab.ui.control.Button
        Panel_matrix                    matlab.ui.container.Panel
        SettingButton                   matlab.ui.control.StateButton
        LayoutDropDown_2                matlab.ui.control.DropDown
        LayoutDropDown_2Label           matlab.ui.control.Label
        SpacingEditField                matlab.ui.control.EditField
        SpacingEditFieldLabel           matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        ColumnsEditField                matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel           matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        RowsLabel                       matlab.ui.control.Label
        CalculationModeButtonGroup      matlab.ui.container.ButtonGroup
        CombinationButton               matlab.ui.control.RadioButton
        FullScanButton                  matlab.ui.control.RadioButton
        PitsparametersPanel             matlab.ui.container.Panel
        EnableScanCheckBox              matlab.ui.control.CheckBox
        mmLabel_8                       matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        DepthEditField                  matlab.ui.control.EditField
        DepthEditFieldLabel             matlab.ui.control.Label
        RadiusEditField_3               matlab.ui.control.EditField
        RadiusEditField_3Label          matlab.ui.control.Label
        PipelineparametersPanel_2       matlab.ui.container.Panel
        mmLabel_29                      matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.EditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.EditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.EditField
        LengthEditFieldLabel            matlab.ui.control.Label
        DataAnalysisTab                 matlab.ui.container.Tab
        DataAnalysisPanel               matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        SingleModelDataTab              matlab.ui.container.Tab
        ResultsPanel_5                  matlab.ui.container.Panel
        UITable_smd                     matlab.ui.control.Table
        UIAxes_smd                      matlab.ui.control.UIAxes
        SettingsPanel_4                 matlab.ui.container.Panel
        OKButton_SMD                    matlab.ui.control.Button
        SpacingDropDown                 matlab.ui.control.DropDown
        SpacingDropDownLabel            matlab.ui.control.Label
        PithDropDown                    matlab.ui.control.DropDown
        PithDropDownLabel               matlab.ui.control.Label
        PitrDropDown                    matlab.ui.control.DropDown
        PitrDropDownLabel               matlab.ui.control.Label
        PipeTDropDown                   matlab.ui.control.DropDown
        PipeTDropDownLabel              matlab.ui.control.Label
        PipeRDropDown                   matlab.ui.control.DropDown
        PipeRDropDownLabel              matlab.ui.control.Label
        PipeLDropDown                   matlab.ui.control.DropDown
        PipeLDropDownLabel              matlab.ui.control.Label
        CorrelationAnalysisTab          matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        HeatmapTab                      matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        CoefficientTab                  matlab.ui.container.Tab
        UITable4                        matlab.ui.control.Table
        SettingsPanel                   matlab.ui.container.Panel
        OKButton_CA                     matlab.ui.control.Button
        InputColumnsPanel               matlab.ui.container.Panel
        Tree_CA                         matlab.ui.container.CheckBoxTree
        CorrelationTypeDropDown         matlab.ui.control.DropDown
        CorrelationTypeDropDownLabel    matlab.ui.control.Label
        UnivariateAnalysisTab           matlab.ui.container.Tab
        SettingsPanel_2                 matlab.ui.container.Panel
        OKButton_SMD_3                  matlab.ui.control.Button
        FittingResultTextArea           matlab.ui.control.TextArea
        FittingResultTextAreaLabel      matlab.ui.control.Label
        ProcessingDropDown              matlab.ui.control.DropDown
        ProcessingDropDownLabel         matlab.ui.control.Label
        FittingMethodDropDown           matlab.ui.control.DropDown
        FittingMethodDropDownLabel      matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        YDropDown                       matlab.ui.control.DropDown
        YDropDownLabel                  matlab.ui.control.Label
        XDropDown                       matlab.ui.control.DropDown
        XDropDownLabel                  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        MultivariateAnalysisTab         matlab.ui.container.Tab
        ResultsPanel_4                  matlab.ui.container.Panel
        UIAxes7                         matlab.ui.control.UIAxes
        SettingsPanel_3                 matlab.ui.container.Panel
        OKButton_SMD_4                  matlab.ui.control.Button
        YDropDown_3                     matlab.ui.control.DropDown
        YDropDown_3Label                matlab.ui.control.Label
        FittingResultTextArea_2         matlab.ui.control.TextArea
        FittingResultTextArea_2Label    matlab.ui.control.Label
        ProcessingDropDown_2            matlab.ui.control.DropDown
        ProcessingDropDown_2Label       matlab.ui.control.Label
        FittingMethodDropDown_2         matlab.ui.control.DropDown
        FittingMethodDropDown_2Label    matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        X2DropDown                      matlab.ui.control.DropDown
        X2DropDownLabel                 matlab.ui.control.Label
        X1DropDown                      matlab.ui.control.DropDown
        X1DropDownLabel                 matlab.ui.control.Label
        DataSelectionPanel              matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        DataRangeDropDown               matlab.ui.control.DropDown
        DataRangeDropDownLabel          matlab.ui.control.Label
        UITable_showselectdata          matlab.ui.control.Table
        SourceDropDown                  matlab.ui.control.DropDown
        SourceLabel                     matlab.ui.control.Label
        PredictionModelConstructionTab  matlab.ui.container.Tab
        SettingPanel                    matlab.ui.container.Panel
        ModelSelectPanel                matlab.ui.container.Panel
        NTrainCheckBox                  matlab.ui.control.CheckBox
        NCheckBox                       matlab.ui.control.CheckBox
        SDSpinner                       matlab.ui.control.Spinner
        SDLabel                         matlab.ui.control.Label
        TabGroup6                       matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        BPTab                           matlab.ui.container.Tab
        epochsSpinner                   matlab.ui.control.Spinner
        epochsSpinnerLabel              matlab.ui.control.Label
        goalSpinner                     matlab.ui.control.Spinner
        goalSpinnerLabel                matlab.ui.control.Label
        lrSpinner                       matlab.ui.control.Spinner
        lrSpinnerLabel                  matlab.ui.control.Label
        hiddenNumSpinner                matlab.ui.control.Spinner
        hiddenNumLabel                  matlab.ui.control.Label
        RFTab                           matlab.ui.container.Tab
        MaxNumSplitsSpinner             matlab.ui.control.Spinner
        MaxNumSplitsSpinnerLabel        matlab.ui.control.Label
        nTreesSpinner                   matlab.ui.control.Spinner
        nTreesSpinnerLabel              matlab.ui.control.Label
        MinLeafSizeSpinner              matlab.ui.control.Spinner
        MinLeafSizeSpinnerLabel         matlab.ui.control.Label
        GBDTTab_2                       matlab.ui.container.Tab
        NumLearningCyclesSpinner        matlab.ui.control.Spinner
        NumLearningCyclesSpinnerLabel   matlab.ui.control.Label
        MinLeafSizeSpinner_2            matlab.ui.control.Spinner
        MinLeafSizeSpinner_2Label       matlab.ui.control.Label
        LearnRateSpinner                matlab.ui.control.Spinner
        LearnRateSpinnerLabel           matlab.ui.control.Label
        SERTab                          matlab.ui.container.Tab
        NumSpinner                      matlab.ui.control.Spinner
        NumSpinnerLabel                 matlab.ui.control.Label
        UITable5                        matlab.ui.control.Table
        ProcessingDropDown_3            matlab.ui.control.DropDown
        ProcessingDropDown_3Label       matlab.ui.control.Label
        ModelStructureFlowchartTab      matlab.ui.container.Tab
        ModelAxes                       matlab.ui.control.UIAxes
        TrainButton                     matlab.ui.control.Button
        TRatioSpinner                   matlab.ui.control.Spinner
        TRatioSpinnerLabel              matlab.ui.control.Label
        ModelsDropDown                  matlab.ui.control.DropDown
        ModelsDropDownLabel             matlab.ui.control.Label
        LoadDataPanel                   matlab.ui.container.Panel
        LoadButton_2                    matlab.ui.control.Button
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        InputDataXPanel                 matlab.ui.container.Panel
        Tree                            matlab.ui.container.CheckBoxTree
        OutputDataYPanel                matlab.ui.container.Panel
        Tree_2                          matlab.ui.container.CheckBoxTree
        ResultsPanel_3                  matlab.ui.container.Panel
        MetricResultPanel               matlab.ui.container.Panel
        SaveTrainedModelButton          matlab.ui.control.Button
        UITable2_2                      matlab.ui.control.Table
        Panel_showfitfig                matlab.ui.container.Panel
        PredictionResultsPanel          matlab.ui.container.Panel
        SaveResultsButton               matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        StatusLabel                     matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        ProgressPanel                   matlab.ui.container.Panel
        ProgressFg                      matlab.ui.control.Label
        ProgressBg                      matlab.ui.control.Label
        Button_cancel                   matlab.ui.control.Button
    end


    properties (Access = private)
        ResultsTable
        SkippedTable
        DataSummaryFolder
        cellArrayText_2 = {}
        ModelTypeNum
        conn
        IsUpdatingExportOptions = false
        ElectrodeCoordData
        ElectrodeCoordDataBatch
        SelectData
        PredTrainedModel
        PredFitAxes
        CancelRequested = false
        IsCalculating = false
        CanSimulate = false
    end

    methods (Access = private)

        function out = figweifushi(app)
            import com.comsol.model.*
            import com.comsol.model.util.*
            model = ModelUtil.create('Model_W');
            model.param.set('pipes', '5[mm]');
            model.param.set('piper', '50[mm]');
            model.param.set('length', '1000[mm]');
            model.param.set('current', '30[A]');

            model.component.create('comp1', true);

            model.component('comp1').geom.create('geom1', 3);

            model.component('comp1').mesh.create('mesh1');

            model.component('comp1').geom('geom1').lengthUnit('mm');
            model.component('comp1').geom('geom1').create('cyl1', 'Cylinder');
            model.component('comp1').geom('geom1').feature('cyl1').set('axis', [1 0 0]);
            model.component('comp1').geom('geom1').feature('cyl1').set('r', 'piper');
            model.component('comp1').geom('geom1').feature('cyl1').set('h', 'length');
            model.component('comp1').geom('geom1').create('cyl2', 'Cylinder');
            model.component('comp1').geom('geom1').feature('cyl2').set('axis', [1 0 0]);
            model.component('comp1').geom('geom1').feature('cyl2').set('r', 'piper-pipes');
            model.component('comp1').geom('geom1').feature('cyl2').set('h', 'length');
            model.component('comp1').geom('geom1').create('dif1', 'Difference');
            model.component('comp1').geom('geom1').feature('dif1').selection('input').set({'cyl1'});
            model.component('comp1').geom('geom1').feature('dif1').selection('input2').set({'cyl2'});
            model.component('comp1').geom('geom1').create('cyl3', 'Cylinder');
            model.component('comp1').geom('geom1').feature('cyl3').set('pos', {'20' '0' 'piper-1'});
            model.component('comp1').geom('geom1').feature('cyl3').set('r', 3);
            model.component('comp1').geom('geom1').feature('cyl3').set('h', 7);
            model.component('comp1').geom('geom1').create('mir1', 'Mirror');
            model.component('comp1').geom('geom1').feature('mir1').set('keep', true);
            model.component('comp1').geom('geom1').feature('mir1').set('pos', {'length/2' '0' '0'});
            model.component('comp1').geom('geom1').feature('mir1').set('axis', [1 0 0]);
            model.component('comp1').geom('geom1').feature('mir1').selection('input').set({'cyl3'});
            model.component('comp1').geom('geom1').run;
            model.component('comp1').geom('geom1').run('fin');

            model.view.create('view2', 2);

            model.component('comp1').material.create('mat1', 'Common');
            model.component('comp1').material('mat1').info.create('UNS');
            model.component('comp1').material('mat1').info.create('WNR');
            model.component('comp1').material('mat1').info.create('EN_DIN');
            model.component('comp1').material('mat1').info.create('JIS');
            model.component('comp1').material('mat1').info.create('ASTM');
            model.component('comp1').material('mat1').info.create('AISI');
            model.component('comp1').material('mat1').info.create('SAE');
            model.component('comp1').material('mat1').info.create('AMS');
            model.component('comp1').material('mat1').info.create('AFNOR');
            model.component('comp1').material('mat1').info.create([native2unicode(hex2dec({'7e' 'c4'}), 'unicode')  native2unicode(hex2dec({'52' '06'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('def').func.create('k', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('res', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('alpha', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('C', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('sigma', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('rho', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('TD', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup.create('ThermalExpansion', [native2unicode(hex2dec({'70' 'ed'}), 'unicode')  native2unicode(hex2dec({'81' 'a8'}), 'unicode')  native2unicode(hex2dec({'80' 'c0'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func.create('dL', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func.create('CTE', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup.create('Enu', [native2unicode(hex2dec({'67' '68'}), 'unicode')  native2unicode(hex2dec({'6c' '0f'}), 'unicode')  native2unicode(hex2dec({'6a' '21'}), 'unicode')  native2unicode(hex2dec({'91' 'cf'}), 'unicode')  native2unicode(hex2dec({'54' '8c'}), 'unicode')  native2unicode(hex2dec({'6c' 'ca'}), 'unicode')  native2unicode(hex2dec({'67' '7e'}), 'unicode')  native2unicode(hex2dec({'6b' 'd4'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('Enu').func.create('E', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('Enu').func.create('nu', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup.create('KG', [native2unicode(hex2dec({'4f' '53'}), 'unicode')  native2unicode(hex2dec({'79' 'ef'}), 'unicode')  native2unicode(hex2dec({'6a' '21'}), 'unicode')  native2unicode(hex2dec({'91' 'cf'}), 'unicode')  native2unicode(hex2dec({'54' '8c'}), 'unicode')  native2unicode(hex2dec({'52' '6a'}), 'unicode')  native2unicode(hex2dec({'52' '07'}), 'unicode')  native2unicode(hex2dec({'6a' '21'}), 'unicode')  native2unicode(hex2dec({'91' 'cf'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('KG').func.create('mu', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('KG').func.create('kappa', 'Piecewise');

            model.component('comp1').physics.create('ec', 'ConductiveMedia', 'geom1');
            model.component('comp1').physics('ec').create('term1', 'DomainTerminal', 3);
            model.component('comp1').physics('ec').feature('term1').selection.set([3]);
            model.component('comp1').physics('ec').create('gnd1', 'Ground', 2);
            model.component('comp1').physics('ec').feature('gnd1').selection.set([29]);

            model.component('comp1').mesh('mesh1').autoMeshSize(getAutoMeshSize(app));

            model.component('comp1').material('mat1').label('1020 [solid,annealed]');
            model.component('comp1').material('mat1').set('family', 'iron');
            model.component('comp1').material('mat1').set('info', {'UNS' '' 'G10200';  ...
                'WNR' '' '1.0402';  ...
                'EN_DIN' '' 'C22/CK22';  ...
                'JIS' '' 'S20C/S22C/SWRCH20A';  ...
                'ASTM' '' 'A510/A519/A544/A576/A659';  ...
                'AISI' '' '1020';  ...
                'SAE' '' 'J403/J412/J414';  ...
                'AMS' '' '5032/5045';  ...
                'AFNOR' '' 'S20C/S22C/SWRCH20A';  ...
                [native2unicode(hex2dec({'7e' 'c4'}), 'unicode')  native2unicode(hex2dec({'52' '06'}), 'unicode') ] '' 'bal. Fe, (0.18-0.23) C, (0.3-0.6) Mn, 0.04 P max, 0.05 S max (wt%)'});
            model.component('comp1').material('mat1').propertyGroup('def').func('k').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('pieces', {'26.0' '200.0' '-5.435137+1.615324*T^1-0.01440223*T^2+6.259829E-5*T^3-1.325386E-7*T^4+1.087384E-10*T^5'; '200.0' '1073.16' '61.1315+0.05272897*T^1-2.040655E-4*T^2+2.105727E-7*T^3-8.423301E-11*T^4'});
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('fununit', 'W/(m*K)');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('pieces', {'293.0' '1073.0' '9.391673E-8+7.568236E-12*T^1+8.641318E-13*T^2'; '1073.0' '1573.16' '-3.625E-7+2.652646E-9*T^1-1.562286E-12*T^2+3.333333E-16*T^3'});
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('fununit', 'ohm*m');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').label('Piecewise 2');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('pieces', {'0.0' '680.0' '6.731035E-6+3.918624E-8*T^1-1.534406E-10*T^2+3.517965E-13*T^3-3.827522E-16*T^4+1.565546E-19*T^5';  ...
                '680.0' '977.0' '8.614185E-6+1.013631E-8*T^1-2.26181E-12*T^2-1.569696E-15*T^3';  ...
                '977.0' '1061.0' '6.292031E-5-4.915637E-8*T^1';  ...
                '1061.0' '1144.0' '-1.563678E-6+1.162025E-8*T^1'});
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('fununit', '1/K');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').label('Piecewise 3');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('pieces', {'293.0' '848.16' '-215.730638+6.0184999*T^1-0.0183429321*T^2+2.414973E-5*T^3-1.07882432E-8*T^4'});
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('fununit', 'J/(kg*K)');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').label('Piecewise 4');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('pieces', {'293.0' '1073.0' '1/(8.641318E-13*T^2+7.568236E-12*T+9.391673E-08)'; '1073.0' '1573.16' '1/(3.333333E-16*T^3-1.562286E-12*T^2+2.652646E-09*T-3.625E-07)'});
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('fununit', 'S/m');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').label('Piecewise 5');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('pieces', {'0.0' '60.0' '7907.978-0.01549395*T^1';  ...
                '60.0' '977.0' '7911.3-0.01678428*T^1-8.018711E-4*T^2+1.172796E-6*T^3-1.015971E-9*T^4+3.677737E-13*T^5';  ...
                '977.0' '1061.0' '7116.994+0.5195388*T^1';  ...
                '1061.0' '1144.0' '8166.523-0.4696497*T^1'});
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('fununit', 'kg/m^3');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').label('Piecewise 6');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('pieces', {'295.0' '1048.0' '1.908565E-5-1.445398E-8*T^1'; '1048.0' '1248.16' '-6.844E-5+1.158517E-7*T^1-4.46478E-11*T^2'});
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('fununit', 'm^2/s');
            model.component('comp1').material('mat1').propertyGroup('def').set('thermalconductivity', {'k(T)' '0' '0' '0' 'k(T)' '0' '0' '0' 'k(T)'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:thermalconductivity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': E.A. Eldridge and H.W. Deem, Report on Physical Properties of Metals and Alloys from Cryogenic to Elevated Temperatures, ASTM, Special Technical Publication No. 296 (1961) https://doi.org/10.1520/STP296-EB and High-Temperature Property Data: Ferrous Alloys, Editor M.F. Rothman, ASM International (1988) and Engineering Properties of Steel, P.D. Harvey, ASM (1982)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': data above 20 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (293 K) is from 1023 and multiplied by 1.2 to match the low temperature data']);
            model.component('comp1').material('mat1').propertyGroup('def').set('resistivity', {'res(T)' '0' '0' '0' 'res(T)' '0' '0' '0' 'res(T)'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:resistivity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': ASM Handbook, v1, Properties and Selection: Irons, Steels, and High-Performance Alloys, 10th edition, ASM International (1990)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values, annealed']);
            model.component('comp1').material('mat1').propertyGroup('def').set('thermalexpansioncoefficient', {'(alpha(T)+(Tempref-293[K])*if(abs(T-Tempref)>1e-3,(alpha(T)-alpha(Tempref))/(T-Tempref),d(alpha(T),T)))/(1+alpha(Tempref)*(Tempref-293[K]))' '0' '0' '0' '(alpha(T)+(Tempref-293[K])*if(abs(T-Tempref)>1e-3,(alpha(T)-alpha(Tempref))/(T-Tempref),d(alpha(T),T)))/(1+alpha(Tempref)*(Tempref-293[K]))' '0' '0' '0' '(alpha(T)+(Tempref-293[K])*if(abs(T-Tempref)>1e-3,(alpha(T)-alpha(Tempref))/(T-Tempref),d(alpha(T),T)))/(1+alpha(Tempref)*(Tempref-293[K]))'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:thermalexpansioncoefficient', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': the reference temperature is 20 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (293 K), same data as 1025 steel' newline  native2unicode(hex2dec({'53' 'c2'}), 'unicode')  native2unicode(hex2dec({'80' '03'}), 'unicode')  native2unicode(hex2dec({'6e' '29'}), 'unicode')  native2unicode(hex2dec({'5e' 'a6'}), 'unicode') ': 293.00[K]']);
            model.component('comp1').material('mat1').propertyGroup('def').set('heatcapacity', 'C(T)');
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:heatcapacity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': ASM Handbook, v1, Properties and Selection: Irons, Steels, and High-Performance Alloys, 10th edition, ASM International (1990)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values, annealed']);
            model.component('comp1').material('mat1').propertyGroup('def').set('electricconductivity', {'1450000' '0' '0' '0' '1450000' '0' '0' '0' '1450000'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:electricconductivity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': ASM Handbook, v1, Properties and Selection: Irons, Steels, and High-Performance Alloys, 10th edition, ASM International (1990)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values, annealed, calculated as the reciprocal of the resistivity']);
            model.component('comp1').material('mat1').propertyGroup('def').set('density', 'rho(T)');
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:density', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': calculated from the linear expansion and the room temperature density']);
            model.component('comp1').material('mat1').propertyGroup('def').set('TD', 'TD(T)');
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:TD', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': C.P. Butler and E.C.Y. Inn, Thermal Diffusivity of Metals at elevated Temperatures, in Thermodynamic and Transport Properties of Gases, Liquids and Solids, ASME Symposium on Thermal Properties, Mc-Graw-Hill Book Co., p377-90 (1959)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': hot-rolled, 5% to 10% error']);
            model.component('comp1').material('mat1').propertyGroup('def').set('relpermittivity', {'1E6' '0' '0' '0' '1E6' '0' '0' '0' '1E6'});
            model.component('comp1').material('mat1').propertyGroup('def').addInput('temperature');
            model.component('comp1').material('mat1').propertyGroup('def').addInput('strainreferencetemperature');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').set('pieces', {'0.0' '50.0' '-0.002022973+3.918919E-7*T^1';  ...
                '50.0' '212.0' '-0.002063267-3.508062E-7*T^1+2.838477E-8*T^2';  ...
                '212.0' '977.0' '-0.00252395773+5.644256E-6*T^1+1.0799E-8*T^2-1.801872E-12*T^3-1.569701E-15*T^4';  ...
                '977.0' '1061.0' '0.03264232-2.297313E-5*T^1';  ...
                '1061.0' '1144.0' '-0.01351301+2.053771E-5*T^1'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('pieces', {'293.0' '1065.0' '5.044861E-6+2.5188E-8*T^1-1.418195E-11*T^2'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('fununit', '1/K');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('alphatan', {'CTE(T)' '0' '0' '0' 'CTE(T)' '0' '0' '0' 'CTE(T)'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('INFO_PREFIX:alphatan', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': same data as 1025 steel, calculated from the mean coefficient of thermal expansion']);
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('dL', {'(dL(T)-dL(Tempref))/(1+dL(Tempref))' '0' '0' '0' '(dL(T)-dL(Tempref))/(1+dL(Tempref))' '0' '0' '0' '(dL(T)-dL(Tempref))/(1+dL(Tempref))'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('INFO_PREFIX:dL', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': the reference temperature is 20 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (293 K), same data as 1025 steel, calculated from the mean coefficient of thermal expansion' newline  native2unicode(hex2dec({'53' 'c2'}), 'unicode')  native2unicode(hex2dec({'80' '03'}), 'unicode')  native2unicode(hex2dec({'6e' '29'}), 'unicode')  native2unicode(hex2dec({'5e' 'a6'}), 'unicode') ': 293.00[K]']);
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').addInput('temperature');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').addInput('strainreferencetemperature');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('pieces', {'4.0' '273.0' '2.217366E11+5020008.0*T^1-305140.4*T^2+926.6601*T^3-1.145454*T^4'; '273.0' '1050.0' '2.11026989E11+3.572844E7*T^1-106319.6*T^2'; '1050.0' '1500.0' '2.02449497E11-6.77381E7*T^1'});
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('fununit', 'Pa');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').set('pieces', {'4.0' '120.0' '0.2850355-1.662951E-6*T^1';  ...
                '120.0' '273.0' '0.28474914-7.147353E-6*T^1+6.558945E-8*T^2';  ...
                '273.0' '1053.0' '0.271114512+7.030261E-5*T^1-3.856929E-8*T^2+1.246582E-11*T^3';  ...
                '1053.0' '1500.0' '0.316398425-1.242823E-6*T^1+1.661461E-9*T^2'});
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('Enu').set('E', 'E(T)');
            model.component('comp1').material('mat1').propertyGroup('Enu').set('INFO_PREFIX:E', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values']);
            model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', 'nu(T)');
            model.component('comp1').material('mat1').propertyGroup('Enu').set('INFO_PREFIX:nu', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values']);
            model.component('comp1').material('mat1').propertyGroup('Enu').addInput('temperature');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('pieces', {'4.0' '273.0' '8.626526E10+1636497.0*T^1-108981.6*T^2+291.1261*T^3-0.3377859*T^4'; '273.0' '1050.0' '8.30237093E10+9184755.0*T^1-38834.5*T^2'; '1050.0' '1500.0' '7.69464143E10-2.580357E7*T^1'});
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('fununit', 'Pa');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('pieces', {'4.0' '100.0' '1.800573E11-3478717.0*T^1+151512.1*T^2-5485.327*T^3+28.28329*T^4'; '100.0' '273.0' '1.81842391E11-4.023792E7*T^1+84204.68*T^2-93.09453*T^3'; '273.0' '1500.0' '1.84220483E11-2.509462E7*T^1-28588.37*T^2'});
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('fununit', 'Pa');
            model.component('comp1').material('mat1').propertyGroup('KG').set('K', 'kappa(T)');
            model.component('comp1').material('mat1').propertyGroup('KG').set('INFO_PREFIX:K', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values and were increased by 6% to match the high temperature values']);
            model.component('comp1').material('mat1').propertyGroup('KG').set('G', 'mu(T)');
            model.component('comp1').material('mat1').propertyGroup('KG').set('INFO_PREFIX:G', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values']);
            model.component('comp1').material('mat1').propertyGroup('KG').addInput('temperature');

            model.component('comp1').physics('ec').feature('term1').set('I0', 'current');
            model.study.create('std1');
            model.study('std1').create('stat', 'Stationary');

            out = model;
        end

        function out = figpit(app)

            import com.comsol.model.*
            import com.comsol.model.util.*
            model = ModelUtil.create('Model_P');
            model.param.set('pipes', '5[mm]');
            model.param.set('piper', '50[mm]');
            model.param.set('pitr', '3[mm]');
            model.param.set('pith', '3[mm]');
            model.param.set('length', '1000[mm]');
            model.param.set('current', '50[A]');

            model.component.create('comp1', true);

            model.component('comp1').geom.create('geom1', 3);

            model.component('comp1').mesh.create('mesh1');

            model.component('comp1').geom('geom1').lengthUnit('mm');
            model.component('comp1').geom('geom1').create('cyl1', 'Cylinder');
            model.component('comp1').geom('geom1').feature('cyl1').set('axis', [1 0 0]);
            model.component('comp1').geom('geom1').feature('cyl1').set('r', 'piper');
            model.component('comp1').geom('geom1').feature('cyl1').set('h', 'length');
            model.component('comp1').geom('geom1').create('cyl2', 'Cylinder');
            model.component('comp1').geom('geom1').feature('cyl2').set('axis', [1 0 0]);
            model.component('comp1').geom('geom1').feature('cyl2').set('r', 'piper-pipes');
            model.component('comp1').geom('geom1').feature('cyl2').set('h', 'length');
            model.component('comp1').geom('geom1').create('dif1', 'Difference');
            model.component('comp1').geom('geom1').feature('dif1').selection('input').set({'cyl1'});
            model.component('comp1').geom('geom1').feature('dif1').selection('input2').set({'cyl2'});
            model.component('comp1').geom('geom1').create('cyl3', 'Cylinder');
            model.component('comp1').geom('geom1').feature('cyl3').set('pos', {'20' '0' 'piper-1'});
            model.component('comp1').geom('geom1').feature('cyl3').set('r', 3);
            model.component('comp1').geom('geom1').feature('cyl3').set('h', 7);
            model.component('comp1').geom('geom1').create('mir1', 'Mirror');
            model.component('comp1').geom('geom1').feature('mir1').set('keep', true);
            model.component('comp1').geom('geom1').feature('mir1').set('pos', {'length/2' '0' '0'});
            model.component('comp1').geom('geom1').feature('mir1').set('axis', [1 0 0]);
            model.component('comp1').geom('geom1').feature('mir1').selection('input').set({'cyl3'});
            model.component('comp1').geom('geom1').create('elp1', 'Ellipsoid');
            model.component('comp1').geom('geom1').feature('elp1').set('pos', {'length/2' '0' 'piper-pipes'});
            model.component('comp1').geom('geom1').feature('elp1').set('semiaxes', {'pitr' 'pitr' 'pith'});
            model.component('comp1').geom('geom1').create('dif2', 'Difference');
            model.component('comp1').geom('geom1').feature('dif2').selection('input').set({'dif1'});
            model.component('comp1').geom('geom1').feature('dif2').selection('input2').set({'elp1'});
            model.component('comp1').geom('geom1').create('ige1', 'IgnoreEdges');
            model.component('comp1').geom('geom1').feature('ige1').selection('input').set('fin(1)', [39 43 46 52]);
            model.component('comp1').geom('geom1').run;

            model.component('comp1').material.create('mat1', 'Common');
            model.component('comp1').material('mat1').info.create('UNS');
            model.component('comp1').material('mat1').info.create('WNR');
            model.component('comp1').material('mat1').info.create('EN_DIN');
            model.component('comp1').material('mat1').info.create('JIS');
            model.component('comp1').material('mat1').info.create('ASTM');
            model.component('comp1').material('mat1').info.create('AISI');
            model.component('comp1').material('mat1').info.create('SAE');
            model.component('comp1').material('mat1').info.create('AMS');
            model.component('comp1').material('mat1').info.create('AFNOR');
            model.component('comp1').material('mat1').info.create([native2unicode(hex2dec({'7e' 'c4'}), 'unicode')  native2unicode(hex2dec({'52' '06'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('def').func.create('k', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('res', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('alpha', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('C', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('sigma', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('rho', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func.create('TD', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup.create('ThermalExpansion', [native2unicode(hex2dec({'70' 'ed'}), 'unicode')  native2unicode(hex2dec({'81' 'a8'}), 'unicode')  native2unicode(hex2dec({'80' 'c0'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func.create('dL', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func.create('CTE', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup.create('Enu', [native2unicode(hex2dec({'67' '68'}), 'unicode')  native2unicode(hex2dec({'6c' '0f'}), 'unicode')  native2unicode(hex2dec({'6a' '21'}), 'unicode')  native2unicode(hex2dec({'91' 'cf'}), 'unicode')  native2unicode(hex2dec({'54' '8c'}), 'unicode')  native2unicode(hex2dec({'6c' 'ca'}), 'unicode')  native2unicode(hex2dec({'67' '7e'}), 'unicode')  native2unicode(hex2dec({'6b' 'd4'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('Enu').func.create('E', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('Enu').func.create('nu', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup.create('KG', [native2unicode(hex2dec({'4f' '53'}), 'unicode')  native2unicode(hex2dec({'79' 'ef'}), 'unicode')  native2unicode(hex2dec({'6a' '21'}), 'unicode')  native2unicode(hex2dec({'91' 'cf'}), 'unicode')  native2unicode(hex2dec({'54' '8c'}), 'unicode')  native2unicode(hex2dec({'52' '6a'}), 'unicode')  native2unicode(hex2dec({'52' '07'}), 'unicode')  native2unicode(hex2dec({'6a' '21'}), 'unicode')  native2unicode(hex2dec({'91' 'cf'}), 'unicode') ]);
            model.component('comp1').material('mat1').propertyGroup('KG').func.create('mu', 'Piecewise');
            model.component('comp1').material('mat1').propertyGroup('KG').func.create('kappa', 'Piecewise');

            model.component('comp1').physics.create('ec', 'ConductiveMedia', 'geom1');
            model.component('comp1').physics('ec').create('term1', 'DomainTerminal', 3);
            model.component('comp1').physics('ec').feature('term1').selection.set([3]);
            model.component('comp1').physics('ec').create('gnd1', 'Ground', 2);
            model.component('comp1').physics('ec').feature('gnd1').selection.set([33]);

            model.component('comp1').mesh('mesh1').autoMeshSize(getAutoMeshSize(app));

            model.component('comp1').material('mat1').label('1020 [solid,annealed]');
            model.component('comp1').material('mat1').set('family', 'iron');
            model.component('comp1').material('mat1').set('info', {'UNS' '' 'G10200';  ...
                'WNR' '' '1.0402';  ...
                'EN_DIN' '' 'C22/CK22';  ...
                'JIS' '' 'S20C/S22C/SWRCH20A';  ...
                'ASTM' '' 'A510/A519/A544/A576/A659';  ...
                'AISI' '' '1020';  ...
                'SAE' '' 'J403/J412/J414';  ...
                'AMS' '' '5032/5045';  ...
                'AFNOR' '' 'S20C/S22C/SWRCH20A';  ...
                [native2unicode(hex2dec({'7e' 'c4'}), 'unicode')  native2unicode(hex2dec({'52' '06'}), 'unicode') ] '' 'bal. Fe, (0.18-0.23) C, (0.3-0.6) Mn, 0.04 P max, 0.05 S max (wt%)'});
            model.component('comp1').material('mat1').propertyGroup('def').func('k').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('pieces', {'26.0' '200.0' '-5.435137+1.615324*T^1-0.01440223*T^2+6.259829E-5*T^3-1.325386E-7*T^4+1.087384E-10*T^5'; '200.0' '1073.16' '61.1315+0.05272897*T^1-2.040655E-4*T^2+2.105727E-7*T^3-8.423301E-11*T^4'});
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('k').set('fununit', 'W/(m*K)');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('pieces', {'293.0' '1073.0' '9.391673E-8+7.568236E-12*T^1+8.641318E-13*T^2'; '1073.0' '1573.16' '-3.625E-7+2.652646E-9*T^1-1.562286E-12*T^2+3.333333E-16*T^3'});
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('res').set('fununit', 'ohm*m');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').label('Piecewise 2');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('pieces', {'0.0' '680.0' '6.731035E-6+3.918624E-8*T^1-1.534406E-10*T^2+3.517965E-13*T^3-3.827522E-16*T^4+1.565546E-19*T^5';  ...
                '680.0' '977.0' '8.614185E-6+1.013631E-8*T^1-2.26181E-12*T^2-1.569696E-15*T^3';  ...
                '977.0' '1061.0' '6.292031E-5-4.915637E-8*T^1';  ...
                '1061.0' '1144.0' '-1.563678E-6+1.162025E-8*T^1'});
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('alpha').set('fununit', '1/K');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').label('Piecewise 3');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('pieces', {'293.0' '848.16' '-215.730638+6.0184999*T^1-0.0183429321*T^2+2.414973E-5*T^3-1.07882432E-8*T^4'});
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('C').set('fununit', 'J/(kg*K)');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').label('Piecewise 4');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('pieces', {'293.0' '1073.0' '1/(8.641318E-13*T^2+7.568236E-12*T+9.391673E-08)'; '1073.0' '1573.16' '1/(3.333333E-16*T^3-1.562286E-12*T^2+2.652646E-09*T-3.625E-07)'});
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('sigma').set('fununit', 'S/m');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').label('Piecewise 5');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('pieces', {'0.0' '60.0' '7907.978-0.01549395*T^1';  ...
                '60.0' '977.0' '7911.3-0.01678428*T^1-8.018711E-4*T^2+1.172796E-6*T^3-1.015971E-9*T^4+3.677737E-13*T^5';  ...
                '977.0' '1061.0' '7116.994+0.5195388*T^1';  ...
                '1061.0' '1144.0' '8166.523-0.4696497*T^1'});
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('rho').set('fununit', 'kg/m^3');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').label('Piecewise 6');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('pieces', {'295.0' '1048.0' '1.908565E-5-1.445398E-8*T^1'; '1048.0' '1248.16' '-6.844E-5+1.158517E-7*T^1-4.46478E-11*T^2'});
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('def').func('TD').set('fununit', 'm^2/s');
            model.component('comp1').material('mat1').propertyGroup('def').set('thermalconductivity', {'k(T)' '0' '0' '0' 'k(T)' '0' '0' '0' 'k(T)'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:thermalconductivity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': E.A. Eldridge and H.W. Deem, Report on Physical Properties of Metals and Alloys from Cryogenic to Elevated Temperatures, ASTM, Special Technical Publication No. 296 (1961) https://doi.org/10.1520/STP296-EB and High-Temperature Property Data: Ferrous Alloys, Editor M.F. Rothman, ASM International (1988) and Engineering Properties of Steel, P.D. Harvey, ASM (1982)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': data above 20 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (293 K) is from 1023 and multiplied by 1.2 to match the low temperature data']);
            model.component('comp1').material('mat1').propertyGroup('def').set('resistivity', {'res(T)' '0' '0' '0' 'res(T)' '0' '0' '0' 'res(T)'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:resistivity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': ASM Handbook, v1, Properties and Selection: Irons, Steels, and High-Performance Alloys, 10th edition, ASM International (1990)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values, annealed']);
            model.component('comp1').material('mat1').propertyGroup('def').set('thermalexpansioncoefficient', {'(alpha(T)+(Tempref-293[K])*if(abs(T-Tempref)>1e-3,(alpha(T)-alpha(Tempref))/(T-Tempref),d(alpha(T),T)))/(1+alpha(Tempref)*(Tempref-293[K]))' '0' '0' '0' '(alpha(T)+(Tempref-293[K])*if(abs(T-Tempref)>1e-3,(alpha(T)-alpha(Tempref))/(T-Tempref),d(alpha(T),T)))/(1+alpha(Tempref)*(Tempref-293[K]))' '0' '0' '0' '(alpha(T)+(Tempref-293[K])*if(abs(T-Tempref)>1e-3,(alpha(T)-alpha(Tempref))/(T-Tempref),d(alpha(T),T)))/(1+alpha(Tempref)*(Tempref-293[K]))'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:thermalexpansioncoefficient', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': the reference temperature is 20 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (293 K), same data as 1025 steel' newline  native2unicode(hex2dec({'53' 'c2'}), 'unicode')  native2unicode(hex2dec({'80' '03'}), 'unicode')  native2unicode(hex2dec({'6e' '29'}), 'unicode')  native2unicode(hex2dec({'5e' 'a6'}), 'unicode') ': 293.00[K]']);
            model.component('comp1').material('mat1').propertyGroup('def').set('heatcapacity', 'C(T)');
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:heatcapacity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': ASM Handbook, v1, Properties and Selection: Irons, Steels, and High-Performance Alloys, 10th edition, ASM International (1990)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values, annealed']);
            model.component('comp1').material('mat1').propertyGroup('def').set('electricconductivity', {'1/2E-7' '0' '0' '0' '1/2E-7' '0' '0' '0' '1/2E-7'});
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:electricconductivity', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': ASM Handbook, v1, Properties and Selection: Irons, Steels, and High-Performance Alloys, 10th edition, ASM International (1990)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values, annealed, calculated as the reciprocal of the resistivity']);
            model.component('comp1').material('mat1').propertyGroup('def').set('density', 'rho(T)');
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:density', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': calculated from the linear expansion and the room temperature density']);
            model.component('comp1').material('mat1').propertyGroup('def').set('TD', 'TD(T)');
            model.component('comp1').material('mat1').propertyGroup('def').set('INFO_PREFIX:TD', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': C.P. Butler and E.C.Y. Inn, Thermal Diffusivity of Metals at elevated Temperatures, in Thermodynamic and Transport Properties of Gases, Liquids and Solids, ASME Symposium on Thermal Properties, Mc-Graw-Hill Book Co., p377-90 (1959)' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': hot-rolled, 5% to 10% error']);
            model.component('comp1').material('mat1').propertyGroup('def').set('relpermittivity', {'1E6' '0' '0' '0' '1E6' '0' '0' '0' '1E6'});
            model.component('comp1').material('mat1').propertyGroup('def').addInput('temperature');
            model.component('comp1').material('mat1').propertyGroup('def').addInput('strainreferencetemperature');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').set('pieces', {'0.0' '50.0' '-0.002022973+3.918919E-7*T^1';  ...
                '50.0' '212.0' '-0.002063267-3.508062E-7*T^1+2.838477E-8*T^2';  ...
                '212.0' '977.0' '-0.00252395773+5.644256E-6*T^1+1.0799E-8*T^2-1.801872E-12*T^3-1.569701E-15*T^4';  ...
                '977.0' '1061.0' '0.03264232-2.297313E-5*T^1';  ...
                '1061.0' '1144.0' '-0.01351301+2.053771E-5*T^1'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('dL').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('pieces', {'293.0' '1065.0' '5.044861E-6+2.5188E-8*T^1-1.418195E-11*T^2'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').func('CTE').set('fununit', '1/K');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('alphatan', {'CTE(T)' '0' '0' '0' 'CTE(T)' '0' '0' '0' 'CTE(T)'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('INFO_PREFIX:alphatan', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': same data as 1025 steel, calculated from the mean coefficient of thermal expansion']);
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('dL', {'(dL(T)-dL(Tempref))/(1+dL(Tempref))' '0' '0' '0' '(dL(T)-dL(Tempref))/(1+dL(Tempref))' '0' '0' '0' '(dL(T)-dL(Tempref))/(1+dL(Tempref))'});
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').set('INFO_PREFIX:dL', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': MIL-HDBK-5H, 1 Dec 1998 p2-9 and R.J. Corruccini and J.J. Gniewek, Thermal Expansion of Technical Solids at Low Temperatures: A Compilation From the Literature, NBS Monograph 29 (1961) https://digital.library.unt.edu/ark:/67531/metadc70437/' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': the reference temperature is 20 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (293 K), same data as 1025 steel, calculated from the mean coefficient of thermal expansion' newline  native2unicode(hex2dec({'53' 'c2'}), 'unicode')  native2unicode(hex2dec({'80' '03'}), 'unicode')  native2unicode(hex2dec({'6e' '29'}), 'unicode')  native2unicode(hex2dec({'5e' 'a6'}), 'unicode') ': 293.00[K]']);
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').addInput('temperature');
            model.component('comp1').material('mat1').propertyGroup('ThermalExpansion').addInput('strainreferencetemperature');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('pieces', {'4.0' '273.0' '2.217366E11+5020008.0*T^1-305140.4*T^2+926.6601*T^3-1.145454*T^4'; '273.0' '1050.0' '2.11026989E11+3.572844E7*T^1-106319.6*T^2'; '1050.0' '1500.0' '2.02449497E11-6.77381E7*T^1'});
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('E').set('fununit', 'Pa');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').set('pieces', {'4.0' '120.0' '0.2850355-1.662951E-6*T^1';  ...
                '120.0' '273.0' '0.28474914-7.147353E-6*T^1+6.558945E-8*T^2';  ...
                '273.0' '1053.0' '0.271114512+7.030261E-5*T^1-3.856929E-8*T^2+1.246582E-11*T^3';  ...
                '1053.0' '1500.0' '0.316398425-1.242823E-6*T^1+1.661461E-9*T^2'});
            model.component('comp1').material('mat1').propertyGroup('Enu').func('nu').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('Enu').set('E', 'E(T)');
            model.component('comp1').material('mat1').propertyGroup('Enu').set('INFO_PREFIX:E', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values']);
            model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', 'nu(T)');
            model.component('comp1').material('mat1').propertyGroup('Enu').set('INFO_PREFIX:nu', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values']);
            model.component('comp1').material('mat1').propertyGroup('Enu').addInput('temperature');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').label('Piecewise');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('pieces', {'4.0' '273.0' '8.626526E10+1636497.0*T^1-108981.6*T^2+291.1261*T^3-0.3377859*T^4'; '273.0' '1050.0' '8.30237093E10+9184755.0*T^1-38834.5*T^2'; '1050.0' '1500.0' '7.69464143E10-2.580357E7*T^1'});
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('KG').func('mu').set('fununit', 'Pa');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').label('Piecewise 1');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('arg', 'T');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('pieces', {'4.0' '100.0' '1.800573E11-3478717.0*T^1+151512.1*T^2-5485.327*T^3+28.28329*T^4'; '100.0' '273.0' '1.81842391E11-4.023792E7*T^1+84204.68*T^2-93.09453*T^3'; '273.0' '1500.0' '1.84220483E11-2.509462E7*T^1-28588.37*T^2'});
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('argunit', 'K');
            model.component('comp1').material('mat1').propertyGroup('KG').func('kappa').set('fununit', 'Pa');
            model.component('comp1').material('mat1').propertyGroup('KG').set('K', 'kappa(T)');
            model.component('comp1').material('mat1').propertyGroup('KG').set('INFO_PREFIX:K', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values and were increased by 6% to match the high temperature values']);
            model.component('comp1').material('mat1').propertyGroup('KG').set('G', 'mu(T)');
            model.component('comp1').material('mat1').propertyGroup('KG').set('INFO_PREFIX:G', [native2unicode(hex2dec({'5f' '15'}), 'unicode')  native2unicode(hex2dec({'75' '28'}), 'unicode') ': M. Fukuhara and A. Sanpei, ISIJ International, v33, No. 4, p508 (1993) https://doi.org/10.2355/isijinternational.33.508 and J.A. Rayne and B.S. Chandrasekhar, Physical Review, v122, p1714 (1961) https://doi.org/10.1103/PhysRev.122.1714' newline  native2unicode(hex2dec({'6c' 'e8'}), 'unicode') ': approximate values for plain carbon and low alloy steels, values below 0 ' native2unicode(hex2dec({'00' 'b0'}), 'unicode') 'C (273 K) were calculated with C11, C12 and C44 from the average of Voigt and Reuss values']);
            model.component('comp1').material('mat1').propertyGroup('KG').addInput('temperature');

            model.component('comp1').physics('ec').feature('term1').set('I0', 'current');

            model.study.create('std1');
            model.study('std1').create('stat', 'Stationary');

            out = model;

        end


        function coordinate = buildElectrodeCoords(~, length, piper, rows, cols, spancing)
            rows = round(rows);
            cols = round(cols);
            if rows < 1 || cols < 2
                error('The number of rows must be ≥1, and the number of columns must be ≥2！');
            end
            d = spancing;
            x0 = length/2 + ((0:cols-1) - (cols-1)/2) * d;
            k  = ((0:rows-1) - (rows-1)/2);
            y0 = -piper * sin(k * d / piper);
            z0 =  piper * cos(k * d / piper);
            coordinate = zeros(3, rows * cols);
            n = 1;
            for ix = 1:cols
                for ir = 1:rows
                    coordinate(:, n) = [x0(ix); y0(ir); z0(ir)];
                    n = n + 1;
                end
            end
            %             disp(coordinate)
        end

        function voltage = extractV(app, model, length, piper, rows, cols, spancing, customCoord)
            if nargin >= 8 && ~isempty(customCoord)
                coordinate = customCoord;
            else
                coordinate = buildElectrodeCoords(app, length, piper, rows, cols, spancing);
            end
            V = mphinterp(model, 'V', 'coord', coordinate);
            Data_V = reshape(V, rows, cols);
            voltage = -diff(Data_V, 1, 2) .* 1e6;
            %             disp(V)
            %             disp(Data_V )
            %             disp(voltage)
        end

        function [ok, customCoord, errMsg] = getCustomCoordIfNeeded(app, mode, rows, cols)
            ok = true;
            customCoord = [];
            errMsg = '';
            if strcmp(mode, 'single')
                scheme = app.LayoutDropDown.Value;
                data = app.ElectrodeCoordData;
            else
                scheme = app.LayoutDropDown_2.Value;
                data = app.ElectrodeCoordDataBatch;
            end
            if ~strcmp(scheme, 'Customize')
                return;
            end
            expected = round(rows) * round(cols);
            if isempty(data)
                ok = false;
                errMsg = 'Please click on "Setting" first, enter and save the electrode coordinates.';
                return;
            end
            if size(data, 1) ~= 3 || size(data, 2) ~= expected
                ok = false;
                errMsg = sprintf('The custom coordinate size should be 3×%d (currently %d×%d). Please reset it.', ...
                    expected, size(data, 1), size(data, 2));
                return;
            end
            customCoord = data;
        end

        function PopupTableButtonPushed(app, source)
            if strcmp(source, 'single')
                row_num = app.RowsSpinner.Value;
                col_num = app.ColumnsSpinner.Value;
                existing = app.ElectrodeCoordData;
            else
                row_num = app.RowsEditField.Value;
                col_num = app.ColumnsEditField.Value;
                existing = app.ElectrodeCoordDataBatch;
            end
            row_num = round(row_num);
            col_num = round(col_num);
            total_col = row_num * col_num;
            if total_col < 1
                uialert(app.VDMMSMUIFigure, 'Rows  or Columns must be positive integers.', 'Parameter error');
                return;
            end

            fig_pop = uifigure('Name', 'Electrode Coordinate Table', 'Position', [400 300 800 400]);
            panel_main = uipanel(fig_pop, 'Position', [20 80 760 280]);
            btn_panel = uipanel(fig_pop, 'Position', [20 10 760 60]);
            if ~isempty(existing) && size(existing, 1) == 3 && size(existing, 2) == total_col
                table_data = existing;
            else
                table_data = zeros(3, total_col);
            end
            ut = uitable(panel_main);
            ut.Position = [10 10 740 260];
            ut.Data = table_data;
            ut.RowName = {'x'; 'y'; 'z'};
            ut.ColumnName = compose("Column %d", 1:total_col);
            ut.ColumnEditable = true;

            uibutton(btn_panel, 'push', 'Text', 'Load CSV File', 'Position', [30 10 180 30], 'ButtonPushedFcn', @(~,~) loadElectrodeCSV(app, ut, fig_pop, total_col));
            uibutton(btn_panel, 'push', 'Text', 'Export to CSV', 'Position', [230 10 180 30], 'ButtonPushedFcn', @(~,~) exportElectrodeCSV(app, ut, fig_pop));
            uibutton(btn_panel, 'push', 'Text', 'Save to App Variable', 'Position', [430 10 180 30], 'ButtonPushedFcn', @(~,~) saveElectrodeToAppVar(app, ut, fig_pop, source, total_col));
        end

        function loadElectrodeCSV(~, table_obj, fig_win, nCol)
            [file, path] = uigetfile('*.csv', 'Select coordinate csv file');
            if isequal(file, 0)
                return;
            end
            data_read = readmatrix(fullfile(path, file));
            if size(data_read, 1) ~= 3
                uialert(fig_win, 'CSV file must contain exactly 3 rows (x/y/z)', 'Data Error');
                return;
            end
            if size(data_read, 2) ~= nCol
                uialert(fig_win, sprintf('CSV must contain exactly %d columns (rows×cols).', nCol), 'Data Error');
                return;
            end
            table_obj.Data = data_read;
            uialert(fig_win, 'CSV data loaded successfully', 'Success');
        end

        function exportElectrodeCSV(~, table_obj, fig_win)
            [file, path] = uiputfile('coordinate_data.csv', 'Save table data as CSV');
            if isequal(file, 0)
                return;
            end
            writematrix(table_obj.Data, fullfile(path, file));
            uialert(fig_win, 'CSV file saved successfully', 'Success');
        end

        function saveElectrodeToAppVar(app, table_obj, fig_win, src, nCol)
            data_mat = table_obj.Data;
            if size(data_mat, 1) ~= 3 || size(data_mat, 2) ~= nCol
                uialert(fig_win, sprintf('Data size must be 3×%d.', nCol), 'Data Error');
                return;
            end
            if strcmp(src, 'single')
                app.ElectrodeCoordData = data_mat;
            else
                app.ElectrodeCoordDataBatch = data_mat;
            end
            uialert(fig_win, 'Data stored into app variable successfully', 'Success');
        end

        function applyMatrixSchemeVisibility(app, scheme, whichPanel)
            isEqual = strcmp(scheme, 'Equal');
            if strcmp(whichPanel, 'single')
                app.SpacingSpinner.Visible = isEqual;
                app.SpacingSpinnerLabel.Visible = isEqual;
                app.mmLabel_41.Visible = isEqual;
                app.SettingButton_2.Visible = ~isEqual;
                if isEqual
                    app.SettingButton_2.Value = false;
                end
            else
                app.SpacingEditField.Visible = isEqual;
                app.SpacingEditFieldLabel.Visible = isEqual;
                app.mmLabel.Visible = isEqual;
                app.SettingButton.Visible = ~isEqual;
                if isEqual
                    app.SettingButton.Value = false;
                end
            end
        end

        function meshSize = getAutoMeshSize(app)
            meshLabels = {'Extremely fine', 'Extra fine', 'Finer', 'Fine', 'Normal', ...
                'Coarse', 'Coarser', 'Extra coarse', 'Extremely coarse'};
            selected = app.MeshsizeDropDown.Value;
            if isnumeric(selected)
                meshSize = round(double(selected));
            else
                idx = find(strcmpi(char(string(selected)), meshLabels), 1);
                if isempty(idx)
                    idx = 1;
                end
                meshSize = idx;
            end
            meshSize = max(1, min(9, meshSize));
        end

        function model_w = solveWeifushiModel(app, length, piper, pipes, sigma, current)
            model_w = figweifushi(app);
            model_w.param.set('length', strcat(num2str(length), '[mm]'));
            model_w.param.set('piper', strcat(num2str(piper), '[mm]'));
            model_w.param.set('pipes', strcat(num2str(pipes), '[mm]'));
            model_w.param.set('current', strcat(num2str(current), '[A]'));
            cond_str = sprintf('%g[S/m]', sigma);
            model_w.component('comp1').material('mat1').propertyGroup('def').set( ...
                'electricconductivity', {cond_str '0' '0' '0' cond_str '0' '0' '0' cond_str});
            model_w.study('std1').run();
        end

        function model_p = solvePitModel(app, length, piper, pipes, pitr, pith, sigma, current)
            model_p = figpit(app);
            model_p.param.set('length', strcat(num2str(length), '[mm]'));
            model_p.param.set('piper', strcat(num2str(piper), '[mm]'));
            model_p.param.set('pipes', strcat(num2str(pipes), '[mm]'));
            model_p.param.set('pitr', strcat(num2str(pitr), '[mm]'));
            model_p.param.set('pith', strcat(num2str(pith), '[mm]'));
            model_p.param.set('current', strcat(num2str(current), '[A]'));
            cond_str = sprintf('%g[S/m]', sigma);
            model_p.component('comp1').material('mat1').propertyGroup('def').set('electricconductivity', {cond_str '0' '0' '0' cond_str '0' '0' '0' cond_str});
            model_p.study('std1').run();
        end

        function voltageW = CalWeifushiVoltage(app, length, piper, pipes, rows, cols, spancing, sigma, current)
            model_w = solveWeifushiModel(app, length, piper, pipes, sigma, current);
            voltageW = extractV(app, model_w, length, piper, rows, cols, spancing);
        end

        function [voltageP, model_p] = CalPitVoltage(app, length, piper, pipes, pitr, pith, rows, cols, spancing, sigma, current)
            model_p = solvePitModel(app, length, piper, pipes, pitr, pith, sigma, current);
            voltageP = extractV(app, model_p, length, piper, rows, cols, spancing);
        end

        function [voltageW, voltageP, FC, model_p] = CalSingleModel(app, length, piper, pipes, pitr, pith, rows, cols, spancing, sigma, current)
            model_w = solveWeifushiModel(app, length, piper, pipes, sigma, current);
            voltageW = extractV(app, model_w, length, piper, rows, cols, spancing);
            model_p = solvePitModel(app, length, piper, pipes, pitr, pith, sigma, current);
            voltageP = extractV(app, model_p, length, piper, rows, cols, spancing);
            FC = (voltageP ./ voltageW - 1) * 1000;
        end

        function PlotSingleModel(app, voltageW, voltageP, FC, model_p)

            app.UITable_Data_SVW.Data = voltageW;
            app.UITable_Data_SVW.ColumnName = arrayfun(@(k) sprintf('Col%d', k), 1:size(voltageW,2), 'UniformOutput', false);
            app.UITable_Data_SVW.RowName = arrayfun(@(k) sprintf('Row%d', k), 1:size(voltageW,1), 'UniformOutput', false);
            app.UITable_Data_SV.Data = voltageP;
            app.UITable_Data_SV.ColumnName = arrayfun(@(k) sprintf('Col%d', k), 1:size(voltageP,2), 'UniformOutput', false);
            app.UITable_Data_SV.RowName = arrayfun(@(k) sprintf('Row%d', k),1:size(voltageP,1), 'UniformOutput', false);
            app.UITable_Data_SFC.Data = FC;
            app.UITable_Data_SFC.ColumnName = arrayfun(@(k) sprintf('Col%d', k),1:size(FC,2), 'UniformOutput', false);
            app.UITable_Data_SFC.RowName = arrayfun(@(k) sprintf('Row%d', k),1:size(FC,1), 'UniformOutput', false);
            cla(app.UIAxes_SFCD);
            bar3(app.UIAxes_SFCD, FC);
            title(app.UIAxes_SFCD, 'FC Distribution');
            xlabel(app.UIAxes_SFCD, 'Column');
            ylabel(app.UIAxes_SFCD, 'Row');
            zlabel(app.UIAxes_SFCD, 'FC');
            view(app.UIAxes_SFCD, 45, 30);
            model_p.result.create('pg1', 'PlotGroup3D');
            model_p.result.create('pg2', 'PlotGroup3D');
            model_p.result('pg1').create('vol1', 'Volume');
            model_p.result('pg2').create('str1', 'Streamline');
            model_p.result('pg2').feature('str1').selection.all;
            model_p.result('pg1').set('frametype', 'spatial');
            model_p.result('pg1').set('showlegendsmaxmin', true);
            model_p.result('pg1').feature('vol1').set('colortable', 'Dipole');
            model_p.result('pg1').feature('vol1').set('resolution', 'normal');
            model_p.result('pg2').feature('str1').set('posmethod', 'start');
            model_p.result('pg2').feature('str1').set('number', 50);
            model_p.result('pg2').feature('str1').set('pointtype', 'arrow');
            model_p.result('pg2').feature('str1').set('arrowcount', 200);
            model_p.result('pg2').feature('str1').set('arrowscale', 8.0E-6);
            model_p.result('pg2').feature('str1').set('arrowscaleactive', true);
            model_p.result('pg2').feature('str1').set('arrowcountactive', false);
            model_p.result('pg2').feature('str1').set('resolution', 'normal');
            model_p.result('pg1').set('titletype', 'none');
            model_p.result('pg2').set('titletype', 'none');
            mphplot(model_p, 'pg1', 'parent', app.UIAxes_SVD,'rangenum',1);
            mphplot(model_p, 'pg2', 'parent', app.UIAxes_SMFCD,'rangenum',1);
        end

        function T = createEmptyResultsTable(~)
            T = table('Size', [0 13], ...
                'VariableTypes', {'double','double','double','double','double', ...
                'double','double','double','double','double','cell','cell','cell'}, ...
                'VariableNames', {'L','piper','S','r','h','rows','cols','spacing', ...
                'sigma','current','V_weifushi','V_pit','FC'});
        end

        function appendResult(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, VW, VP, FC)
            newRow = table(L, piper, S, r, h, rows, cols, spacing, sigma, current, {VW}, {VP}, {FC}, ...
                'VariableNames', {'L','piper','S','r','h','rows','cols','spacing', ...
                'sigma','current','V_weifushi','V_pit','FC'});
            if isempty(app.ResultsTable)
                app.ResultsTable = newRow;
            else
                app.ResultsTable = [app.ResultsTable; newRow];
            end
        end

        function T = createEmptySkippedTable(~)
            T = table('Size', [0 11], ...
                'VariableTypes', {'double','double','double','double','double', ...
                'double','double','double','double','double','cell'}, ...
                'VariableNames', {'L','piper','S','r','h','rows','cols','spacing', ...
                'sigma','current','reason'});
        end

        function s = classifyBatchError(~, ME, defaultReason)
            msg = lower(char(string(ME.message)));
            if contains(msg, '几何') || contains(msg, 'geom') || contains(msg, '椭球') || ...
                    contains(msg, 'elp') || contains(msg, 'semiaxes') || contains(msg, 'invalid property') || ...
                    contains(msg, '构建') || contains(msg, 'boolean') || contains(msg, 'difference')
                s = 'Geometry build failed';
            elseif contains(msg, '网格') || contains(msg, 'mesh')
                s = 'Mesh generation failed';
            elseif contains(msg, '求解') || contains(msg, 'solver') || contains(msg, 'convergen') || ...
                    contains(msg, 'failed to find a solution') || contains(msg, 'singular')
                s = 'Solver failed';
            elseif contains(msg, 'mphinterp') || contains(msg, 'interpolat')
                s = 'Voltage extraction failed';
            elseif nargin >= 3 && ~isempty(defaultReason)
                s = char(string(defaultReason));
            else
                s = 'Unknown error';
            end
        end

        function logSkippedBatchCase(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, reason)
            reason = char(string(reason));
            newRow = table(L, piper, S, r, h, rows, cols, spacing, sigma, current, {reason}, ...
                'VariableNames', {'L','piper','S','r','h','rows','cols','spacing', ...
                'sigma','current','reason'});
            if isempty(app.SkippedTable)
                app.SkippedTable = newRow;
            else
                app.SkippedTable = [app.SkippedTable; newRow];
            end
            outPut_Text2(app, sprintf('  skipped L=%g R=%g S=%g r=%g h=%g spacing=%g | %s', ...
                L, piper, S, r, h, spacing, reason));
        end

        function recordSkippedSpacings(app, L, piper, S, r, h, rows, cols, spacing_list, sigma, current, reason)
            if isempty(spacing_list)
                logSkippedBatchCase(app, L, piper, S, r, h, rows, cols, NaN, sigma, current, reason);
                return
            end
            for id = 1:numel(spacing_list)
                logSkippedBatchCase(app, L, piper, S, r, h, rows, cols, spacing_list(id), sigma, current, reason);
            end
        end

        function recordSkippedIntactDependents(app, ME, L, piper, S, r_list, h_list, rows, cols, spacing_list, sigma, current)
            reason = classifyBatchError(app, ME);
            for r = r_list
                for h = h_list
                    recordSkippedSpacings(app, L, piper, S, r, h, rows, cols, spacing_list, sigma, current, reason);
                end
            end
        end

        function [checkout, errMsg] = checkValue(app, folder, fileName, reportName)
            doExcel = app.ExporttoExcelCheckBox.Value;
            doMat = app.ExporttoMATfileCheckBox.Value;
            doReport = app.SaveReportCheckBox.Value;
            doMysql = app.SavetoDatabaseCheckBox.Value;
            checkout = 0;
            errMsg = '';

            if ~doExcel && ~doMat && ~doReport && ~doMysql
                choice = uiconfirm(app.VDMMSMUIFigure, ...
                    'No save option is checked, the calculation results will not be saved. Do you still want to proceed with the calculation?', ...
                    'Save', ...
                    'Options', {'OK', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);
                if strcmp(choice, 'Cancel')
                    checkout = -1;
                    errMsg = 'Cancelled: No saving method selected';
                end
                return
            end
            if doExcel || doMat
                if isempty(folder)
                    checkout = 1;
                    errMsg = 'Please fill in the file save path';
                    return
                end
                if isempty(fileName)
                    checkout = 2;
                    errMsg = 'Please fill in the file name';
                    return
                end
            end
            if doReport
                if isempty(folder)
                    checkout = 3;
                    errMsg = 'Please fill in the file save path';
                    return
                end
                if isempty(reportName)
                    checkout = 4;
                    errMsg = 'Please fill in the Report Name';
                    return
                end
            end

            if doMysql

                if isempty(app.conn)
                    checkout = 5;
                    errMsg = 'Database connection failed!';
                    return
                end
            end
        end

        function exportResultsToMat(app, folder, fileName)
            ResultsTable = app.ResultsTable;
            SkippedTable = app.SkippedTable;
            fpath = fullfile(folder, [fileName, '.mat']);
            save(fpath, 'ResultsTable', 'SkippedTable');
        end

        function exportResultsToExcel(app, folder, fileName)
            T = app.ResultsTable;
            Ts = app.SkippedTable;
            hasOk = ~(isempty(T) || height(T) == 0);
            hasSkip = ~(isempty(Ts) || height(Ts) == 0);
            if ~hasOk && ~hasSkip
                outPut_Text2(app, 'Excel skipped: ResultsTable is empty.');
                return
            end
            fpath = fullfile(folder, [fileName, '.xlsx']);
            if isfile(fpath)
                delete(fpath);
            end
            if ~hasOk
                T = createEmptyResultsTable(app);
            end
            if ~hasSkip
                Ts = createEmptySkippedTable(app);
            end
            OutVW = buildFlattenedResultSheet(app, T, 'V_weifushi', 'Vw');
            OutVP = buildFlattenedResultSheet(app, T, 'V_pit', 'Vp');
            OutFC = buildFlattenedResultSheet(app, T, 'FC', 'FC');
            writetable(OutVW, fpath, 'Sheet', 'IntactVoltage');
            writetable(OutVP, fpath, 'Sheet', 'PitVoltage');
            writetable(OutFC, fpath, 'Sheet', 'FC');
            OutSkip = buildSkippedSheet(app, Ts);
            writetable(OutSkip, fpath, 'Sheet', 'Skipped');
            nOk = 0;
            nSkip = 0;
            if hasOk
                nOk = height(app.ResultsTable);
            end
            if hasSkip
                nSkip = height(app.SkippedTable);
            end
            outPut_Text2(app, sprintf('Excel saved: %s (Sheet1=IntactVoltage, Sheet2=PitVoltage, Sheet3=FC, Sheet4=Skipped; %d saved, %d skipped).', ...
                fpath, nOk, nSkip));
        end

        function Out = buildSkippedSheet(~, T)
            n = height(T);
            Index = (1:n)';
            Out = table(Index, T.L, T.piper, T.S, T.r, T.h, T.rows, T.cols, ...
                T.spacing, T.sigma, T.current, T.reason, ...
                'VariableNames', {'Index','PipeL','PipeR','PipeS','pitr','pith', ...
                'rows','cols','spacing','sigma','current','reason'});
        end

        function Out = buildFlattenedResultSheet(~, T, matrixCol, valuePrefix)
            n = height(T);
            nVal = 0;
            for i = 1:n
                M = T.(matrixCol){i};
                if ~isempty(M)
                    nVal = max(nVal, numel(M));
                end
            end
            paramNames = {'Index','PipeL','PipeR','PipeS','pitr','pith','rows','cols','spacing','sigma','current'};
            nParam = numel(paramNames);
            if nVal > 0
                valNames = arrayfun(@(k) sprintf('%s_%d', valuePrefix, k), 1:nVal, 'UniformOutput', false);
            else
                valNames = {};
            end
            data = nan(n, nParam + nVal);
            for i = 1:n
                data(i, 1:nParam) = [i, T.L(i), T.piper(i), T.S(i), T.r(i), T.h(i), ...
                    T.rows(i), T.cols(i), T.spacing(i), T.sigma(i), T.current(i)];
                M = T.(matrixCol){i};
                if ~isempty(M)
                    flat = reshape(M.', 1, []);
                    data(i, nParam+(1:numel(flat))) = flat;
                end
            end
            Out = array2table(data, 'VariableNames', [paramNames, valNames]);
        end

        function exportResultsToMysql(app)
            T = app.ResultsTable;
            if isempty(T) || height(T) == 0
                outPut_Text2(app, 'MySQL skipped: ResultsTable is empty.');
                return
            end
            if isempty(app.conn) || ~isopen(app.conn)
                outPut_Text2(app, 'MySQL skipped: database connection is not open.');
                return
            end
            try
                exec(app.conn, 'USE pipepitssim;');
            catch ME
                outPut_Text2(app, ['MySQL failed: cannot use database pipepitssim. ', ME.message]);
                return
            end
            modelType = str2double(app.ModelTypeNum);
            if isnan(modelType)
                modelType = [];
            end
            nOk = 0;
            nFail = 0;
            for i = 1:height(T)
                try
                    jsonVW = matrixToJsonSql(app, T.V_weifushi{i});
                    jsonVP = matrixToJsonSql(app, T.V_pit{i});
                    jsonFC = matrixToJsonSql(app, T.FC{i});
                    if isempty(modelType)
                        modelTypeSql = 'NULL';
                    else
                        modelTypeSql = sprintf('%d', modelType);
                    end
                    sql = sprintf([ ...
                        'INSERT INTO simulation_records ', ...
                        '(pipeL, pipeR, pipeS, pitr, pith, elec_rows, elec_cols, spacing, ', ...
                        'sigma, current_a, V_weifushi, V_pit, FC, model_type) VALUES (', ...
                        '%.16g, %.16g, %.16g, %.16g, %.16g, %d, %d, %.16g, %.16g, %.16g, ', ...
                        '%s, %s, %s, %s);'], ...
                        T.L(i), T.piper(i), T.S(i), T.r(i), T.h(i), ...
                        round(T.rows(i)), round(T.cols(i)), T.spacing(i), ...
                        T.sigma(i), T.current(i), ...
                        jsonVW, jsonVP, jsonFC, modelTypeSql);
                    exec(app.conn, sql);
                    nOk = nOk + 1;
                catch ME
                    nFail = nFail + 1;
                    outPut_Text2(app, sprintf('MySQL insert failed at row %d: %s', i, ME.message));
                end
            end
        end

        function s = matrixToJsonSql(~, M)
            if isempty(M)
                js = '[]';
            else
                js = jsonencode(M);
            end
            js = char(js);
            js = strrep(js, '\', '\\');
            js = strrep(js, '''', '''''');
            s = ['''', js, ''''];
        end

        function exportCalculationReport(app, folder, reportName)
            fpath = fullfile(folder, [reportName, '.txt']);
            fid = fopen(fpath, 'w');
            if fid < 0
                outPut_Text2(app, ['Report save failed: cannot open ', fpath]);
                return
            end
            titleStr = strtrim(app.EditField_ReportName.Value);
            if ~isempty(titleStr)
                fprintf(fid, '%s\r\n', titleStr);
                fprintf(fid, '%s\r\n\r\n', repmat('=', 1, max(8, numel(char(titleStr)))));
            end
            fprintf(fid, 'Generated: %s\r\n\r\n', datestr(now));
            lines = app.cellArrayText_2;
            if isempty(lines)
                lines = app.TextArea_2.Value;
            end
            if ischar(lines)
                fprintf(fid, '%s\r\n', lines);
            elseif isstring(lines)
                for i = 1:numel(lines)
                    fprintf(fid, '%s\r\n', lines(i));
                end
            else
                for i = 1:numel(lines)
                    s = lines{i};
                    if isstring(s), s = char(s); end
                    if isempty(s)
                        fprintf(fid, '\r\n');
                    else
                        fprintf(fid, '%s', s);
                        if isempty(regexp(s, '[\r\n]$', 'once'))
                            fprintf(fid, '\r\n');
                        end
                    end
                end
            end
            fclose(fid);
        end

        function exportCalculationResults(app)
            doExcel = app.ExporttoExcelCheckBox.Value;
            doMat = app.ExporttoMATfileCheckBox.Value;
            doReport = app.SaveReportCheckBox.Value;
            doMysql = app.SavetoDatabaseCheckBox.Value;
            folder = strtrim(app.EditField_folderPath.Value);
            fileName = strtrim(app.EditField_FileName.Value);
            reportName = strtrim(app.EditField_ReportName.Value);
            if doMat
                exportResultsToMat(app, folder, fileName);
            end
            if doExcel
                exportResultsToExcel(app, folder, fileName);
            end
            if doMysql
                exportResultsToMysql(app);
            end
            if doReport
                exportCalculationReport(app, folder, reportName);
            end
        end

        function showAllResultsInTable(app)
            T = app.ResultsTable;
            if isempty(T) || height(T) == 0
                app.UITable_showalldata.Data = [];
                app.UITable_showalldata.ColumnName = {};
                outPut_Text2(app, 'UITable_showalldata: ResultsTable is empty.');
                return
            end
            n = height(T);
            nFc = 0;
            for i = 1:n
                FC = T.FC{i};
                if ~isempty(FC)
                    nFc = max(nFc, numel(FC));
                end
            end
            paramNames = {'Index','PipeL','PipeR','PipeS','pitr','pith','rows','cols','spacing','sigma','current'};
            nParam = numel(paramNames);
            if nFc > 0
                fcNames = arrayfun(@(k) sprintf('FC_%d', k), 1:nFc, 'UniformOutput', false);
            else
                fcNames = {};
            end
            colNames = [paramNames, fcNames];
            data = nan(n, nParam + nFc);
            for i = 1:n
                data(i, 1:nParam) = [i, T.L(i), T.piper(i), T.S(i), T.r(i), T.h(i), ...
                    T.rows(i), T.cols(i), T.spacing(i), T.sigma(i), T.current(i)];
                FC = T.FC{i};
                if ~isempty(FC)
                    flat = reshape(FC.', 1, []);
                    data(i, nParam+(1:numel(flat))) = flat;
                end
            end
            app.UITable_showalldata.ColumnName = colNames;
            app.UITable_showalldata.RowName = {};
            app.UITable_showalldata.Data = data;
        end

        function timeStr = formatElapsedHM(~, elapsedSec)
            elapsedSec = max(0, round(double(elapsedSec)));
            h = floor(elapsedSec / 3600);
            m = floor(mod(elapsedSec, 3600) / 60);
            timeStr = sprintf('%d h %d min', h, m);
        end

        function showBatchStatisticsInTable(app, elapsedSec)
            T = app.ResultsTable;
            colNames = {'Total Models', 'Calculation Time', 'Max Voltage (Intact)', 'Max Voltage (Pit)', 'Max FC', 'Min FC'};
            app.UITable.ColumnName = colNames;
            app.UITable.RowName = {};
            if isempty(T) || height(T) == 0
                app.UITable.Data = [];
                outPut_Text2(app, 'UITable statistics: ResultsTable is empty.');
                return
            end
            maxVW = -inf;
            maxVP = -inf;
            maxFC = -inf;
            minFC = inf;
            for i = 1:height(T)
                VW = T.V_weifushi{i};
                VP = T.V_pit{i};
                FC = T.FC{i};
                if ~isempty(VW)
                    maxVW = max(maxVW, max(VW(:), [], 'omitnan'));
                end
                if ~isempty(VP)
                    maxVP = max(maxVP, max(VP(:), [], 'omitnan'));
                end
                if ~isempty(FC)
                    maxFC = max(maxFC, max(FC(:), [], 'omitnan'));
                    minFC = min(minFC, min(FC(:), [], 'omitnan'));
                end
            end
            if ~isfinite(maxVW), maxVW = NaN; end
            if ~isfinite(maxVP), maxVP = NaN; end
            if ~isfinite(maxFC), maxFC = NaN; end
            if ~isfinite(minFC), minFC = NaN; end
            timeStr = formatElapsedHM(app, elapsedSec);
            app.UITable.Data = {height(T), timeStr, maxVW, maxVP, maxFC, minFC};
        end

        function displaySelectDataInTable(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                app.UITable_showselectdata.Data = [];
                app.UITable_showselectdata.ColumnName = {};
                updateSmdParamDropDowns(app);
                updateCorrelationTree(app);
                updateUnivariateDropDowns(app);
                updateMultivariateDropDowns(app);
                updatePredictionTrees(app);
                outPut_Text2(app, 'UITable_showselectdata: SelectData is empty.');
                return;
            end
            rangeMode = app.DataRangeDropDown.Value;
            if strcmp(rangeMode, 'Top 10 Rows')
                nShow = min(10, height(T));
                T = T(1:nShow, :);
            end
            [data, colNames] = resultsTableToDisplayMatrix(app, T);
            app.UITable_showselectdata.ColumnName = colNames;
            app.UITable_showselectdata.RowName = {};
            app.UITable_showselectdata.Data = data;
            updateSmdParamDropDowns(app);
            updateCorrelationTree(app);
            updateUnivariateDropDowns(app);
            updateMultivariateDropDowns(app);
            updatePredictionTrees(app);
        end

        function [data, colNames] = resultsTableToDisplayMatrix(~, T)
            n = height(T);
            nFc = 0;
            for i = 1:n
                FC = T.FC{i};
                if ~isempty(FC)
                    nFc = max(nFc, numel(FC));
                end
            end
            paramNames = {'Index','PipeL','PipeR','PipeS','pitr','pith','rows','cols','spacing','sigma','current'};
            nParam = numel(paramNames);
            if nFc > 0
                fcNames = arrayfun(@(k) sprintf('FC_%d', k), 1:nFc, 'UniformOutput', false);
            else
                fcNames = {};
            end
            colNames = [paramNames, fcNames];
            data = nan(n, nParam + nFc);
            for i = 1:n
                data(i, 1:nParam) = [i, T.L(i), T.piper(i), T.S(i), T.r(i), T.h(i), ...
                    T.rows(i), T.cols(i), T.spacing(i), T.sigma(i), T.current(i)];
                FC = T.FC{i};
                if ~isempty(FC)
                    flat = reshape(FC.', 1, []);
                    data(i, nParam+(1:numel(flat))) = flat;
                end
            end
        end

        function T = excelFileToResultsTable(app, fpath)
            sheets = getExcelSheetNames(app, fpath);
            vwSheet = matchSheetName(app, sheets, {'IntactVoltage', 'V_weifushi'});
            vpSheet = matchSheetName(app, sheets, {'PitVoltage', 'V_pit'});
            fcSheet = matchSheetName(app, sheets, {'FC', 'Results', 'SelectData'});
            if ~isempty(fcSheet)
                T = excelTableToResultsTable(app, readtable(fpath, 'Sheet', fcSheet));
            else
                T = excelTableToResultsTable(app, readtable(fpath));
            end
            if ~isempty(vwSheet)
                Tvw = excelTableToResultsTable(app, readtable(fpath, 'Sheet', vwSheet));
                n = min(height(T), height(Tvw));
                for i = 1:n
                    if ~isempty(Tvw.V_weifushi{i})
                        T.V_weifushi{i} = Tvw.V_weifushi{i};
                    end
                end
            end
            if ~isempty(vpSheet)
                Tvp = excelTableToResultsTable(app, readtable(fpath, 'Sheet', vpSheet));
                n = min(height(T), height(Tvp));
                for i = 1:n
                    if ~isempty(Tvp.V_pit{i})
                        T.V_pit{i} = Tvp.V_pit{i};
                    end
                end
            end
            T = normalizeLoadedResultsTable(app, T);
        end

        function T = normalizeLoadedResultsTable(app, T)
            if isempty(T) || ~istable(T)
                T = createEmptyResultsTable(app);
                return
            end
            n = height(T);
            need = {'V_weifushi', 'V_pit', 'FC'};
            for k = 1:numel(need)
                name = need{k};
                if ~ismember(name, T.Properties.VariableNames)
                    T.(name) = repmat({[]}, n, 1);
                elseif ~iscell(T.(name))
                    col = T.(name);
                    c = cell(n, 1);
                    for i = 1:n
                        try
                            c{i} = col(i, :);
                        catch
                            c{i} = [];
                        end
                    end
                    T.(name) = c;
                end
            end
            for i = 1:n
                VW = T.V_weifushi{i};
                VP = T.V_pit{i};
                FC = T.FC{i};
                if isempty(FC) && ~isempty(VW) && ~isempty(VP) && isequal(size(VW), size(VP))
                    T.FC{i} = (VP ./ VW - 1) * 1000;
                end
            end
        end

        function names = getExcelSheetNames(~, fpath)
            names = {};
            try
                names = cellstr(sheetnames(fpath));
            catch
                try
                    [~, names] = xlsfinfo(fpath);
                    if ischar(names)
                        names = {names};
                    elseif isstring(names)
                        names = cellstr(names);
                    end
                catch
                    names = {};
                end
            end
            if isempty(names)
                names = {};
            end
        end

        function name = matchSheetName(~, sheets, candidates)
            name = '';
            if isempty(sheets)
                return
            end
            sheets = cellstr(sheets);
            for i = 1:numel(candidates)
                idx = find(strcmpi(sheets, candidates{i}), 1);
                if ~isempty(idx)
                    name = sheets{idx};
                    return
                end
            end
        end

        function names = sortedPrefixedNames(~, vn, prefix)
            mask = startsWith(vn, prefix, 'IgnoreCase', true);
            names = vn(mask);
            if isempty(names)
                return
            end
            idx = nan(size(names));
            for k = 1:numel(names)
                tok = regexp(names{k}, '\d+$', 'match', 'once');
                if isempty(tok)
                    idx(k) = k;
                else
                    idx(k) = str2double(tok);
                end
            end
            [~, ord] = sort(idx);
            names = names(ord);
        end

        function M = extractRowMatrixFromColumns(~, excelT, colNames, i, rows, cols)
            if isempty(colNames)
                M = [];
                return
            end
            flat = nan(1, numel(colNames));
            for j = 1:numel(colNames)
                v = excelT.(colNames{j})(i);
                if iscell(v)
                    if isempty(v)
                        v = NaN;
                    else
                        v = v{1};
                    end
                end
                if isstring(v) || ischar(v)
                    v = str2double(v);
                end
                if isempty(v) || ~isnumeric(v)
                    v = NaN;
                else
                    v = double(v(1));
                end
                flat(j) = v;
            end
            flat = flat(~isnan(flat));
            if ~isempty(flat) && rows >= 1 && cols >= 1 && numel(flat) == rows * cols
                M = reshape(flat, cols, rows).';
            else
                M = flat;
            end
        end

        function T = excelTableToResultsTable(app, excelT)
            vn = cellstr(excelT.Properties.VariableNames);
            req = {'PipeL','PipeR','PipeS','pitr','pith','rows','cols','spacing','sigma','current'};
            for k = 1:numel(req)
                if ~any(strcmpi(vn, req{k}))
                    error('Excel is missing the necessary column: %s', req{k});
                end
            end
            getCol = @(name) excelT.(vn{find(strcmpi(vn, name), 1, 'first')});
            vwNames = sortedPrefixedNames(app, vn, 'Vw_');
            vpNames = sortedPrefixedNames(app, vn, 'Vp_');
            fcNames = sortedPrefixedNames(app, vn, 'FC_');
            T = createEmptyResultsTable(app);
            PipeL = getCol('PipeL'); PipeR = getCol('PipeR'); PipeS = getCol('PipeS');
            pitr = getCol('pitr'); pith = getCol('pith');
            rowsCol = getCol('rows'); colsCol = getCol('cols');
            spacingCol = getCol('spacing'); sigmaCol = getCol('sigma'); currentCol = getCol('current');
            for i = 1:height(excelT)
                rows = rowsCol(i);
                cols = colsCol(i);
                VW = extractRowMatrixFromColumns(app, excelT, vwNames, i, rows, cols);
                VP = extractRowMatrixFromColumns(app, excelT, vpNames, i, rows, cols);
                FC = extractRowMatrixFromColumns(app, excelT, fcNames, i, rows, cols);
                newRow = table(PipeL(i), PipeR(i), PipeS(i), pitr(i), pith(i), rows, cols, ...
                    spacingCol(i), sigmaCol(i), currentCol(i), {VW}, {VP}, {FC}, ...
                    'VariableNames', {'L','piper','S','r','h','rows','cols','spacing', ...
                    'sigma','current','V_weifushi','V_pit','FC'});
                T = [T; newRow];
            end
        end

        function openSelecteDataDialog(app, sourceTable, sourceName)
            isDb = strcmp(sourceName, 'Database');
            try
                if isDb
                    [meta, rowsVals, colsVals, sigmaVals, currVals] = getParamOptionsFromDatabase(app);
                else
                    [meta, rowsVals, colsVals, sigmaVals, currVals] = getParamOptionsFromTable(app, sourceTable);
                end
            catch ME
                uialert(app.VDMMSMUIFigure, ME.message, 'Failed to load parameters');
                return;
            end
            if isempty(rowsVals) || isempty(colsVals) || isempty(sigmaVals) || isempty(currVals)
                uialert(app.VDMMSMUIFigure, 'The list of available parameters is empty.', 'No data');
                return;
            end

            fig = uifigure('Name', sprintf('Select Data (%s)', sourceName), ...
                'Position', [420 160 540 560], 'Resize', 'off');
            pPipe = uipanel(fig, 'Title', 'Pipeline (min ~ max)', 'Position', [20 390 500 120]);
            [efLmin, efLmax] = makeMinMaxRow(pPipe, 'Length (L):', 10, 70, meta.Lmin, meta.Lmax);
            [efRmin, efRmax] = makeMinMaxRow(pPipe, 'Radius (R):', 10, 40, meta.Rmin, meta.Rmax);
            [efSmin, efSmax] = makeMinMaxRow(pPipe, 'Thickness (S):', 10, 10, meta.Smin, meta.Smax);
            pPit = uipanel(fig, 'Title', 'Pitting (min ~ max)', 'Position', [20 290 500 90]);
            [efrmin, efrmax] = makeMinMaxRow(pPit, 'Radius (r):', 10, 40, meta.rmin, meta.rmax);
            [efhmin, efhmax] = makeMinMaxRow(pPit, 'Depth (h):', 10, 10, meta.hmin, meta.hmax);
            pElec = uipanel(fig, 'Title', 'Electrode', 'Position', [20 160 500 120]);
            uilabel(pElec, 'Text', 'Rows:', 'Position', [10 70 80 22], 'HorizontalAlignment', 'right');
            ddRows = uidropdown(pElec, 'Items', arrayfun(@num2str, rowsVals, 'UniformOutput', false), ...
                'ItemsData', rowsVals, 'Position', [100 70 100 22], 'Value', rowsVals(1));
            uilabel(pElec, 'Text', 'Cols:', 'Position', [230 70 80 22], 'HorizontalAlignment', 'right');
            ddCols = uidropdown(pElec, 'Items', arrayfun(@num2str, colsVals, 'UniformOutput', false), ...
                'ItemsData', colsVals, 'Position', [320 70 100 22], 'Value', colsVals(1));
            [efDmin, efDmax] = makeMinMaxRow(pElec, 'Spacing:', 10, 30, meta.dmin, meta.dmax);
            pPhy = uipanel(fig, 'Title', 'Physical Field (single value)', 'Position', [20 55 500 95]);
            uilabel(pPhy, 'Text', 'Sigma:', 'Position', [10 45 80 22], 'HorizontalAlignment', 'right');
            ddSigma = uidropdown(pPhy, ...
                'Items', arrayfun(@(x) sprintf('%.8g', x), sigmaVals, 'UniformOutput', false), ...
                'ItemsData', sigmaVals, 'Position', [100 45 140 22], 'Value', sigmaVals(1));
            uilabel(pPhy, 'Text', 'Current:', 'Position', [250 45 70 22], 'HorizontalAlignment', 'right');
            ddCurr = uidropdown(pPhy, ...
                'Items', arrayfun(@(x) sprintf('%.8g', x), currVals, 'UniformOutput', false), ...
                'ItemsData', currVals, 'Position', [330 45 120 22], 'Value', currVals(1));
            pickClosest(ddRows, app.RowsEditField.Value);
            pickClosest(ddCols, app.ColumnsEditField.Value);
            pickClosest(ddSigma, app.ConductivitySpinner.Value);
            pickClosest(ddCurr, app.CurrentSpinner.Value);
            uibutton(fig, 'push', 'Text', 'Confirm', 'Position', [150 15 100 28], ...
                'ButtonPushedFcn', @(~,~) onConfirm());
            uibutton(fig, 'push', 'Text', 'Cancel', 'Position', [290 15 100 28], ...
                'ButtonPushedFcn', @(~,~) close(fig));

            function [efMin, efMax] = makeMinMaxRow(parent, labelText, x, y, vmin, vmax)
                uilabel(parent, 'Text', labelText, 'Position', [x y 100 22], 'HorizontalAlignment', 'right');
                efMin = uieditfield(parent, 'numeric', 'Position', [x+110 y 100 22], 'Value', vmin);
                uilabel(parent, 'Text', '~', 'Position', [x+215 y 20 22], 'HorizontalAlignment', 'center');
                efMax = uieditfield(parent, 'numeric', 'Position', [x+240 y 100 22], 'Value', vmax);
            end

            function pickClosest(dd, target)
                vals = dd.ItemsData;
                if isempty(vals)
                    return;
                end
                [~, idx] = min(abs(vals - target));
                dd.Value = vals(idx);
            end

            function onConfirm()
                filters = struct( ...
                    'Lmin', efLmin.Value, 'Lmax', efLmax.Value, ...
                    'Rmin', efRmin.Value, 'Rmax', efRmax.Value, ...
                    'Smin', efSmin.Value, 'Smax', efSmax.Value, ...
                    'rmin', efrmin.Value, 'rmax', efrmax.Value, ...
                    'hmin', efhmin.Value, 'hmax', efhmax.Value, ...
                    'dmin', efDmin.Value, 'dmax', efDmax.Value, ...
                    'rows', ddRows.Value, 'cols', ddCols.Value, ...
                    'sigma', ddSigma.Value, 'current', ddCurr.Value);
                try
                    if isDb
                        T = queryDatabaseByParams(app, filters);
                    else
                        T = filterResultsTableByParams(app, sourceTable, filters);
                    end
                    if isempty(T) || height(T) == 0
                        uialert(fig, 'No eligible data was filtered out.', 'Filtering failed');
                        return;
                    end
                    app.SelectData = T;
                    displaySelectDataInTable(app);
                    outPut_Text2(app, sprintf('Load %s: %d rows selected.', sourceName, height(T)));
                    close(fig);
                catch ME
                    uialert(fig, ME.message, 'Filtering failed');
                end
            end
        end

        function [meta, rowsVals, colsVals, sigmaVals, currVals] = getParamOptionsFromTable(~, T)
            if isempty(T) || height(T) == 0
                error('The source data table is empty.');
            end
            meta = struct( ...
                'Lmin', min(T.L), 'Lmax', max(T.L), ...
                'Rmin', min(T.piper), 'Rmax', max(T.piper), ...
                'Smin', min(T.S), 'Smax', max(T.S), ...
                'rmin', min(T.r), 'rmax', max(T.r), ...
                'hmin', min(T.h), 'hmax', max(T.h), ...
                'dmin', min(T.spacing), 'dmax', max(T.spacing));
            rowsVals = unique(T.rows(:)');
            colsVals = unique(T.cols(:)');
            sigmaVals = unique(T.sigma(:)');
            currVals = unique(T.current(:)');
        end

        function [meta, rowsVals, colsVals, sigmaVals, currVals] = getParamOptionsFromDatabase(app)
            exec(app.conn, 'USE pipepitssim;');
            m = fetch(app.conn, [ ...
                'SELECT MIN(pipeL) AS Lmin, MAX(pipeL) AS Lmax, ', ...
                'MIN(pipeR) AS Rmin, MAX(pipeR) AS Rmax, ', ...
                'MIN(pipeS) AS Smin, MAX(pipeS) AS Smax, ', ...
                'MIN(pitr) AS rmin, MAX(pitr) AS rmax, ', ...
                'MIN(pith) AS hmin, MAX(pith) AS hmax, ', ...
                'MIN(spacing) AS dmin, MAX(spacing) AS dmax ', ...
                'FROM simulation_records']);
            if isempty(m) || height(m) == 0 || isnan(m.Lmin(1))
                error('No Data');
            end
            meta = struct( ...
                'Lmin', m.Lmin(1), 'Lmax', m.Lmax(1), ...
                'Rmin', m.Rmin(1), 'Rmax', m.Rmax(1), ...
                'Smin', m.Smin(1), 'Smax', m.Smax(1), ...
                'rmin', m.rmin(1), 'rmax', m.rmax(1), ...
                'hmin', m.hmin(1), 'hmax', m.hmax(1), ...
                'dmin', m.dmin(1), 'dmax', m.dmax(1));
            rowsTbl = fetch(app.conn, 'SELECT DISTINCT elec_rows FROM simulation_records ORDER BY elec_rows');
            colsTbl = fetch(app.conn, 'SELECT DISTINCT elec_cols FROM simulation_records ORDER BY elec_cols');
            sigmaTbl = fetch(app.conn, 'SELECT DISTINCT sigma FROM simulation_records ORDER BY sigma');
            currTbl = fetch(app.conn, 'SELECT DISTINCT current_a FROM simulation_records ORDER BY current_a');
            rowsVals = double(rowsTbl.elec_rows(:)');
            colsVals = double(colsTbl.elec_cols(:)');
            sigmaVals = double(sigmaTbl.sigma(:)');
            currVals = double(currTbl.current_a(:)');
        end

        function T = filterResultsTableByParams(~, srcT, f)
            mask = srcT.L >= f.Lmin & srcT.L <= f.Lmax & ...
                srcT.piper >= f.Rmin & srcT.piper <= f.Rmax & ...
                srcT.S >= f.Smin & srcT.S <= f.Smax & ...
                srcT.r >= f.rmin & srcT.r <= f.rmax & ...
                srcT.h >= f.hmin & srcT.h <= f.hmax & ...
                srcT.spacing >= f.dmin & srcT.spacing <= f.dmax & ...
                srcT.rows == f.rows & srcT.cols == f.cols & ...
                srcT.sigma == f.sigma & srcT.current == f.current;
            T = srcT(mask, :);
        end

        function T = queryDatabaseByParams(app, f)
            sql = sprintf([ ...
                'SELECT pipeL, pipeR, pipeS, pitr, pith, elec_rows, elec_cols, spacing, ', ...
                'sigma, current_a, V_weifushi, V_pit, FC ', ...
                'FROM simulation_records WHERE ', ...
                'pipeL BETWEEN %.16g AND %.16g AND ', ...
                'pipeR BETWEEN %.16g AND %.16g AND ', ...
                'pipeS BETWEEN %.16g AND %.16g AND ', ...
                'pitr BETWEEN %.16g AND %.16g AND ', ...
                'pith BETWEEN %.16g AND %.16g AND ', ...
                'spacing BETWEEN %.16g AND %.16g AND ', ...
                'elec_rows = %d AND elec_cols = %d AND ', ...
                'sigma = %.16g AND current_a = %.16g ', ...
                'ORDER BY id'], ...
                f.Lmin, f.Lmax, f.Rmin, f.Rmax, f.Smin, f.Smax, ...
                f.rmin, f.rmax, f.hmin, f.hmax, f.dmin, f.dmax, ...
                round(f.rows), round(f.cols), f.sigma, f.current);
            dbT = fetch(app.conn, sql);
            T = dbTableToResultsTable(app, dbT);
        end

        function T = dbTableToResultsTable(app, dbT)
            T = createEmptyResultsTable(app);
            if isempty(dbT) || height(dbT) == 0
                return;
            end
            for i = 1:height(dbT)
                VW = decodeJsonMatrix(app, dbT.V_weifushi(i));
                VP = decodeJsonMatrix(app, dbT.V_pit(i));
                FC = decodeJsonMatrix(app, dbT.FC(i));
                newRow = table(dbT.pipeL(i), dbT.pipeR(i), dbT.pipeS(i), ...
                    dbT.pitr(i), dbT.pith(i), double(dbT.elec_rows(i)), double(dbT.elec_cols(i)), ...
                    dbT.spacing(i), dbT.sigma(i), dbT.current_a(i), ...
                    {VW}, {VP}, {FC}, ...
                    'VariableNames', {'L','piper','S','r','h','rows','cols','spacing', ...
                    'sigma','current','V_weifushi','V_pit','FC'});
                T = [T; newRow];
            end
        end

        function M = decodeJsonMatrix(~, raw)
            if iscell(raw)
                raw = raw{1};
            end
            if isempty(raw)
                M = [];
                return;
            end
            if isnumeric(raw)
                M = raw;
                return;
            end
            if isstring(raw)
                raw = char(raw);
            end
            if ~ischar(raw)
                raw = char(string(raw));
            end
            try
                M = jsondecode(raw);
            catch
                M = [];
            end
        end

        function updateSmdParamDropDowns(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                clearNumericDropDown(app, app.PipeLDropDown);
                clearNumericDropDown(app, app.PipeRDropDown);
                clearNumericDropDown(app, app.PipeTDropDown);
                clearNumericDropDown(app, app.PitrDropDown);
                clearNumericDropDown(app, app.PithDropDown);
                clearNumericDropDown(app, app.SpacingDropDown);
                return;
            end
            setNumericDropDown(app, app.PipeLDropDown, unique(T.L(:)'));
            setNumericDropDown(app, app.PipeRDropDown, unique(T.piper(:)'));
            setNumericDropDown(app, app.PipeTDropDown, unique(T.S(:)'));
            setNumericDropDown(app, app.PitrDropDown, unique(T.r(:)'));
            setNumericDropDown(app, app.PithDropDown, unique(T.h(:)'));
            setNumericDropDown(app, app.SpacingDropDown, unique(T.spacing(:)'));
        end

        function setNumericDropDown(app, dd, vals)
            vals = vals(~isnan(vals));
            if isempty(vals)
                clearNumericDropDown(app, dd);
                return;
            end
            dd.Items = arrayfun(@(x) sprintf('%.8g', x), vals, 'UniformOutput', false);
            dd.ItemsData = vals;
            dd.Value = vals(1);
        end

        function clearNumericDropDown(~, dd)
            dd.Items = {};
            dd.ItemsData = {};
            dd.Value = {};
        end

        function displaySingleModelData(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                uialert(app.VDMMSMUIFigure, 'Please load data via the data selection module first.', 'No Data');
                return;
            end
            if isempty(app.PipeLDropDown.ItemsData) || isempty(app.PipeRDropDown.ItemsData) || ...
                    isempty(app.PipeTDropDown.ItemsData) || isempty(app.PitrDropDown.ItemsData) || ...
                    isempty(app.PithDropDown.ItemsData) || isempty(app.SpacingDropDown.ItemsData)
                uialert(app.VDMMSMUIFigure, 'The parameter dropdown list is empty. Please load valid data in advance.', 'No Data');
                return;
            end
            L = app.PipeLDropDown.Value;
            R = app.PipeRDropDown.Value;
            S = app.PipeTDropDown.Value;
            r = app.PitrDropDown.Value;
            h = app.PithDropDown.Value;
            spacing = app.SpacingDropDown.Value;
            mask = abs(T.L - L) < 1e-12 & abs(T.piper - R) < 1e-12 & abs(T.S - S) < 1e-12 & ...
                abs(T.r - r) < 1e-12 & abs(T.h - h) < 1e-12 & abs(T.spacing - spacing) < 1e-12;
            idx = find(mask, 1, 'first');
            if isempty(idx)
                uialert(app.VDMMSMUIFigure, 'No model data fully matching the selected parameters was found.', 'No Match');
                return;
            end
            FC = T.FC{idx};
            rows = round(T.rows(idx));
            cols = round(T.cols(idx));
            if isempty(FC)
                uialert(app.VDMMSMUIFigure, 'This model record does not contain FC data.', 'Missing FC Data');
                return;
            end
            nDispRows = rows;
            nDispCols = max(cols - 1, 1);
            flat = reshape(FC.', 1, []);
            flat = flat(~isnan(flat));
            nExpect = nDispRows * nDispCols;
            if numel(flat) < nExpect
                uialert(app.VDMMSMUIFigure, sprintf( ...
                    'Insufficient number of FC elements: Required %d elements (%d rows × %d columns), actual count: %d.', ...
                    nExpect, nDispRows, nDispCols, numel(flat)), 'Data Dimension Mismatch');
                return;
            end
            FC = reshape(flat(1:nExpect), nDispCols, nDispRows).';
            app.UITable_smd.Data = FC;
            app.UITable_smd.ColumnName = arrayfun(@(k) sprintf('Col%d', k), ...
                1:nDispCols, 'UniformOutput', false);
            app.UITable_smd.RowName = arrayfun(@(k) sprintf('Row%d', k), ...
                1:nDispRows, 'UniformOutput', false);
            cla(app.UIAxes_smd);
            bar3(app.UIAxes_smd, FC);
            title(app.UIAxes_smd, sprintf('FC Distribution (%d×%d)', nDispRows, nDispCols));
            xlabel(app.UIAxes_smd, 'Column');
            ylabel(app.UIAxes_smd, 'Row');
            zlabel(app.UIAxes_smd, 'FC');
            view(app.UIAxes_smd, 45, 30);
            outPut_Text2(app, sprintf( ...
                'SMD: L=%.8g, R=%.8g, T=%.8g, r=%.8g, h=%.8g, spacing=%.8g | electrode %dx%d → FC %dx%d', ...
                L, R, S, r, h, spacing, rows, cols, nDispRows, nDispCols));
        end

        function updateCorrelationTree(app)
            delete(app.Tree_CA.Children);
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                return;
            end
            nFc = 0;
            for i = 1:height(T)
                FC = T.FC{i};
                if ~isempty(FC)
                    nFc = max(nFc, numel(FC));
                end
            end
            nodePipe = uitreenode(app.Tree_CA, 'Text', 'pipeline');
            uitreenode(nodePipe, 'Text', 'PipeL', 'NodeData', 'PipeL');
            uitreenode(nodePipe, 'Text', 'PipeR', 'NodeData', 'PipeR');
            uitreenode(nodePipe, 'Text', 'PipeS', 'NodeData', 'PipeS');
            nodePit = uitreenode(app.Tree_CA, 'Text', 'pits');
            uitreenode(nodePit, 'Text', 'pitr', 'NodeData', 'pitr');
            uitreenode(nodePit, 'Text', 'pith', 'NodeData', 'pith');
            nodeSp = uitreenode(app.Tree_CA, 'Text', 'Array');
            uitreenode(nodeSp, 'Text', 'spacing', 'NodeData', 'spacing');
            nodeFc = uitreenode(app.Tree_CA, 'Text', 'FC Value');
            for k = 1:nFc
                uitreenode(nodeFc, 'Text', sprintf('FC_%d', k), 'NodeData', sprintf('FC_%d', k));
            end
            expand(app.Tree_CA);
        end

        function keys = getCheckedCorrelationKeys(app)
            keys = {};
            nodes = app.Tree_CA.CheckedNodes;
            if isempty(nodes)
                return;
            end
            for i = 1:numel(nodes)
                nd = nodes(i).NodeData;
                if ischar(nd) || (isstring(nd) && isscalar(nd))
                    key = char(nd);
                    if ~isempty(key)
                        keys{end+1} = key;
                    end
                end
            end
            keys = unique(keys, 'stable');
        end

        function col = getSelectDataColumnByKey(~, T, key)
            switch key
                case 'PipeL'
                    col = T.L(:);
                case 'PipeR'
                    col = T.piper(:);
                case 'PipeS'
                    col = T.S(:);
                case 'pitr'
                    col = T.r(:);
                case 'pith'
                    col = T.h(:);
                case 'spacing'
                    col = T.spacing(:);
                otherwise
                    if startsWith(key, 'FC_')
                        k = sscanf(key, 'FC_%d');
                        col = nan(height(T), 1);
                        for i = 1:height(T)
                            FC = T.FC{i};
                            if isempty(FC)
                                continue;
                            end
                            flat = reshape(FC.', 1, []);
                            if k >= 1 && k <= numel(flat)
                                col(i) = flat(k);
                            end
                        end
                    else
                        error('Unknown parameter: %s', key);
                    end
            end
        end

        function runCorrelationAnalysis(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                uialert(app.VDMMSMUIFigure, 'Please load data via the data selection module first.', 'No Data');
                return;
            end
            keys = getCheckedCorrelationKeys(app);
            if numel(keys) < 2
                uialert(app.VDMMSMUIFigure, 'Please check at least 2 parameters.', 'Missing Parameters');
                return;
            end
            n = height(T);
            p = numel(keys);
            X = nan(n, p);
            for j = 1:p
                X(:, j) = getSelectDataColumnByKey(app, T, keys{j});
            end
            validRows = all(isfinite(X), 2);
            X = X(validRows, :);
            if size(X, 1) < 2
                uialert(app.VDMMSMUIFigure, 'Valid samples are insufficient (at least 2 rows of complete data are required).', 'Missing Samples');
                return;
            end
            corrType = app.CorrelationTypeDropDown.Value;
            try
                if strcmpi(corrType, 'Spearman')
                    R = corr(X, 'Type', 'Spearman', 'Rows', 'complete');
                else
                    R = corr(X, 'Type', 'Pearson', 'Rows', 'complete');
                end
            catch ME
                uialert(app.VDMMSMUIFigure, ME.message, 'Correlation calculation failed！');
                return;
            end
            app.UITable4.Data = R;
            app.UITable4.ColumnName = keys;
            app.UITable4.RowName = keys;

            cla(app.UIAxes6);
            imagesc(app.UIAxes6, R);
            axis(app.UIAxes6, 'image');
            colormap(app.UIAxes6, turbo);
            try
                clim(app.UIAxes6, [-1 1]);
            catch
                caxis(app.UIAxes6, [-1 1]);
            end
            hold(app.UIAxes6, 'on');
            for k = 0.5:1:(p + 0.5)
                plot(app.UIAxes6, [0.5, p + 0.5], [k, k], 'k-', 'LineWidth', 0.8);
                plot(app.UIAxes6, [k, k], [0.5, p + 0.5], 'k-', 'LineWidth', 0.8);
            end
            for i = 1:p
                for j = 1:p
                    val = R(i, j);
                    if ~isfinite(val)
                        continue;
                    end
                    if abs(val) > 0.55
                        tc = [1 1 1];
                    else
                        tc = [0 0 0];
                    end
                    text(app.UIAxes6, j, i, sprintf('%.2f', val), ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 9, 'Color', tc, 'FontWeight', 'bold', ...
                        'Clipping', 'on');
                end
            end
            hold(app.UIAxes6, 'off');
            cb = colorbar(app.UIAxes6);
            cb.Label.String = 'Correlation';
            app.UIAxes6.XTick = 1:p;
            app.UIAxes6.YTick = 1:p;
            app.UIAxes6.XTickLabel = keys;
            app.UIAxes6.YTickLabel = keys;
            app.UIAxes6.XTickLabelRotation = 45;
            title(app.UIAxes6, sprintf('%s Correlation Heatmap', corrType));
            xlabel(app.UIAxes6, '');
            ylabel(app.UIAxes6, '');
            outPut_Text2(app, sprintf( ...
                'Correlation (%s): %d params, %d valid samples.', corrType, p, size(X, 1)));
        end

        function updateUnivariateDropDowns(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                app.XDropDown.Items = {};
                app.XDropDown.Value = {};
                app.YDropDown.Items = {};
                app.YDropDown.Value = {};
                return;
            end
            xItems = {'PipeL', 'PipeR', 'PipeS', 'pitr', 'pith', 'spacing'};
            app.XDropDown.Items = xItems;
            app.XDropDown.Value = xItems{1};
            nFc = 0;
            for i = 1:height(T)
                FC = T.FC{i};
                if ~isempty(FC)
                    nFc = max(nFc, numel(FC));
                end
            end
            if nFc < 1
                app.YDropDown.Items = {};
                app.YDropDown.Value = {};
            else
                yItems = arrayfun(@(k) sprintf('FC_%d', k), 1:nFc, 'UniformOutput', false);
                app.YDropDown.Items = yItems;
                app.YDropDown.Value = yItems{1};
            end
        end

        function [xp, yp, xLabel, yLabel] = applyProcessing(~, x, y, mode, xName, yName)
            switch mode
                case 'Logarithm'
                    mask = isfinite(x) & isfinite(y) & (x > 0) & (y > 0);
                    xp = log(x(mask));
                    yp = log(y(mask));
                    xLabel = sprintf('ln(%s)', xName);
                    yLabel = sprintf('ln(%s)', yName);
                case 'Reciprocal'
                    mask = isfinite(x) & isfinite(y) & (x ~= 0) & (y ~= 0);
                    xp = 1 ./ x(mask);
                    yp = 1 ./ y(mask);
                    xLabel = sprintf('1/%s', xName);
                    yLabel = sprintf('1/%s', yName);
                otherwise
                    mask = isfinite(x) & isfinite(y);
                    xp = x(mask);
                    yp = y(mask);
                    xLabel = xName;
                    yLabel = yName;
            end
            xp = xp(:);
            yp = yp(:);
        end

        function [yhat, eqStr, metrics, xfit, yfit] = fitUnivariateXY(~, method, x, y)
            x = x(:); y = y(:);
            switch method
                case 'Polynomial'
                    coef = polyfit(x, y, 2);
                    yhat = polyval(coef, x);
                    eqStr = sprintf('y = %.6g*x^2 + %.6g*x + %.6g', coef(1), coef(2), coef(3));
                    xfit = linspace(min(x), max(x), 200)';
                    yfit = polyval(coef, xfit);
                otherwise
                    coef = polyfit(x, y, 1);
                    yhat = polyval(coef, x);
                    eqStr = sprintf('y = %.6g*x + %.6g', coef(1), coef(2));
                    xfit = linspace(min(x), max(x), 200)';
                    yfit = polyval(coef, xfit);
            end
            resid = y - yhat;
            ssRes = sum(resid.^2);
            ssTot = sum((y - mean(y)).^2);
            if ssTot <= 0
                R2 = NaN;
            else
                R2 = 1 - ssRes / ssTot;
            end
            metrics = struct( ...
                'R2', R2, ...
                'RMSE', sqrt(mean(resid.^2)), ...
                'MAE', mean(abs(resid)), ...
                'SSE', ssRes, ...
                'N', numel(y));
        end

        function runUnivariateAnalysis(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                uialert(app.VDMMSMUIFigure, 'Please load data via the data selection module first.', 'No Data');
                return;
            end
            if isempty(app.XDropDown.Items) || isempty(app.YDropDown.Items)
                uialert(app.VDMMSMUIFigure, 'Please load data containing FC first.', 'No Parameters');
                return;
            end
            xName = app.XDropDown.Value;
            yName = app.YDropDown.Value;
            if isempty(xName) || isempty(yName)
                uialert(app.VDMMSMUIFigure, 'Please select the X and Y parameters.', 'No Parameters');
                return;
            end
            x = getSelectDataColumnByKey(app, T, xName);
            y = getSelectDataColumnByKey(app, T, yName);
            proc = app.ProcessingDropDown.Value;
            [xp, yp, xLabel, yLabel] = applyProcessing(app, x, y, proc, xName, yName);
            if numel(xp) < 2
                uialert(app.VDMMSMUIFigure, 'Insufficient valid data points', 'Missing Data');
                return;
            end
            cla(app.UIAxes3);
            hold(app.UIAxes3, 'on');
            scatter(app.UIAxes3, xp, yp, 36, 'filled', 'DisplayName', 'Data');
            xlabel(app.UIAxes3, xLabel);
            ylabel(app.UIAxes3, yLabel);
            grid(app.UIAxes3, 'on');
            doFit = app.CheckBox.Value;
            if doFit
                method = app.FittingMethodDropDown.Value;
                try
                    [~, eqStr, metrics, xfit, yfit] = fitUnivariateXY(app, method, xp, yp);
                catch ME
                    hold(app.UIAxes3, 'off');
                    uialert(app.VDMMSMUIFigure, ME.message, 'Fitting failed');
                    return;
                end
                plot(app.UIAxes3, xfit, yfit, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Fit');
                app.FittingResultTextArea.Value = { ...
                    sprintf('%s', eqStr); ...
                    sprintf('R^2 = %.6g', metrics.R2); ...
                    sprintf('RMSE = %.6g', metrics.RMSE); ...
                    sprintf('MAE = %.6g', metrics.MAE)};
                title(app.UIAxes3, sprintf('Univariate: %s vs %s (%s fit)', yName, xName, method));
                outPut_Text2(app, sprintf('UA fit (%s, %s): %s | R2=%.4g', method, proc, eqStr, metrics.R2));
            else
                app.FittingResultTextArea.Value = { ...
                    'Fitting disabled (CheckBox unchecked).'; ...
                    sprintf('Processing: %s', proc); ...
                    sprintf('X: %s | Y: %s', xName, yName); ...
                    sprintf('N = %d', numel(xp))};
                title(app.UIAxes3, sprintf('Univariate: %s vs %s (scatter)', yName, xName));
                outPut_Text2(app, sprintf('UA scatter: %s vs %s | N=%d | Processing=%s', ...
                    yName, xName, numel(xp), proc));
            end
            legend(app.UIAxes3, 'Location', 'best');
            hold(app.UIAxes3, 'off');
        end

        function updateMultivariateDropDowns(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                app.X1DropDown.Items = {};
                app.X1DropDown.Value = {};
                app.X2DropDown.Items = {};
                app.X2DropDown.Value = {};
                app.YDropDown_3.Items = {};
                app.YDropDown_3.Value = {};
                return;
            end
            xItems = {'PipeL', 'PipeR', 'PipeS', 'pitr', 'pith', 'spacing'};
            app.X1DropDown.Items = xItems;
            app.X1DropDown.Value = xItems{1};
            app.X2DropDown.Items = xItems;
            if numel(xItems) >= 2
                app.X2DropDown.Value = xItems{2};
            else
                app.X2DropDown.Value = xItems{1};
            end

            nFc = 0;
            for i = 1:height(T)
                FC = T.FC{i};
                if ~isempty(FC)
                    nFc = max(nFc, numel(FC));
                end
            end
            if nFc < 1
                app.YDropDown_3.Items = {};
                app.YDropDown_3.Value = {};
            else
                yItems = arrayfun(@(k) sprintf('FC_%d', k), 1:nFc, 'UniformOutput', false);
                app.YDropDown_3.Items = yItems;
                app.YDropDown_3.Value = yItems{1};
            end
        end

        function [x1p, x2p, yp, x1Label, x2Label, yLabel] = applyProcessing2(~, x1, x2, y, mode, x1Name, x2Name, yName)
            switch mode
                case 'Logarithm'
                    mask = isfinite(x1) & isfinite(x2) & isfinite(y) & ...
                        (x1 > 0) & (x2 > 0) & (y > 0);
                    x1p = log(x1(mask));
                    x2p = log(x2(mask));
                    yp = log(y(mask));
                    x1Label = sprintf('ln(%s)', x1Name);
                    x2Label = sprintf('ln(%s)', x2Name);
                    yLabel = sprintf('ln(%s)', yName);
                case 'Reciprocal'
                    mask = isfinite(x1) & isfinite(x2) & isfinite(y) & ...
                        (x1 ~= 0) & (x2 ~= 0) & (y ~= 0);
                    x1p = 1 ./ x1(mask);
                    x2p = 1 ./ x2(mask);
                    yp = 1 ./ y(mask);
                    x1Label = sprintf('1/%s', x1Name);
                    x2Label = sprintf('1/%s', x2Name);
                    yLabel = sprintf('1/%s', yName);
                otherwise
                    mask = isfinite(x1) & isfinite(x2) & isfinite(y);
                    x1p = x1(mask);
                    x2p = x2(mask);
                    yp = y(mask);
                    x1Label = x1Name;
                    x2Label = x2Name;
                    yLabel = yName;
            end
            x1p = x1p(:); x2p = x2p(:); yp = yp(:);
        end

        function [yhat, eqStr, metrics, x1g, x2g, yg] = fitMultivariateXYZ(~, method, x1, x2, y)
            x1 = x1(:); x2 = x2(:); y = y(:);
            n = numel(y);
            switch method
                case 'Polynomial'
                    if n < 6
                        error('Missing Data.');
                    end
                    A = [x1.^2, x2.^2, x1.*x2, x1, x2, ones(n, 1)];
                    beta = A \ y;
                    yhat = A * beta;
                    eqStr = sprintf(['y = %.6g*x1^2 + %.6g*x2^2 + %.6g*x1*x2 + ', ...
                        '%.6g*x1 + %.6g*x2 + %.6g'], ...
                        beta(1), beta(2), beta(3), beta(4), beta(5), beta(6));
                    predFun = @(u, v) beta(1).*u.^2 + beta(2).*v.^2 + beta(3).*u.*v + ...
                        beta(4).*u + beta(5).*v + beta(6);
                otherwise
                    if n < 3
                        error('Missing Data.');
                    end
                    A = [x1, x2, ones(n, 1)];
                    beta = A \ y;
                    yhat = A * beta;
                    eqStr = sprintf('y = %.6g*x1 + %.6g*x2 + %.6g', beta(1), beta(2), beta(3));
                    predFun = @(u, v) beta(1).*u + beta(2).*v + beta(3);
            end
            resid = y - yhat;
            ssRes = sum(resid.^2);
            ssTot = sum((y - mean(y)).^2);
            if ssTot <= 0
                R2 = NaN;
            else
                R2 = 1 - ssRes / ssTot;
            end
            metrics = struct( ...
                'R2', R2, ...
                'RMSE', sqrt(mean(resid.^2)), ...
                'MAE', mean(abs(resid)), ...
                'SSE', ssRes, ...
                'N', n);
            [x1g, x2g] = meshgrid(linspace(min(x1), max(x1), 30), linspace(min(x2), max(x2), 30));
            yg = predFun(x1g, x2g);
        end

        function runMultivariateAnalysis(app)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                uialert(app.VDMMSMUIFigure, 'Please load data via the data selection module first.', 'No Data');
                return;
            end
            if isempty(app.X1DropDown.Items) || isempty(app.X2DropDown.Items) || isempty(app.YDropDown_3.Items)
                uialert(app.VDMMSMUIFigure, 'Please load data containing FC first.', 'No Parameters');
                return;
            end
            x1Name = app.X1DropDown.Value;
            x2Name = app.X2DropDown.Value;
            yName = app.YDropDown_3.Value;
            if isempty(x1Name) || isempty(x2Name) || isempty(yName)
                uialert(app.VDMMSMUIFigure, 'Please select the X and Y parameters.', 'No Parameters');
                return;
            end
            if strcmp(x1Name, x2Name)
                uialert(app.VDMMSMUIFigure, 'X1 and X2 cannot select the same', 'Parameter duplication');
                return;
            end
            x1 = getSelectDataColumnByKey(app, T, x1Name);
            x2 = getSelectDataColumnByKey(app, T, x2Name);
            y = getSelectDataColumnByKey(app, T, yName);
            proc = app.ProcessingDropDown_2.Value;
            [x1p, x2p, yp, x1Label, x2Label, yLabel] = applyProcessing2( ...
                app, x1, x2, y, proc, x1Name, x2Name, yName);
            if numel(yp) < 2
                uialert(app.VDMMSMUIFigure, 'Insufficient valid data points', 'Missing Data');
                return;
            end

            cla(app.UIAxes7);
            hold(app.UIAxes7, 'on');
            scatter3(app.UIAxes7, x1p, x2p, yp, 36, 'filled', 'DisplayName', 'Data');
            xlabel(app.UIAxes7, x1Label);
            ylabel(app.UIAxes7, x2Label);
            zlabel(app.UIAxes7, yLabel);
            grid(app.UIAxes7, 'on');
            view(app.UIAxes7, 45, 30);

            doFit = app.CheckBox_2.Value;
            if doFit
                method = app.FittingMethodDropDown_2.Value;
                try
                    [~, eqStr, metrics, x1g, x2g, yg] = fitMultivariateXYZ(app, method, x1p, x2p, yp);
                catch ME
                    hold(app.UIAxes7, 'off');
                    uialert(app.VDMMSMUIFigure, ME.message, 'Fitting failed');
                    return;
                end
                surf(app.UIAxes7, x1g, x2g, yg, 'FaceAlpha', 0.45, 'EdgeColor', 'none', ...
                    'DisplayName', 'Fit');
                app.FittingResultTextArea_2.Value = { ...
                    sprintf(' %s', eqStr); ...
                    sprintf('R^2 = %.6g', metrics.R2); ...
                    sprintf('RMSE = %.6g', metrics.RMSE); ...
                    sprintf('MAE = %.6g', metrics.MAE)};
                title(app.UIAxes7, sprintf('Multivariate: %s ~ %s, %s (%s)', ...
                    yName, x1Name, x2Name, method));
                outPut_Text2(app, sprintf('MA fit (%s, %s): %s | R2=%.4g', ...
                    method, proc, eqStr, metrics.R2));
            else
                app.FittingResultTextArea_2.Value = { ...
                    'Fitting disabled (CheckBox_2 unchecked).'; ...
                    sprintf('Processing: %s', proc); ...
                    sprintf('X1: %s | X2: %s | Y: %s', x1Name, x2Name, yName); ...
                    sprintf('N = %d', numel(yp))};
                title(app.UIAxes7, sprintf('Multivariate: %s ~ %s, %s (scatter)', ...
                    yName, x1Name, x2Name));
                outPut_Text2(app, sprintf('MA scatter: %s ~ %s, %s | N=%d | Processing=%s', ...
                    yName, x1Name, x2Name, numel(yp), proc));
            end
            legend(app.UIAxes7, 'Location', 'best');
            hold(app.UIAxes7, 'off');
        end

        function updatePredictionTrees(app)
            delete(app.Tree.Children);
            delete(app.Tree_2.Children);
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                updateSEREquationTable(app);
                return;
            end
            nFc = 0;
            for i = 1:height(T)
                FC = T.FC{i};
                if ~isempty(FC)
                    nFc = max(nFc, numel(FC));
                end
            end
            nodeFc = uitreenode(app.Tree, 'Text', 'FC Values');
            for k = 1:nFc
                uitreenode(nodeFc, 'Text', sprintf('FC_%d', k), 'NodeData', sprintf('FC_%d', k));
            end
            expand(app.Tree);
            nodePipe = uitreenode(app.Tree_2, 'Text', 'Pipeline');
            uitreenode(nodePipe, 'Text', 'PipeL', 'NodeData', 'PipeL');
            uitreenode(nodePipe, 'Text', 'PipeR', 'NodeData', 'PipeR');
            uitreenode(nodePipe, 'Text', 'PipeS', 'NodeData', 'PipeS');
            nodePit = uitreenode(app.Tree_2, 'Text', 'Pits');
            uitreenode(nodePit, 'Text', 'pitr', 'NodeData', 'pitr');
            uitreenode(nodePit, 'Text', 'pith', 'NodeData', 'pith');
            nodeSp = uitreenode(app.Tree_2, 'Text', 'Array');
            uitreenode(nodeSp, 'Text', 'spacing', 'NodeData', 'spacing');
            expand(app.Tree_2);
            nOut = 2;
            app.NumSpinner.Value = max(1, nOut);
            updateSEREquationTable(app);
        end

        function keys = getCheckedTreeKeys(~, tree)
            keys = {};
            nodes = tree.CheckedNodes;
            if isempty(nodes)
                return;
            end
            for i = 1:numel(nodes)
                nd = nodes(i).NodeData;
                if ischar(nd) || (isstring(nd) && isscalar(nd))
                    key = char(nd);
                    if ~isempty(key)
                        keys{end+1} = key;
                    end
                end
            end
            keys = unique(keys, 'stable');
        end

        function keys = getAllPredictionInputKeys(app)
            keys = {};
            if isempty(app.Tree.Children)
                return;
            end
            for i = 1:numel(app.Tree.Children)
                kids = app.Tree.Children(i).Children;
                for j = 1:numel(kids)
                    nd = kids(j).NodeData;
                    if ischar(nd) || (isstring(nd) && isscalar(nd))
                        key = char(nd);
                        if ~isempty(key)
                            keys{end+1} = key;
                        end
                    end
                end
            end
        end

        function keys = getAllPredictionOutputKeys(app)
            keys = {};
            if isempty(app.Tree_2.Children)
                return;
            end
            for i = 1:numel(app.Tree_2.Children)
                kids = app.Tree_2.Children(i).Children;
                for j = 1:numel(kids)
                    nd = kids(j).NodeData;
                    if ischar(nd) || (isstring(nd) && isscalar(nd))
                        key = char(nd);
                        if ~isempty(key)
                            keys{end+1} = key;
                        end
                    end
                end
            end
        end

        function updateSEREquationTable(app)
            nEq = max(1, round(app.NumSpinner.Value));
            app.NumSpinner.Value = nEq;
            yItems = getCheckedTreeKeys(app, app.Tree);
            if isempty(yItems)
                yItems = getAllPredictionInputKeys(app);
            end
            xItems = getCheckedTreeKeys(app, app.Tree_2);
            if isempty(xItems)
                xItems = getAllPredictionOutputKeys(app);
            end
            if isempty(yItems)
                yItems = {'(none)'};
            end
            if isempty(xItems)
                xItems = {'(none)'};
            end
            old = app.UITable5.Data;
            data = cell(nEq, 3);
            for i = 1:nEq
                if iscell(old) && size(old, 1) >= i && size(old, 2) >= 3
                    data(i, :) = old(i, 1:3);
                else
                    data{i, 1} = yItems{min(i, numel(yItems))};
                    data{i, 2} = xItems{1};
                    if numel(xItems) >= 2
                        data{i, 3} = xItems{min(2, numel(xItems))};
                    else
                        data{i, 3} = xItems{1};
                    end
                end
                if ~ismember(data{i, 1}, yItems)
                    data{i, 1} = yItems{1};
                end
                if ~ismember(data{i, 2}, xItems)
                    data{i, 2} = xItems{1};
                end
                if ~ismember(data{i, 3}, xItems)
                    data{i, 3} = xItems{min(2, numel(xItems))};
                end
            end
            app.UITable5.ColumnName = {'Y', 'X1', 'X2'};
            app.UITable5.ColumnEditable = true(1, 3);
            app.UITable5.ColumnFormat = {yItems, xItems, xItems};
            app.UITable5.Data = data;
        end

        function [X, Y, xNames, yNames] = buildPredictionXY(app, T, doFilter)
            if nargin < 2 || isempty(T)
                T = app.SelectData;
            end
            if nargin < 3
                doFilter = true;
            end
            xNames = getCheckedTreeKeys(app, app.Tree);
            yNames = getCheckedTreeKeys(app, app.Tree_2);
            if isempty(xNames)
                error('Please select at least one FC input variable in Input Data (X).');
            end
            if isempty(yNames)
                error('Please select at least one output variable in Output Data (Y).');
            end
            n = height(T);
            X = nan(n, numel(xNames));
            Y = nan(n, numel(yNames));
            for j = 1:numel(xNames)
                X(:, j) = getSelectDataColumnByKey(app, T, xNames{j});
            end
            for j = 1:numel(yNames)
                Y(:, j) = getSelectDataColumnByKey(app, T, yNames{j});
            end
            if doFilter
                valid = all(isfinite(X), 2) & all(isfinite(Y), 2);
                X = X(valid, :);
                Y = Y(valid, :);
                if size(X, 1) < 3
                    error('Valid samples are insufficient');
                end
            end
        end

        function VW = resolveIntactVoltage(~, T, i)
            VW = T.V_weifushi{i};
            if ~isempty(VW)
                return
            end
            for k = 1:height(T)
                if isequaln(T.L(k), T.L(i)) && isequaln(T.piper(k), T.piper(i)) && ...
                        isequaln(T.S(k), T.S(i)) && isequaln(T.spacing(k), T.spacing(i)) && ...
                        isequaln(T.rows(k), T.rows(i)) && isequaln(T.cols(k), T.cols(i))
                    VW = T.V_weifushi{k};
                    if ~isempty(VW)
                        return
                    end
                end
            end
        end

        function key = intactVoltageGroupKey(~, T, i)
            key = sprintf('%.16g|%.16g|%.16g|%.16g|%.16g|%.16g|%.16g|%.16g', ...
                T.L(i), T.piper(i), T.S(i), T.spacing(i), T.rows(i), T.cols(i), ...
                T.sigma(i), T.current(i));
        end

        function [T, nApplied] = addGaussianNoiseToVoltages(app, T, rowIdx, sd)
            nApplied = 0;
            rowIdx = unique(rowIdx(:)).';
            noisyVW = containers.Map('KeyType', 'char', 'ValueType', 'any');
            for i = rowIdx
                VW = resolveIntactVoltage(app, T, i);
                VP = T.V_pit{i};
                if isempty(VW) || isempty(VP)
                    continue
                end
                VW = double(VW);
                VP = double(VP);
                if ~isequal(size(VW), size(VP))
                    continue
                end
                key = intactVoltageGroupKey(app, T, i);
                if ~isKey(noisyVW, key)
                    noisyVW(key) = VW + sd .* randn(size(VW));
                end
                VWn = noisyVW(key);
                if ~isequal(size(VWn), size(VP))
                    continue
                end
                VPn = VP + sd .* randn(size(VP));
                T.V_weifushi{i} = VWn;
                T.V_pit{i} = VPn;
                T.FC{i} = (VPn ./ VWn - 1) * 1000;
                nApplied = nApplied + 1;
            end
        end

        function [T, nApplied] = applyInverseModelNoise(app, T, trainIdx, testIdx)
            nApplied = 0;
            noiseTrain = logical(app.NTrainCheckBox.Value);
            noiseTest = logical(app.NCheckBox.Value) || noiseTrain;
            sd = app.SDSpinner.Value;
            if (~noiseTrain && ~noiseTest) || ~(sd > 0)
                return
            end
            rowIdx = [];
            if noiseTrain
                rowIdx = [rowIdx, trainIdx(:).'];
            end
            if noiseTest
                rowIdx = [rowIdx, testIdx(:).'];
            end
            rowIdx = unique(rowIdx);
            [T, nApplied] = addGaussianNoiseToVoltages(app, T, rowIdx, sd);
            if nApplied == 0
                error('Loaded data has no intact/pitted voltages; cannot add measurement noise.');
            end
            outPut_Text2(app, sprintf( ...
                'Measurement noise: SD=%g, intact voltage=one noisy draw per electrode set (shared), pit voltage=independent, train=%s, test=%s, samples=%d', ...
                sd, mat2str(noiseTrain), mat2str(noiseTest), nApplied));
        end

        function [trainIdx, testIdx] = splitTrainTest(app, n, ratio)
            ratio = min(max(ratio, 0.05), 0.95);
            nTrain = max(1, min(n - 1, floor(n * ratio)));
            seed = 42;
            rngState = rng;
            rng(seed, 'twister');
            idx = randperm(n);
            rng(rngState);
            trainIdx = idx(1:nTrain);
            testIdx = idx(nTrain+1:end);
            if isempty(testIdx)
                testIdx = trainIdx(end);
                trainIdx = trainIdx(1:end-1);
            end
        end

        function metrics = calcRegressionMetrics(~, yTrue, yPred)
            yTrue = yTrue(:);
            yPred = yPred(:);
            resid = yTrue - yPred;
            ssRes = sum(resid.^2);
            ssTot = sum((yTrue - mean(yTrue)).^2);
            if ssTot <= 0
                R2 = NaN;
            else
                R2 = 1 - ssRes / ssTot;
            end
            metrics = struct( ...
                'R2', R2, ...
                'RMSE', sqrt(mean(resid.^2)), ...
                'MAE', mean(abs(resid)), ...
                'N', numel(yTrue));
        end

        function Yhat = trainPredictBP(app, Xtr, Ytr, Xte)
            hiddenNum = max(1, round(app.hiddenNumSpinner.Value));
            lr = app.lrSpinner.Value;
            goal = app.goalSpinner.Value;
            epochs = max(1, round(app.epochsSpinner.Value));
            if exist('fitnet', 'file') ~= 2
                error('Please confirm that the Neural Network / Deep Learning Toolbox。');
            end
            net = fitnet(hiddenNum);
            net.trainParam.showWindow = false;
            net.trainParam.showCommandLine = false;
            net.trainParam.lr = lr;
            net.trainParam.goal = goal;
            net.trainParam.epochs = epochs;
            net.divideParam.trainRatio = 1;
            net.divideParam.valRatio = 0;
            net.divideParam.testRatio = 0;
            net = train(net, Xtr', Ytr');
            Yhat = net(Xte')';
            app.PredTrainedModel = struct('Type', 'BP', 'Net', net);
        end

        function Yhat = trainPredictRF(app, Xtr, Ytr, Xte)
            nTrees = max(1, round(app.nTreesSpinner.Value));
            minLeaf = max(1, round(app.MinLeafSizeSpinner.Value));
            maxSplits = max(1, round(app.MaxNumSplitsSpinner.Value));
            nOut = size(Ytr, 2);
            Yhat = nan(size(Xte, 1), nOut);
            models = cell(1, nOut);
            t = templateTree('MinLeafSize', minLeaf, 'MaxNumSplits', maxSplits);
            for j = 1:nOut
                mdl = fitrensemble(Xtr, Ytr(:, j), 'Method', 'Bag','NumLearningCycles', nTrees, 'Learners', t);
                Yhat(:, j) = predict(mdl, Xte);
                models{j} = mdl;
            end
            app.PredTrainedModel = struct('Type', 'RF', 'Models', {models});
        end

        function Yhat = trainPredictGBDT(app, Xtr, Ytr, Xte)
            nCycles = max(1, round(app.NumLearningCyclesSpinner.Value));
            minLeaf = max(1, round(app.MinLeafSizeSpinner_2.Value));
            learnRate = app.LearnRateSpinner.Value;
            nOut = size(Ytr, 2);
            Yhat = nan(size(Xte, 1), nOut);
            models = cell(1, nOut);
            t = templateTree('MinLeafSize', minLeaf);
            for j = 1:nOut
                mdl = fitrensemble(Xtr, Ytr(:, j), ...
                    'Method', 'LSBoost', ...
                    'NumLearningCycles', nCycles, ...
                    'LearnRate', learnRate, ...
                    'Learners', t);
                Yhat(:, j) = predict(mdl, Xte);
                models{j} = mdl;
            end
            app.PredTrainedModel = struct('Type', 'GBDT', 'Models', {models});
        end

        function [v, ok] = applyScalarProcessing(~, v, mode, forward)
            ok = true;
            switch mode
                case 'Logarithm'
                    if forward
                        if ~(isfinite(v) && v > 0)
                            ok = false; return;
                        end
                        v = log(v);
                    else
                        v = exp(v);
                    end
                case 'Reciprocal'
                    if forward
                        if ~(isfinite(v) && v ~= 0)
                            ok = false; return;
                        end
                        v = 1 / v;
                    else
                        if ~(isfinite(v) && v ~= 0)
                            ok = false; return;
                        end
                        v = 1 / v;
                    end
                otherwise
                    if ~isfinite(v)
                        ok = false;
                    end
            end
        end

        function [Yhat, yNames] = trainPredictSER(app, T, trainIdx, testIdx)
            proc = app.ProcessingDropDown_3.Value;
            eqData = app.UITable5.Data;
            if isempty(eqData)
                error('	Please configure Num, Y, X1 and X2 first.');
            end
            nEq = size(eqData, 1);
            eqs = struct('yName', {}, 'x1Name', {}, 'x2Name', {}, 'beta', {});
            unkNames = {};
            for i = 1:nEq
                yName = char(string(eqData{i, 1}));
                x1Name = char(string(eqData{i, 2}));
                x2Name = char(string(eqData{i, 3}));
                if strcmp(yName, '(none)') || strcmp(x1Name, '(none)') || strcmp(x2Name, '(none)')
                    error('Invalid variables for equation %d. Please load data and select variables beforehand.', i);
                end
                if strcmp(x1Name, x2Name)
                    error('X1 and X2 cannot be the same in equation %d.', i);
                end
                y = getSelectDataColumnByKey(app, T, yName);
                x1 = getSelectDataColumnByKey(app, T, x1Name);
                x2 = getSelectDataColumnByKey(app, T, x2Name);
                y = y(trainIdx); x1 = x1(trainIdx); x2 = x2(trainIdx);
                [x1p, x2p, yp] = applyProcessing2(app, x1, x2, y, proc, x1Name, x2Name, yName);
                if numel(yp) < 3
                    error('	Insufficient valid training samples for equation %d.', i);
                end
                A = [x1p, x2p, ones(numel(yp), 1)];
                beta = A \ yp;
                eqs(i).yName = yName;
                eqs(i).x1Name = x1Name;
                eqs(i).x2Name = x2Name;
                eqs(i).beta = beta;
                unkNames = unique([unkNames, {x1Name, x2Name}], 'stable');
            end
            yNames = unkNames;
            nTest = numel(testIdx);
            nUnk = numel(unkNames);
            Yhat = nan(nTest, nUnk);
            for t = 1:nTest
                row = testIdx(t);
                M = zeros(nEq, nUnk);
                rhs = zeros(nEq, 1);
                validEq = true(nEq, 1);
                for i = 1:nEq
                    yRaw = getSelectDataColumnByKey(app, T, eqs(i).yName);
                    yRaw = yRaw(row);
                    [yp, ok] = applyScalarProcessing(app, yRaw, proc, true);
                    if ~ok
                        validEq(i) = false;
                        continue;
                    end
                    a = eqs(i).beta(1); b = eqs(i).beta(2); c = eqs(i).beta(3);
                    j1 = find(strcmp(unkNames, eqs(i).x1Name), 1);
                    j2 = find(strcmp(unkNames, eqs(i).x2Name), 1);
                    M(i, j1) = a;
                    M(i, j2) = b;
                    rhs(i) = yp - c;
                end
                if nnz(validEq) < nUnk
                    continue;
                end
                Mv = M(validEq, :);
                rv = rhs(validEq);
                u = Mv \ rv;
                for k = 1:nUnk
                    [val, ok] = applyScalarProcessing(app, u(k), proc, false);
                    if ok
                        Yhat(t, k) = val;
                    end
                end
            end
            app.PredTrainedModel = struct('Type', 'SER', 'Equations', eqs, ...
                'Processing', proc, 'Unknowns', {unkNames});
        end

        function displayPredictionResults(app, Xall, Ytrue, Ypred, xNames, yNames, sampleIdx)
            n = size(Xall, 1);
            nX = numel(xNames);
            nY = numel(yNames);
            colNames = [{'id'}, ...
                cellfun(@(s) ['X_' s], xNames, 'UniformOutput', false), ...
                cellfun(@(s) ['Ytrue_' s], yNames, 'UniformOutput', false), ...
                cellfun(@(s) ['Ypred_' s], yNames, 'UniformOutput', false)];
            data = nan(n, 1 + nX + 2 * nY);
            data(:, 1) = sampleIdx(:);
            data(:, 2:1+nX) = Xall;
            data(:, 2+nX:1+nX+nY) = Ytrue;
            data(:, 2+nX+nY:end) = Ypred;
            app.UITable2.ColumnName = colNames;
            app.UITable2.Data = data;
            metricRows = {};
            for j = 1:nY
                m = calcRegressionMetrics(app, Ytrue(:, j), Ypred(:, j));
                metricRows(end+1, :) = {sprintf('%s_R2', yNames{j}), m.R2};
                metricRows(end+1, :) = {sprintf('%s_RMSE', yNames{j}), m.RMSE};
                metricRows(end+1, :) = {sprintf('%s_MAE', yNames{j}), m.MAE};
            end
            if nY > 1
                mAll = calcRegressionMetrics(app, Ytrue(:), Ypred(:));
                metricRows(end+1, :) = {'Overall_R2', mAll.R2};
                metricRows(end+1, :) = {'Overall_RMSE', mAll.RMSE};
                metricRows(end+1, :) = {'Overall_MAE', mAll.MAE};
            end
            metricRows(end+1, :) = {'N_samples', n};
            app.UITable2_2.ColumnName = {'Results', 'Value'};
            app.UITable2_2.Data = metricRows;
            plotPredictionFit(app, Ytrue, Ypred, yNames);
        end

        function plotPredictionFit(app, Ytrue, Ypred, yNames)
            delete(app.Panel_showfitfig.Children);
            app.PredFitAxes = gobjects(0);
            nY = numel(yNames);
            if nY < 1
                return;
            end
            nRow = ceil(sqrt(nY));
            nCol = ceil(nY / nRow);
            for j = 1:nY
                ax = uiaxes(app.Panel_showfitfig);
                ax.Position = tileAxesPosition(app, app.Panel_showfitfig, nRow, nCol, j);
                yt = Ytrue(:, j); yp = Ypred(:, j);
                mask = isfinite(yt) & isfinite(yp);
                yt = yt(mask); yp = yp(mask);
                hold(ax, 'on');
                if isempty(yt)
                    title(ax, yNames{j});
                    hold(ax, 'off');
                    app.PredFitAxes(end+1) = ax;
                    continue;
                end
                scatter(ax, yt, yp, 28, 'filled');
                lims = [min([yt; yp]), max([yt; yp])];
                if lims(1) == lims(2)
                    lims = lims + [-1 1];
                end
                plot(ax, lims, lims, 'r--', 'LineWidth', 1.2);
                xlim(ax, lims); ylim(ax, lims);
                grid(ax, 'on');
                xlabel(ax, 'True Value');
                ylabel(ax, 'Predicted Value');
                title(ax, yNames{j});
                hold(ax, 'off');
                app.PredFitAxes(end+1) = ax;
            end
        end

        function pos = tileAxesPosition(~, panel, nR, nC, k)
            marg = 8;
            topPad = 22;
            w = panel.Position(3);
            h = panel.Position(4) - topPad;
            cw = (w - (nC + 1) * marg) / nC;
            ch = (h - (nR + 1) * marg) / nR;
            [rr, cc] = ind2sub([nR, nC], k);
            x = marg + (cc - 1) * (cw + marg);
            y = marg + (nR - rr) * (ch + marg);
            pos = [x, y, cw, ch];
        end

        function runPredictionTraining(app)
            modelType = app.ModelsDropDown.Value;
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                uialert(app.VDMMSMUIFigure, 'Please click Load to load and filter the data first.', 'No data ');
                return;
            end
            try
                n = height(T);
                [trainIdx, testIdx] = splitTrainTest(app, n, app.TRatioSpinner.Value);
                [Twork, ~] = applyInverseModelNoise(app, T, trainIdx, testIdx);
                if strcmp(modelType, 'SER')
                    updateSEREquationTable(app);
                    [Ypred, yNames] = trainPredictSER(app, Twork, trainIdx, testIdx);
                    Xte = [];
                    xNames = {};
                    eqData = app.UITable5.Data;
                    yIn = unique(eqData(:, 1), 'stable');
                    for k = 1:numel(yIn)
                        if strcmp(yIn{k}, '(none)'), continue; end
                        xNames{end+1} = char(string(yIn{k}));
                    end
                    if isempty(xNames)
                        error('There are no valid Y (input) variables in the equation table.');
                    end
                    Xte = nan(numel(testIdx), numel(xNames));
                    for j = 1:numel(xNames)
                        col = getSelectDataColumnByKey(app, Twork, xNames{j});
                        Xte(:, j) = col(testIdx);
                    end
                    Ytrue = nan(numel(testIdx), numel(yNames));
                    for j = 1:numel(yNames)
                        col = getSelectDataColumnByKey(app, Twork, yNames{j});
                        Ytrue(:, j) = col(testIdx);
                    end
                    displayPredictionResults(app, Xte, Ytrue, Ypred, xNames, yNames, testIdx(:));
                    if ~isempty(app.PredTrainedModel)
                        app.PredTrainedModel.XNames = xNames;
                        app.PredTrainedModel.YNames = yNames;
                        app.PredTrainedModel.TrainRatio = app.TRatioSpinner.Value;
                        app.PredTrainedModel.Metrics = app.UITable2_2.Data;
                    end
                    outPut_Text2(app, sprintf( ...
                        'SER trained: %d eqs, train=%d, test=%d, unknowns=%s', ...
                        size(eqData, 1), numel(trainIdx), numel(testIdx), strjoin(yNames, ',')));
                else
                    [X, Y, xNames, yNames] = buildPredictionXY(app, Twork, false);
                    trKeep = all(isfinite(X(trainIdx, :)), 2) & all(isfinite(Y(trainIdx, :)), 2);
                    teKeep = all(isfinite(X(testIdx, :)), 2) & all(isfinite(Y(testIdx, :)), 2);
                    trainIdx = trainIdx(trKeep);
                    testIdx = testIdx(teKeep);
                    if numel(trainIdx) < 2 || isempty(testIdx)
                        error('Valid samples are insufficient');
                    end
                    Xtr = X(trainIdx, :); Ytr = Y(trainIdx, :);
                    Xte = X(testIdx, :); Yte = Y(testIdx, :);
                    switch modelType
                        case 'BP'
                            Ypred = trainPredictBP(app, Xtr, Ytr, Xte);
                        case 'RF'
                            Ypred = trainPredictRF(app, Xtr, Ytr, Xte);
                        case 'GBDT'
                            Ypred = trainPredictGBDT(app, Xtr, Ytr, Xte);
                        otherwise
                            error('Unknown model type: %s', modelType);
                    end
                    if size(Ypred, 1) ~= size(Yte, 1) && size(Ypred, 2) == size(Yte, 1)
                        Ypred = Ypred';
                    end
                    if size(Ypred, 2) ~= size(Yte, 2) && size(Ypred, 1) == size(Yte, 2)
                        Ypred = Ypred';
                    end
                    displayPredictionResults(app, Xte, Yte, Ypred, xNames, yNames, testIdx(:));
                    if ~isempty(app.PredTrainedModel)
                        app.PredTrainedModel.XNames = xNames;
                        app.PredTrainedModel.YNames = yNames;
                        app.PredTrainedModel.TrainRatio = app.TRatioSpinner.Value;
                        app.PredTrainedModel.Metrics = app.UITable2_2.Data;
                    end
                    outPut_Text2(app, sprintf( ...
                        '%s trained: X=%d, Y=%d, train=%d, test=%d', ...
                        modelType, numel(xNames), numel(yNames), numel(trainIdx), numel(testIdx)));
                end
            catch ME
                uialert(app.VDMMSMUIFigure, ME.message, 'Training failed!');
                outPut_Text2(app, ['Train failed: ' ME.message]);
            end
        end

        function y = stackY(~, n, yTop, yBot)
            if n <= 0
                y = [];
            elseif n == 1
                y = (yTop + yBot) / 2;
            else
                y = linspace(yTop, yBot, n);
            end
        end

        function drawFlowBox(~, ax, xc, yc, w, h, faceColor, edgeColor, label, fontSize)
            rectangle(ax, 'Position', [xc - w/2, yc - h/2, w, h], ...
                'FaceColor', faceColor, 'EdgeColor', edgeColor, ...
                'LineWidth', 1.2, 'Curvature', 0.12);
            text(ax, xc, yc, label, 'FontSize', fontSize, ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                'Interpreter', 'none', 'FontWeight', 'bold', 'Clipping', 'on');
        end

        function drawElbowArrow(~, ax, x1, y1, x2, y2, color)
            xm = (x1 + x2) / 2;
            plot(ax, [x1, xm], [y1, y1], '-', 'Color', color, 'LineWidth', 1.2);
            plot(ax, [xm, xm], [y1, y2], '-', 'Color', color, 'LineWidth', 1.2);
            plot(ax, [xm, x2], [y2, y2], '-', 'Color', color, 'LineWidth', 1.2);
            ah = 0.18;
            aw = 0.10;
            tip = [x2, y2];
            p1 = [tip(1) - ah, tip(2) + aw];
            p2 = [tip(1) - ah, tip(2) - aw];
            fill(ax, [tip(1), p1(1), p2(1)], [tip(2), p1(2), p2(2)], color,'EdgeColor', 'none');
        end

        function subtitle = modelSubtitle(app, modelType)
            switch modelType
                case 'BP'
                    subtitle = sprintf('hidden=%g, lr=%g, epochs=%g', app.hiddenNumSpinner.Value, app.lrSpinner.Value, app.epochsSpinner.Value);
                case 'RF'
                    subtitle = sprintf('nTrees=%g, MinLeaf=%g, MaxSplits=%g', app.nTreesSpinner.Value, app.MinLeafSizeSpinner.Value, app.MaxNumSplitsSpinner.Value);
                case 'GBDT'
                    subtitle = sprintf('Cycles=%g, LearnRate=%g, MinLeaf=%g', app.NumLearningCyclesSpinner.Value, app.LearnRateSpinner.Value, app.MinLeafSizeSpinner_2.Value);
                case 'SER'
                    subtitle = sprintf('NumEq=%g, Proc=%s', app.NumSpinner.Value, app.ProcessingDropDown_3.Value);
                otherwise
                    subtitle = '';
            end
        end

        function RefreshModelFigure(app)
            ax = app.ModelAxes;
            cla(ax);
            hold(ax, 'on');
            try
                disableDefaultInteractivity(ax);
            catch
            end
            ax.XLim = [0, 10];
            ax.YLim = [0, 10];
            ax.XTick = [];
            ax.YTick = [];
            ax.Box = 'off';
            ax.Color = [1 1 1];
            title(ax, '');
            xlabel(ax, '');
            ylabel(ax, '');
            try
                ax.Toolbar.Visible = 'off';
            catch
            end

            inNames = getCheckedTreeKeys(app, app.Tree);
            outNames = getCheckedTreeKeys(app, app.Tree_2);
            modelType = char(string(app.ModelsDropDown.Value));
            if isempty(inNames) || isempty(outNames)
                text(ax, 5, 5, {'Please check Input Data (X) and Output Data (Y) first', 'Click Train to draw the model structure'}, ...
                    'HorizontalAlignment', 'center', 'FontSize', 9, 'Color', [0.45 0.45 0.45]);
                hold(ax, 'off');
                return;
            end
            nIn = numel(inNames);
            nOut = numel(outNames);
            yTop = 8.5;
            yBot = 2.4;
            yIn = stackY(app, nIn, yTop, yBot);
            yOut = stackY(app, nOut, yTop, yBot);
            boxW = 1.55;
            boxH = min(0.75, max(0.38, 5.5 / max([nIn, nOut, 1])));
            xIn = 1.45;
            xMid = 5.0;
            xOut = 8.55;
            midW = 2.2;
            midH = 1.55;
            lineColor = [0.45 0.48 0.55];
            nameFs = max(7, min(9, round(10 - 0.35 * max(nIn, nOut))));
            text(ax, xIn, 9.4, 'Input', 'FontSize', 9, 'Color', [0.75 0.2 0.2], 'HorizontalAlignment', 'center', 'FontWeight', 'bold');
            text(ax, xMid, 9.4, 'Model', 'FontSize', 9, 'Color', [0.15 0.3 0.75],'HorizontalAlignment', 'center', 'FontWeight', 'bold');
            text(ax, xOut, 9.4, 'Output', 'FontSize', 9, 'Color', [0.15 0.55 0.25],'HorizontalAlignment', 'center', 'FontWeight', 'bold');
            for i = 1:nIn
                drawFlowBox(app, ax, xIn, yIn(i), boxW, boxH, ...
                    [1.0 0.88 0.88], [0.75 0.25 0.25], inNames{i}, nameFs);
            end
            modelFace = [0.88 0.92 1.0];
            modelEdge = [0.2 0.35 0.75];
            switch modelType
                case 'RF'
                    modelFace = [1.0 0.95 0.85]; modelEdge = [0.75 0.45 0.1];
                case 'GBDT'
                    modelFace = [0.9 0.95 0.88]; modelEdge = [0.3 0.55 0.2];
                case 'SER'
                    modelFace = [0.93 0.88 0.98]; modelEdge = [0.5 0.25 0.7];
            end
            yMid = 5.15;
            drawFlowBox(app, ax, xMid, yMid, midW, midH, modelFace, modelEdge, modelType, 13);
            sub = modelSubtitle(app, modelType);
            if ~isempty(sub)
                text(ax, xMid, yMid - midH/2 - 0.32, sub, 'FontSize', 7, 'Color', [0.35 0.35 0.35], ...
                    'HorizontalAlignment', 'center', 'Interpreter', 'none');
            end
            text(ax, xMid, yMid - midH/2 - 0.68, sprintf('TrainRatio = %.2f', app.TRatioSpinner.Value), ...
                'FontSize', 7, 'Color', [0.4 0.4 0.4], 'HorizontalAlignment', 'center');
            for i = 1:nOut
                drawFlowBox(app, ax, xOut, yOut(i), boxW, boxH, ...
                    [0.88 0.96 0.88], [0.2 0.55 0.25], outNames{i}, nameFs);
            end
            yPortIn  = stackY(app, nIn,  yMid + midH*0.32, yMid - midH*0.32);
            yPortOut = stackY(app, nOut, yMid + midH*0.32, yMid - midH*0.32);
            xLeft  = xMid - midW/2;
            xRight = xMid + midW/2;
            for i = 1:nIn
                drawElbowArrow(app, ax, xIn + boxW/2, yIn(i), xLeft, yPortIn(i), lineColor);
            end
            for i = 1:nOut
                drawElbowArrow(app, ax, xRight, yPortOut(i), xOut - boxW/2, yOut(i), lineColor);
            end
            text(ax, 5, 0.75, sprintf('X: %d  |  Model: %s  |  Y: %d', nIn, modelType, nOut), ...
                'FontSize', 8, 'HorizontalAlignment', 'center', 'Color', [0.25 0.25 0.25], ...
                'Interpreter', 'none');

            hold(ax, 'off');
            drawnow limitrate;
        end

        function outPut(app,str)
            string = sprintf('%s\n', str);
            app.cellArrayText=horzcat(app.cellArrayText,string);
            app.TextArea.Value = app.cellArrayText;
        end

        function outPut_Text2(app,str)
            string = sprintf('%s\n', str);
            app.cellArrayText_2 = horzcat(app.cellArrayText_2, string);
            app.TextArea_2.Value = app.cellArrayText_2;
            drawnow limitrate;
        end
        function beginCalculation(app)
            app.ProgressFg.Visible = 'on';
            app.CancelRequested = false;
            app.IsCalculating = true;
            app.CalculateButton.Enable = 'off';
            app.Button_startCalculate.Enable = 'off';
            app.Button_cancel.Enable = 'on';
            drawnow;
        end

        function endCalculation(app)
            app.IsCalculating = false;
            app.CancelRequested = false;
            app.Button_cancel.Enable = 'off';
            updateCalculateButtons(app);
            drawnow;
        end

        function setProgress(app, pct)
            pct = max(0, min(100, double(pct)));
            fullW = app.ProgressBg.Position(3);
            app.ProgressFg.Position(3) = fullW * pct / 100;
            drawnow limitrate;
        end

        function throwIfCancelled(app)
            drawnow;
            if app.CancelRequested
                error('UserCancel:Aborted', 'Calculation aborted by user');
            end
        end

        function tf = isUserCancel(~, ME)
            tf = strcmp(ME.identifier, 'UserCancel:Aborted') || ...
                contains(ME.message, 'Calculation aborted by user');
        end

        function saveBatchResultsIfAny(app, elapsedSec)
            hasOk = ~isempty(app.ResultsTable) && height(app.ResultsTable) > 0;
            hasSkip = ~isempty(app.SkippedTable) && height(app.SkippedTable) > 0;
            if ~hasOk && ~hasSkip
                outPut_Text2(app, 'No completed rows to save.');
                return
            end
            if hasOk && nargin >= 2 && ~isempty(elapsedSec)
                showBatchStatisticsInTable(app, elapsedSec);
                showAllResultsInTable(app);
            end
            exportCalculationResults(app);
        end

        function [ok, msg] = checkSimulationReady(app)
            pathNotes = sanitizeComsolLiveLinkPath(app);
            for i = 1:numel(pathNotes)
                outPut_Text2(app, pathNotes{i});
            end

            ok = true;
            reasons = {};

            if ~hasMatlabFile('mphinterp')
                ok = false;
                reasons{end+1} = 'Missing mphinterp';
            end
            if ~hasMatlabFile('mphplot')
                ok = false;
                reasons{end+1} = 'Missing mphplot';
            end
            if ~hasMatlabFile('mphstart')
                ok = false;
                reasons{end+1} = 'Missing mphstart';
            end

            [mliOk, mliMsg] = verifyComsolMliIntegrity(app);
            if ~mliOk
                ok = false;
                reasons{end+1} = mliMsg;
            else
                outPut_Text2(app, mliMsg);
            end

            try
                import com.comsol.model.util.*
                try
                    ModelUtil.tags();
                catch
                    if hasMatlabFile('mphstart')
                        mphstart;
                        ModelUtil.tags();
                    else
                        error('no_mphstart');
                    end
                end
            catch ME
                ok = false;
                if strcmp(ME.message, 'no_mphstart') || contains(ME.message, 'no_mphstart')
                    reasons{end+1} = 'mphstart cannot be found. Please verify COMSOL LiveLink for MATLAB is installed';
                else
                    reasons{end+1} = ['Failed to connect to COMSOL（' ME.message '）'];
                end
            end


            if ok
                msg = 'COMSOL LiveLink Available';
            else
                msg = strjoin(reasons, '；');
            end
            function tf = hasMatlabFile(name)
                tf = ismember(exist(name, 'file'), [2 3 6]);
            end
        end
        function notes = sanitizeComsolLiveLinkPath(app)
            notes = {};
            addOnApps = fullfile(getenv('APPDATA'), 'MathWorks', 'MATLAB Add-Ons', 'Apps');
            pmmRoot = fullfile(addOnApps, 'PMMBSAP');
            if isfolder(pmmRoot)
                try
                    rmpath(genpath(pmmRoot));
                    notes{end+1} = ['Removed Add-On shadowed LiveLink path: ' pmmRoot];
                catch ME
                    notes{end+1} = ['Failed to remove PMMBSAP Add-On path: ' ME.message];
                end
            end

            pathCells = strsplit(path, pathsep);
            for i = 1:numel(pathCells)
                pi = strtrim(pathCells{i});
                if isempty(pi)
                    continue;
                end
                isAddOn = contains(pi, 'MATLAB Add-Ons') && contains(pi, 'Apps');
                isBundledMli = contains(lower(pi), [filesep 'mli']) || ...
                    contains(pi, 'COMSOL') || contains(pi, 'D__Program Files') || ...
                    contains(pi, 'D__Program');
                if isAddOn && isBundledMli
                    try
                        rmpath(pi);
                        notes{end+1} = ['Removed shadowed mli path: ' pi];
                    catch
                    end
                end
            end

            w = which('mphstart');
            curMli = '';
            if ~isempty(w)
                curMli = validateComsolMliDir(app, fileparts(w));
            end
            needSearch = isempty(curMli);
            if needSearch
                notes{end+1} = 'Searching local COMSOL Multiphysics\\mli ...';
                mliDir = findLocalComsolMliDir(app);
                if ~isempty(mliDir)
                    addpath(mliDir);
                    rehash;
                    notes{end+1} = ['Found and added local COMSOL mli: ' mliDir];
                else
                    notes{end+1} = 'Local COMSOL mli not found. Install COMSOL LiveLink or start via COMSOL with MATLAB.';
                end
            else
                pathCells2 = strsplit(path, pathsep);
                onPath = any(strcmpi(strtrim(pathCells2), curMli));
                if ~onPath
                    addpath(curMli);
                    rehash;
                end
                notes{end+1} = ['Using COMSOL mli: ' curMli];
            end
        end

        function d = validateComsolMliDir(~, folder)
            d = '';
            if isempty(folder) || ~isfolder(folder)
                return;
            end
            if contains(folder, 'MATLAB Add-Ons')
                return;
            end
            hasStart = isfile(fullfile(folder, 'mphstart.p')) || isfile(fullfile(folder, 'mphstart.m'));
            hasErr = isfile(fullfile(folder, 'private', 'errorchk_model.p')) || ...
                isfile(fullfile(folder, 'private', 'errorchk_model.m'));
            if hasStart && hasErr
                d = folder;
            end
        end

        function mliDir = findLocalComsolMliDir(app)
            mliDir = '';
            w = which('mphstart');
            if ~isempty(w)
                mliDir = validateComsolMliDir(app, fileparts(w));
                if ~isempty(mliDir), return; end
            end
            mliDir = findMliFromJavaClasspath(app);
            if ~isempty(mliDir), return; end
            mliDir = findMliFromEnv(app);
            if ~isempty(mliDir), return; end
            if ispc
                mliDir = findMliFromRegistry(app);
                if ~isempty(mliDir), return; end
            end
            mliDir = searchMliOnLocalDrives(app);
        end

        function mliDir = findMliFromJavaClasspath(app)
            mliDir = '';
            try
                jcp = [javaclasspath('-all'); javaclasspath('-dynamic')];
            catch
                return;
            end
            if isempty(jcp)
                return;
            end
            if ischar(jcp)
                jcp = cellstr(jcp);
            end
            for i = 1:numel(jcp)
                p = char(string(jcp{i}));
                if isempty(p) || ~contains(p, 'Multiphysics', 'IgnoreCase', true)
                    continue;
                end
                idx = regexpi(p, '[\\/]Multiphysics[\\/]', 'once');
                if isempty(idx)
                    continue;
                end
                tok = regexpi(p, '^(.*[\\/]Multiphysics)([\\/]|$)', 'tokens', 'once');
                if isempty(tok)
                    continue;
                end
                cand = fullfile(tok{1}, 'mli');
                mliDir = validateComsolMliDir(app, cand);
                if ~isempty(mliDir)
                    return;
                end
            end
        end

        function mliDir = findMliFromEnv(app)
            mliDir = '';
            envNames = {'COMSOLROOT', 'COMSOL62', 'COMSOL61', 'COMSOL60', ...
                'COMSOL59', 'COMSOL56', 'COMSOL55', 'COMSOL54'};
            for i = 1:numel(envNames)
                root = strtrim(getenv(envNames{i}));
                if isempty(root)
                    continue;
                end
                cands = { ...
                    fullfile(root, 'mli'), ...
                    fullfile(root, 'Multiphysics', 'mli'), ...
                    fullfile(root, 'COMSOL62', 'Multiphysics', 'mli')};
                for j = 1:numel(cands)
                    mliDir = validateComsolMliDir(app, cands{j});
                    if ~isempty(mliDir), return; end
                end
            end
        end

        function mliDir = findMliFromRegistry(app)
            mliDir = '';
            try
                [status, out] = system('reg query "HKLM\SOFTWARE\COMSOL" /s 2>nul');
            catch
                return;
            end
            if status ~= 0 || isempty(out)
                return;
            end
            lines = strsplit(out, {'\r', '\n'});
            for i = 1:numel(lines)
                ln = strtrim(lines{i});
                if isempty(ln)
                    continue;
                end
                tok = regexp(ln, 'REG_SZ\s+(.+)$', 'tokens', 'once');
                if isempty(tok)
                    continue;
                end
                val = strtrim(tok{1});
                if ~isfolder(val) && ~isfile(val)
                    continue;
                end
                if isfile(val)
                    val = fileparts(val);
                end
                cands = { ...
                    fullfile(val, 'mli'), ...
                    fullfile(val, 'Multiphysics', 'mli')};
                if contains(val, 'Multiphysics', 'IgnoreCase', true)
                    cands{end+1} = fullfile(val, 'mli');
                    cands{end+1} = fullfile(fileparts(val), 'mli');
                end
                for j = 1:numel(cands)
                    mliDir = validateComsolMliDir(app, cands{j});
                    if ~isempty(mliDir), return; end
                end
                try
                    subs = dir(val);
                    for k = 1:numel(subs)
                        if ~subs(k).isdir || startsWith(subs(k).name, '.')
                            continue;
                        end
                        if ~contains(subs(k).name, 'COMSOL', 'IgnoreCase', true)
                            continue;
                        end
                        cand = fullfile(val, subs(k).name, 'Multiphysics', 'mli');
                        mliDir = validateComsolMliDir(app, cand);
                        if ~isempty(mliDir), return; end
                    end
                catch
                end
            end
        end

        function mliDir = searchMliOnLocalDrives(app)
            mliDir = '';
            roots = listLocalDriveRoots(app);
            for i = 1:numel(roots)
                drive = roots{i};
                searchRoots = {drive, fullfile(drive, 'Program Files'), ...
                    fullfile(drive, 'Program Files (x86)')};
                for r = 1:numel(searchRoots)
                    base = searchRoots{r};
                    if ~isfolder(base)
                        continue;
                    end
                    try
                        ents = dir(base);
                    catch
                        continue;
                    end
                    for k = 1:numel(ents)
                        if ~ents(k).isdir || startsWith(ents(k).name, '.')
                            continue;
                        end
                        if ~contains(ents(k).name, 'comsol', 'IgnoreCase', true)
                            continue;
                        end
                        startDir = fullfile(base, ents(k).name);
                        mliDir = findMliUnderDir(app, startDir, 5);
                        if ~isempty(mliDir)
                            return;
                        end
                    end
                end
            end
        end

        function roots = listLocalDriveRoots(~)
            roots = {};
            if ~ispc
                roots = {'/'};
                return;
            end
            try
                fileRoots = java.io.File('.').listRoots();
                for i = 1:numel(fileRoots)
                    roots{end+1} = char(fileRoots(i).getPath());
                end
            catch
                roots = {'C:\', 'D:\', 'E:\', 'F:\'};
            end
        end

        function mliDir = findMliUnderDir(app, startDir, maxDepth)
            mliDir = '';
            if ~isfolder(startDir)
                return;
            end
            queue = {startDir};
            depth = 0;
            while ~isempty(queue) && depth <= maxDepth
                nextQ = {};
                for i = 1:numel(queue)
                    cur = queue{i};
                    cand1 = validateComsolMliDir(app, cur);
                    if ~isempty(cand1) && endsWith(lower(cand1), [filesep 'mli'])
                        mliDir = cand1;
                        return;
                    end
                    cand2 = validateComsolMliDir(app, fullfile(cur, 'mli'));
                    if ~isempty(cand2)
                        mliDir = cand2;
                        return;
                    end
                    cand3 = validateComsolMliDir(app, fullfile(cur, 'Multiphysics', 'mli'));
                    if ~isempty(cand3)
                        mliDir = cand3;
                        return;
                    end
                    if depth == maxDepth
                        continue;
                    end
                    try
                        ents = dir(cur);
                    catch
                        continue;
                    end
                    for k = 1:numel(ents)
                        if ~ents(k).isdir || startsWith(ents(k).name, '.')
                            continue;
                        end
                        name = ents(k).name;
                        keep = contains(name, 'comsol', 'IgnoreCase', true) || ...
                            strcmpi(name, 'Multiphysics') || ...
                            strcmpi(name, 'mli') || ...
                            ~isempty(regexp(name, '^\d', 'once')) || ...
                            startsWith(lower(name), 'comsol');
                        if keep
                            nextQ{end+1} = fullfile(cur, name);
                        end
                    end
                end
                queue = nextQ;
                depth = depth + 1;
            end
        end

        function [ok, msg] = verifyComsolMliIntegrity(app)
            ok = true;
            w = which('mphinterp');
            if isempty(w)
                ok = false;
                msg = 'mphinterp not on MATLAB path';
                return;
            end
            if contains(w, 'MATLAB Add-Ons')
                ok = false;
                msg = ['mphinterp is still resolved to Add-On shadow copy: ' w ...
                    '. Uninstall PMMBSAP Add-On or remove its mli from path.'];
                return;
            end
            mliDir = validateComsolMliDir(app, fileparts(w));
            if isempty(mliDir)
                ok = false;
                msg = ['Incomplete LiveLink under ' fileparts(w) ...
                    ' (missing private/errorchk_model).'];
                return;
            end
            msg = ['Using COMSOL mli: ' mliDir];
        end
        function updateCalculateButtons(app)
            if app.IsCalculating || ~app.CanSimulate
                app.CalculateButton.Enable = 'off';
                app.Button_startCalculate.Enable = 'off';
            else
                app.CalculateButton.Enable = 'on';
                app.Button_startCalculate.Enable = 'on';
            end
        end

        function refreshSimulationAvailability(app)
            outPut_Text2(app, 'Checking simulation environment...');
            drawnow;
            [ok, msg] = checkSimulationReady(app);
            app.CanSimulate = ok;
            updateCalculateButtons(app);
            if ok
                outPut_Text2(app, ['Simulation environment check passed:' msg]);
            else
                outPut_Text2(app, ['Simulation environment check failed:' msg]);
                outPut_Text2(app, 'ingle / Batch Calculate functions are disabled. Please launch COMSOL, configure LiveLink, then restart this application.');
            end
            drawnow;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off', 'all');
            app.Panel_par1.Enable = 'on';
            app.Panel_matrix.Enable = 'on';
            app.ModelTypeNum = '1';
            app.EnableScanCheckBox.Enable = 'off';
            app.Button_cancel.Enable = 'off';
            setProgress(app, 0);
            applyMatrixSchemeVisibility(app, app.LayoutDropDown.Value, 'single');
            applyMatrixSchemeVisibility(app, app.LayoutDropDown_2.Value, 'batch');
            app.CanSimulate = false;
            updateCalculateButtons(app);
            refreshSimulationAvailability(app);

        end

        % Button pushed function: Button_startCalculate
        function Button_startCalculatePushed(app, event)
            app.TextArea_2.Visible = 'on';
            app.cellArrayText_2 = {};
            app.ResultsTable = createEmptyResultsTable(app);
            app.SkippedTable = createEmptySkippedTable(app);
            folder = strtrim(app.EditField_folderPath.Value);
            fileName = strtrim(app.EditField_FileName.Value);
            reportName = strtrim(app.EditField_ReportName.Value);
            [checkout, errMsg] = checkValue(app, folder, fileName, reportName);
            if checkout ~= 0
                if checkout == -1
                    return
                end
                uialert(app.VDMMSMUIFigure, errMsg, 'Save parameter check failed!');
                return
            end

            rows = app.RowsEditField.Value;
            cols = app.ColumnsEditField.Value;
            [ok, customCoord, errMsg] = getCustomCoordIfNeeded(app, 'batch', rows, cols);
            if ~ok
                uialert(app.VDMMSMUIFigure, errMsg, 'Electrode parameter error');
                return
            end
            if isempty(customCoord)
                spacing_list = str2num(app.SpacingEditField.Value);
                if isempty(spacing_list)
                    uialert(app.VDMMSMUIFigure, 'Spacing parsed as empty, please check the input.', 'Electrode parameter error');
                    return
                end
            else
                spacing_list = 0;
            end
            sigma = app.ConductivitySpinner.Value;
            current = app.CurrentSpinner.Value;
            L_list = str2num(app.LengthEditField.Value);
            piper_list = str2num(app.RadiusEditField.Value);
            S_list = str2num(app.ThicknessEditField.Value);
            r_list = str2num(app.RadiusEditField_3.Value);
            h_list = str2num(app.DepthEditField.Value);
            if app.FullScanButton.Value
                model_type = 1;
            elseif app.CombinationButton.Value
                model_type = 2;
            else
                errordlg('Unknown calculation mode, please choose full scan or combination');
                return
            end
            if model_type == 2
                if numel(piper_list) ~= numel(S_list)
                    errordlg('Mismatched number of pipeline parameters ', 'Parameter Mismatch');
                    return
                end
                if ~app.EnableScanCheckBox.Value
                    if numel(r_list) ~= numel(h_list)
                        errordlg('Mismatched number of pits parameters', 'Parameter Mismatch');
                        return
                    end
                    if numel(r_list) ~= numel(piper_list)
                        errordlg('Mismatched quantity between pipeline and pits', 'Parameter Mismatch');
                        return
                    end
                end
            end
            beginCalculation(app);
            app.ModelTypeNum = num2str(model_type);
            t0 = datetime('now');
            try
                outPut_Text2(app, ['*** Batch calculation start: ', datestr(now), ' ***']);
                meshSize = getAutoMeshSize(app);
                outPut_Text2(app, sprintf('Mesh size: %s (autoMeshSize=%d)', ...
                    char(string(app.MeshsizeDropDown.Value)), meshSize));
                if isempty(customCoord)
                    outPut_Text2(app, sprintf('Electrodes: Equal | rows=%g, cols=%g; spacing=%s mm;',rows, cols, mat2str(spacing_list)));
                else
                    outPut_Text2(app, sprintf('Electrodes: Customize | rows=%g, cols=%g; coords=3×%d;',rows, cols, size(customCoord, 2)));
                end
                setProgress(app, 1);
                throwIfCancelled(app);
                if model_type == 1
                    nSolveP = numel(L_list)*numel(piper_list)*numel(S_list)*numel(r_list)*numel(h_list);
                    outPut_Text2(app, sprintf('Mode: Full Scan | pit models=%d, spacing samples=%d', nSolveP, numel(spacing_list)));
                    plan = 0;
                    for L = L_list
                        for piper = piper_list
                            for S = S_list
                                throwIfCancelled(app);
                                intactOk = false;
                                try
                                    model_w = solveWeifushiModel(app, L, piper, S, sigma, current);
                                    VW_all = cell(1, numel(spacing_list));
                                    for id = 1:numel(spacing_list)
                                        throwIfCancelled(app);
                                        VW_all{id} = extractV(app, model_w, L, piper, rows, cols, spacing_list(id), customCoord);
                                    end
                                    intactOk = true;
                                catch ME
                                    if isUserCancel(app, ME)
                                        rethrow(ME);
                                    end
                                    nSkip = numel(r_list) * numel(h_list);
                                    plan = plan + nSkip;
                                    recordSkippedIntactDependents(app, ME, L, piper, S, r_list, h_list, rows, cols, spacing_list, sigma, current);
                                    setProgress(app, plan / max(nSolveP, 1) * 100);
                                end
                                if ~intactOk
                                    continue
                                end
                                for r = r_list
                                    for h = h_list
                                        throwIfCancelled(app);
                                        plan = plan + 1;
                                        tag = sprintf('[%d/%d] L=%g R=%g S=%g r=%g h=%g', plan, nSolveP, L, piper, S, r, h);
                                        outPut_Text2(app, sprintf('%s ...', tag));
                                        try
                                            model_p = solvePitModel(app, L, piper, S, r, h, sigma, current);
                                            for id = 1:numel(spacing_list)
                                                throwIfCancelled(app);
                                                spacing = spacing_list(id);
                                                try
                                                    VW = VW_all{id};
                                                    VP = extractV(app, model_p, L, piper, rows, cols, spacing, customCoord);
                                                    FC = (VP ./ VW - 1) * 1000;
                                                    appendResult(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, VW, VP, FC);
                                                catch ME
                                                    if isUserCancel(app, ME)
                                                        rethrow(ME);
                                                    end
                                                    logSkippedBatchCase(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, ...
                                                        classifyBatchError(app, ME, 'Voltage extraction failed'));
                                                end
                                            end
                                            outPut_Text2(app, sprintf('progress=%.1f%%', plan/nSolveP*100));
                                        catch ME
                                            if isUserCancel(app, ME)
                                                rethrow(ME);
                                            end
                                            recordSkippedSpacings(app, L, piper, S, r, h, rows, cols, spacing_list, sigma, current, ...
                                                classifyBatchError(app, ME));
                                        end
                                        setProgress(app, plan / max(nSolveP, 1) * 100);
                                    end
                                end
                            end
                        end
                    end

                else
                    L = L_list(1);
                    nPipe = numel(piper_list);
                    isScan = app.EnableScanCheckBox.Value;
                    if ~isScan
                        nSolveP = nPipe;
                        outPut_Text2(app, sprintf('Mode: Combination | pit models=%d, spacing samples=%d', nSolveP, numel(spacing_list)));
                        plan = 0;
                        for i = 1:nPipe
                            throwIfCancelled(app);
                            piper = piper_list(i);
                            S = S_list(i);
                            r = r_list(i);
                            h = h_list(i);
                            plan = plan + 1;
                            tag = sprintf('[%d/%d] L=%g R=%g S=%g r=%g h=%g', plan, nSolveP, L, piper, S, r, h);
                            outPut_Text2(app, sprintf('%s ...', tag));
                            try
                                model_w = solveWeifushiModel(app, L, piper, S, sigma, current);
                                model_p = solvePitModel(app, L, piper, S, r, h, sigma, current);
                                for id = 1:numel(spacing_list)
                                    throwIfCancelled(app);
                                    spacing = spacing_list(id);
                                    try
                                        VW = extractV(app, model_w, L, piper, rows, cols, spacing, customCoord);
                                        VP = extractV(app, model_p, L, piper, rows, cols, spacing, customCoord);
                                        FC = (VP ./ VW - 1) * 1000;
                                        appendResult(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, VW, VP, FC);
                                    catch ME
                                        if isUserCancel(app, ME)
                                            rethrow(ME);
                                        end
                                        logSkippedBatchCase(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, ...
                                            classifyBatchError(app, ME, 'Voltage extraction failed'));
                                    end
                                end
                                outPut_Text2(app, sprintf('  done. progress=%.1f%%', plan/nSolveP*100));
                            catch ME
                                if isUserCancel(app, ME)
                                    rethrow(ME);
                                end
                                recordSkippedSpacings(app, L, piper, S, r, h, rows, cols, spacing_list, sigma, current, ...
                                    classifyBatchError(app, ME));
                            end
                            setProgress(app, plan / max(nSolveP, 1) * 100);
                        end
                    else
                        nSolveP = nPipe * numel(r_list) * numel(h_list);
                        outPut_Text2(app, sprintf('Mode: Combination | pit models=%d, spacing samples=%d', ...
                            nSolveP, numel(spacing_list)));
                        plan = 0;
                        for i = 1:nPipe
                            throwIfCancelled(app);
                            piper = piper_list(i);
                            S = S_list(i);
                            intactOk = false;
                            try
                                model_w = solveWeifushiModel(app, L, piper, S, sigma, current);
                                VW_all = cell(1, numel(spacing_list));
                                for id = 1:numel(spacing_list)
                                    throwIfCancelled(app);
                                    VW_all{id} = extractV(app, model_w, L, piper, rows, cols, spacing_list(id), customCoord);
                                end
                                intactOk = true;
                            catch ME
                                if isUserCancel(app, ME)
                                    rethrow(ME);
                                end
                                nSkip = numel(r_list) * numel(h_list);
                                plan = plan + nSkip;
                                recordSkippedIntactDependents(app, ME, L, piper, S, r_list, h_list, rows, cols, spacing_list, sigma, current);
                                setProgress(app, plan / max(nSolveP, 1) * 100);
                            end
                            if ~intactOk
                                continue
                            end
                            for r = r_list
                                for h = h_list
                                    throwIfCancelled(app);
                                    plan = plan + 1;
                                    tag = sprintf('[%d/%d] L=%g R=%g S=%g r=%g h=%g', plan, nSolveP, L, piper, S, r, h);
                                    outPut_Text2(app, sprintf('%s ...', tag));
                                    try
                                        model_p = solvePitModel(app, L, piper, S, r, h, sigma, current);
                                        for id = 1:numel(spacing_list)
                                            throwIfCancelled(app);
                                            spacing = spacing_list(id);
                                            try
                                                VW = VW_all{id};
                                                VP = extractV(app, model_p, L, piper, rows, cols, spacing, customCoord);
                                                FC = (VP ./ VW - 1) * 1000;
                                                appendResult(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, VW, VP, FC);
                                            catch ME
                                                if isUserCancel(app, ME)
                                                    rethrow(ME);
                                                end
                                                logSkippedBatchCase(app, L, piper, S, r, h, rows, cols, spacing, sigma, current, ...
                                                    classifyBatchError(app, ME, 'Voltage extraction failed'));
                                            end
                                        end
                                        outPut_Text2(app, sprintf('  done. progress=%.1f%%', plan/nSolveP*100));
                                    catch ME
                                        if isUserCancel(app, ME)
                                            rethrow(ME);
                                        end
                                        recordSkippedSpacings(app, L, piper, S, r, h, rows, cols, spacing_list, sigma, current, ...
                                            classifyBatchError(app, ME));
                                    end
                                    setProgress(app, plan / max(nSolveP, 1) * 100);
                                end
                            end
                        end
                    end
                end

                setProgress(app, 100);
                elapsedSec = seconds(datetime('now') - t0);
                timeStr = formatElapsedHM(app, elapsedSec);
                nOk = 0;
                nFail = 0;
                if ~isempty(app.ResultsTable)
                    nOk = height(app.ResultsTable);
                end
                if ~isempty(app.SkippedTable)
                    nFail = height(app.SkippedTable);
                end
                if nFail > 0
                    outPut_Text2(app, sprintf('*** Batch finished: %d rows saved, %d case(s) skipped, total time=%s ***', ...
                        nOk, nFail, timeStr));
                else
                    outPut_Text2(app, sprintf('*** Batch finished: %d rows in ResultsTable, total time=%s ***', nOk, timeStr));
                end
                saveBatchResultsIfAny(app, elapsedSec);
            catch ME
                elapsedSec = seconds(datetime('now') - t0);
                if isUserCancel(app, ME)
                    app.StatusLabel.Text = 'Cancelled';
                    nOk = 0;
                    if ~isempty(app.ResultsTable)
                        nOk = height(app.ResultsTable);
                    end
                    outPut_Text2(app, sprintf('*** Batch calculation cancelled (%d rows completed)***', nOk));
                    saveBatchResultsIfAny(app, elapsedSec);
                else
                    outPut_Text2(app, 'Calculation error occurred; remaining cases were not completed.');
                    saveBatchResultsIfAny(app, elapsedSec);
                end
            end
            endCalculation(app);
        end

        % Callback function
        function DropDown_databasesValueChanged(app, event)
            app.show_ComboboxName()
        end

        % Button pushed function: Button_ConnectMySQL_2
        function Button_ConnectMySQL_2Pushed(app, event)
            try
                connectName = app.EditField_connectName_2.Value;
                username = app.EditField_user_2.Value;
                pwd = app.EditField_pwd_2.Value;
                app.conn= database(connectName,username,pwd);
                app.Lamp_2.Color = '0.00,1.00,0.00';
            catch
                errordlg('Please ensure that the username and password are correct.','Database connection error!')
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            beginCalculation(app);
            try
                outPut_Text2(app, '*** Single model calculation start ***');
                setProgress(app, 2);
                throwIfCancelled(app);
                length = app.LengthSpinner.Value;
                piper = app.RadiusSpinner_pipe.Value;
                pipes = app.ThicknessSpinner.Value;
                pitr = app.RadiusSpinner.Value;
                pith = app.DepthSpinner.Value;
                rows = app.RowsSpinner.Value;
                cols = app.ColumnsSpinner.Value;
                spancing = app.SpacingSpinner.Value;
                sigma = app.ConductivitySpinner.Value;
                current = app.CurrentSpinner.Value;
                meshSize = getAutoMeshSize(app);
                outPut_Text2(app, sprintf('Mesh size: %s (autoMeshSize=%d)', ...
                    char(string(app.MeshsizeDropDown.Value)), meshSize));
                %                 drawnow limitrate;
                setProgress(app, 8);
                throwIfCancelled(app);
                [ok, customCoord, errMsg] = getCustomCoordIfNeeded(app, 'single', rows, cols);
                if ~ok
                    outPut_Text2(app, ['Calculate failed: ', errMsg]);
                    uialert(app.VDMMSMUIFigure, errMsg, 'Calculate failed');
                    endCalculation(app);
                    return;
                end
                outPut_Text2(app, 'Solving intact model...');
                setProgress(app, 15);
                throwIfCancelled(app);
                model_w = solveWeifushiModel(app, length, piper, pipes, sigma, current);
                throwIfCancelled(app);
                setProgress(app, 35);
                outPut_Text2(app, 'Extracting intact voltages...');
                throwIfCancelled(app);
                voltageW = extractV(app, model_w, length, piper, rows, cols, spancing, customCoord);
                setProgress(app, 50);
                outPut_Text2(app, 'Solving pitted model...');
                throwIfCancelled(app);
                model_p = solvePitModel(app, length, piper, pipes, pitr, pith, sigma, current);
                throwIfCancelled(app);
                setProgress(app, 75);
                outPut_Text2(app, 'Extracting pitted voltages...');
                throwIfCancelled(app);
                voltageP = extractV(app, model_p, length, piper, rows, cols, spancing, customCoord);
                setProgress(app, 88);
                outPut_Text2(app, 'Computing FC and plotting results...');
                throwIfCancelled(app);
                FC = (voltageP ./ voltageW - 1) * 1000;
                PlotSingleModel(app, voltageW, voltageP, FC, model_p);
                setProgress(app, 100);
                outPut_Text2(app, '*** Single model calculation completed ***');
            catch ME
                if isUserCancel(app, ME)
                    app.StatusLabel.Text = 'Cancelled';
                    outPut_Text2(app, '*** 	Single model calculation cancelled ***');
                else
                    app.StatusLabel.Text = 'Calculation Failed';
                    outPut_Text2(app, ['Calculation Failed：', ME.message]);
                    uialert(app.VDMMSMUIFigure, ME.message, 'Calculation Failed');
                    disp(getReport(ME, 'extended'));
                end
            end
            endCalculation(app);
        end

        % Selection changed function: CalculationModeButtonGroup
        function CalculationModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationModeButtonGroup.SelectedObject;
            if app.FullScanButton.Value
                app.ModelTypeNum = '1';
                app.EnableScanCheckBox.Value = false;
                app.EnableScanCheckBox.Enable = 'off';
                app.Panel_par1.Title = 'Batch Calculation Parameters';
            else
                app.ModelTypeNum = '2';
                app.EnableScanCheckBox.Enable = 'on';
                app.Panel_par1.Title = 'Batch Calculation Parameters';
            end

        end

        % Button pushed function: Button_openFolder
        function Button_openFolderPushed2(app, event)
            try
                app.DataSummaryFolder=uigetdir;
                app.EditField_folderPath.Value=app.DataSummaryFolder;
            catch
                errordlg('No folder selected!','Error！');
            end
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            if app.IsUpdatingExportOptions
                return
            end
            app.IsUpdatingExportOptions = true;
            v = app.SelectAllCheckBox.Value;
            app.ExporttoExcelCheckBox.Value = v;
            app.ExporttoMATfileCheckBox.Value = v;
            app.SaveReportCheckBox.Value = v;
            app.SavetoDatabaseCheckBox.Value = v;
            app.IsUpdatingExportOptions = false;

        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(app, event)
            applyMatrixSchemeVisibility(app, app.LayoutDropDown.Value, 'single');
            %             applyMatrixSchemeVisibility(app, app.LayoutDropDown_2.Value, 'batch');

        end

        % Value changed function: LayoutDropDown_2
        function LayoutDropDown_2ValueChanged(app, event)
            applyMatrixSchemeVisibility(app, app.LayoutDropDown_2.Value, 'batch');

        end

        % Value changed function: SettingButton_2
        function SettingButton_2ValueChanged(app, event)
            if app.SettingButton_2.Value
                PopupTableButtonPushed(app, 'single');
                app.SettingButton_2.Value = false;
            end

        end

        % Value changed function: SettingButton
        function SettingButtonValueChanged(app, event)
            if app.SettingButton.Value
                PopupTableButtonPushed(app, 'batch');
                app.SettingButton.Value = false;
            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            datatype = app.SourceDropDown.Value;
            switch datatype
                case 'Batch'
                    if isempty(app.ResultsTable) || height(app.ResultsTable) == 0
                        uialert(app.VDMMSMUIFigure, ' Please complete batch calculation first.', 'No Data');
                        return;
                    end
                    openSelecteDataDialog(app, app.ResultsTable, 'Batch');

                case 'Excel'
                    [file, path] = uigetfile({'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'}, ...
                        'Select Excel data file');
                    if isequal(file, 0)
                        return;
                    end
                    try
                        srcT = excelFileToResultsTable(app, fullfile(path, file));
                        if isempty(srcT) || height(srcT) == 0
                            error('No valid data rows exist in the Excel file.');
                        end
                        openSelecteDataDialog(app, srcT, 'Excel');
                    catch ME
                        uialert(app.VDMMSMUIFigure, ME.message, 'Excel Loading Failed!');
                    end

                case 'MAT'
                    [file, path] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select MAT data file');
                    if isequal(file, 0)
                        return;
                    end
                    try
                        S = load(fullfile(path, file));
                        if isfield(S, 'ResultsTable')
                            srcT = S.ResultsTable;
                        elseif isfield(S, 'SelectData')
                            srcT = S.SelectData;
                        else
                            error('No ResultsTable');
                        end
                        if isempty(srcT) || height(srcT) == 0
                            error('No Data');
                        end
                        openSelecteDataDialog(app, srcT, 'MAT');
                    catch ME
                        uialert(app.VDMMSMUIFigure, ME.message, 'MAT Loading Failed!');
                    end

                case 'Database'
                    if isempty(app.conn) || ~isopen(app.conn)
                        uialert(app.VDMMSMUIFigure, ...
                            'Please configure the connection in Database Configuration first.', ...
                            'Database connection not established. ');
                        return;
                    end
                    openSelecteDataDialog(app, [], 'Database');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            T = app.SelectData;
            if isempty(T) || height(T) == 0
                uialert(app.VDMMSMUIFigure, 'No data is selected currently. Please click Load to import data first.', 'No Data');
                return;
            end
            [file, path] = uiputfile({'*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Save selected data as Excel', 'SelectData.xlsx');
            if isequal(file, 0)
                return;
            end
            fpath = fullfile(path, file);
            try
                if isfile(fpath)
                    delete(fpath);
                end
                OutVW = buildFlattenedResultSheet(app, T, 'V_weifushi', 'Vw');
                OutVP = buildFlattenedResultSheet(app, T, 'V_pit', 'Vp');
                OutFC = buildFlattenedResultSheet(app, T, 'FC', 'FC');
                writetable(OutVW, fpath, 'Sheet', 'IntactVoltage');
                writetable(OutVP, fpath, 'Sheet', 'PitVoltage');
                writetable(OutFC, fpath, 'Sheet', 'FC');
                outPut_Text2(app, sprintf('SelectData saved to Excel: %s (%d rows, Sheet1=IntactVoltage, Sheet2=PitVoltage, Sheet3=FC).', ...
                    fpath, height(T)));
                uialert(app.VDMMSMUIFigure, sprintf('%d rows of data have been saved to:\n%s', height(T), fpath), 'Success');
            catch ME
                uialert(app.VDMMSMUIFigure, ME.message, 'Excel Saving Failed!');
            end
        end

        % Button pushed function: OKButton_SMD
        function OKButton_SMDPushed(app, event)
            displaySingleModelData(app);
        end

        % Button pushed function: OKButton_CA
        function OKButton_CAPushed(app, event)
            runCorrelationAnalysis(app);
        end

        % Button pushed function: OKButton_SMD_3
        function OKButton_SMD_3Pushed(app, event)
            runUnivariateAnalysis(app);
        end

        % Button pushed function: OKButton_SMD_4
        function OKButton_SMD_4Pushed(app, event)
            runMultivariateAnalysis(app);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            [file, path] = uigetfile({'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'}, ...
                'Select Excel data file');
            if isequal(file, 0)
                return;
            end
            fpath = fullfile(path, file);
            app.PathEditField.Value = fpath;
            try
                srcT = excelFileToResultsTable(app, fullfile(path, file));
                if isempty(srcT) || height(srcT) == 0
                    error('No Data');
                end
                openSelecteDataDialog(app, srcT, 'Excel');
            catch ME
                uialert(app.VDMMSMUIFigure, ME.message, 'Excel Loading Failed!');
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TabGroup6.SelectedTab = app.ModelStructureFlowchartTab;
            RefreshModelFigure(app);
            runPredictionTraining(app);
        end

        % Value changed function: NumSpinner
        function NumSpinnerValueChanged(app, event)
            updateSEREquationTable(app);

        end

        % Value changed function: ModelsDropDown
        function ModelsDropDownValueChanged(app, event)
            switch app.ModelsDropDown.Value
                case 'BP'
                    app.TabGroup4.SelectedTab = app.BPTab;
                case 'RF'
                    app.TabGroup4.SelectedTab = app.RFTab;
                case 'GBDT'
                    app.TabGroup4.SelectedTab = app.GBDTTab_2;
                case 'SER'
                    app.TabGroup4.SelectedTab = app.SERTab;
                    updateSEREquationTable(app);
            end
            inNames = getCheckedTreeKeys(app, app.Tree);
            outNames = getCheckedTreeKeys(app, app.Tree_2);
            if ~isempty(inNames) && ~isempty(outNames)
                RefreshModelFigure(app);
            end

        end

        % Callback function: Tree_2
        function Tree_2CheckedNodesChanged(app, event)
            yKeys = getCheckedTreeKeys(app, app.Tree_2);
            if ~isempty(yKeys)
                app.NumSpinner.Value = max(1, numel(yKeys));
            end
            updateSEREquationTable(app);

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            updateSEREquationTable(app);

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            data = app.UITable2.Data;
            colNames = app.UITable2.ColumnName;
            if isempty(data)
                uialert(app.VDMMSMUIFigure, 'Please train the model first.', 'No Data');
                return;
            end
            if isempty(colNames)
                colNames = arrayfun(@(k) sprintf('Col%d', k), 1:size(data, 2), 'UniformOutput', false);
            end
            if isstring(colNames)
                colNames = cellstr(colNames);
            end
            defaultName = sprintf('PredictionSamples_%s.xlsx', datestr(now, 'yyyymmdd_HHMMSS'));
            [file, path] = uiputfile({'*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Save prediction samples as Excel', defaultName);
            if isequal(file, 0)
                return;
            end
            fpath = fullfile(path, file);
            try
                varNames = matlab.lang.makeValidName(colNames, 'ReplacementStyle', 'underscore');
                varNames = matlab.lang.makeUniqueStrings(varNames);
                if iscell(data)
                    Out = cell2table(data, 'VariableNames', varNames);
                else
                    Out = array2table(data, 'VariableNames', varNames);
                end
                if isfile(fpath)
                    delete(fpath);
                end
                writetable(Out, fpath, 'Sheet', 'Samples');
                metricData = app.UITable2_2.Data;
                if ~isempty(metricData)
                    if iscell(metricData)
                        MetricTbl = cell2table(metricData, 'VariableNames', {'Results', 'Value'});
                    else
                        MetricTbl = array2table(metricData, 'VariableNames', {'Results', 'Value'});
                    end
                    writetable(MetricTbl, fpath, 'Sheet', 'Metrics');
                end
                outPut_Text2(app, sprintf('Prediction samples saved: %s (%d rows).', fpath, size(data, 1)));
                uialert(app.VDMMSMUIFigure, sprintf('	%d rows of samples have been exported to:\n%s', size(data, 1), fpath), 'Success');
            catch ME
                uialert(app.VDMMSMUIFigure, ME.message, 'Excel Export Failed');
            end
        end

        % Button pushed function: SaveTrainedModelButton
        function SaveTrainedModelButtonPushed(app, event)
            mdl = app.PredTrainedModel;
            if isempty(mdl) || ~isstruct(mdl) || ~isfield(mdl, 'Type')
                uialert(app.VDMMSMUIFigure, 'Please click Train first.', 'No Model');
                return;
            end
            modelType = mdl.Type;
            defaultName = sprintf('TrainedModel_%s_%s.mat', modelType, datestr(now, 'yyyymmdd_HHMMSS'));
            [file, path] = uiputfile( ...
                {'*.mat', 'MATLAB MAT Files (*.mat)'}, ...
                'Save trained model', defaultName);
            if isequal(file, 0)
                return;
            end
            fpath = fullfile(path, file);
            try
                PredTrainedModel = mdl;
                ModelType = modelType;
                SavedAt = datestr(now, 31);
                if strcmp(modelType, 'SER') && isfield(mdl, 'Equations')
                    eqs = mdl.Equations;
                    eqLines = cell(numel(eqs), 1);
                    for i = 1:numel(eqs)
                        b = eqs(i).beta;
                        eqLines{i} = sprintf('%s = %.8g*%s + %.8g*%s + %.8g', ...
                            eqs(i).yName, b(1), eqs(i).x1Name, b(2), eqs(i).x2Name, b(3));
                    end
                    if isfield(mdl, 'Processing')
                        Processing = mdl.Processing;
                    else
                        Processing = 'Raw';
                    end
                    EquationStrings = eqLines;
                    save(fpath, 'PredTrainedModel', 'ModelType', 'SavedAt', ...
                        'EquationStrings', 'Processing');
                else
                    save(fpath, 'PredTrainedModel', 'ModelType', 'SavedAt');
                end
                outPut_Text2(app, sprintf('Trained model (%s) saved: %s', modelType, fpath));
                uialert(app.VDMMSMUIFigure, sprintf('	The %s model has been saved to:\n%s', modelType, fpath), 'Success');
            catch ME
                uialert(app.VDMMSMUIFigure, ME.message, 'Model Saving Failed');
            end
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(app, event)
            if ~app.IsCalculating
                return;
            end
            app.CancelRequested = true;
            outPut_Text2(app, 'Cancelling...');
            drawnow;
        end

        % Close request function: VDMMSMUIFigure
        function VDMMSMUIFigureCloseRequest(app, event)
            if app.IsCalculating
                choice = uiconfirm(app.VDMMSMUIFigure, ...
                    'Closing the window will terminate the calculation. Proceed to close?', ...
                    'Confirm', ...
                    'Options', {'Close', 'Continue'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);
                if strcmp(choice, 'Continue')
                    return;
                end
                app.CancelRequested = true;
            else
                choice = uiconfirm(app.VDMMSMUIFigure, ...
                    'Are you sure you want to close the window?', ...
                    'Confirm', ...
                    'Options', {'Close', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);
                if ~strcmp(choice, 'Close')
                    return;
                end
            end
            delete(app.VDMMSMUIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VDMMSMUIFigure = uifigure('Visible', 'off');
            app.VDMMSMUIFigure.Name = 'VDMMBESP';
            app.VDMMSMUIFigure.CloseRequestFcn = createCallbackFcn(app, @VDMMSMUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.VDMMSMUIFigure);
            app.ProgressPanel = uipanel(app.GridLayout);
            app.ProgressPanel.Title = 'Progress';
            app.Button_cancel = uibutton(app.ProgressPanel, 'push');
            app.Button_cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_cancelPushed, true);
            app.Button_cancel.Tooltip = {'Cancel calculation'};
            app.Button_cancel.Text = 'Cancel';
            app.ProgressBg = uilabel(app.ProgressPanel);
            app.ProgressBg.Text = '';
            app.ProgressFg = uilabel(app.ProgressPanel);
            app.ProgressFg.Text = '';
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SingleModelCalculationTab = uitab(app.TabGroup);
            app.SingleModelCalculationTab.Title = 'Single Model Calculation';
            app.ParameterSettingsPanel = uipanel(app.SingleModelCalculationTab);
            app.ParameterSettingsPanel.Title = 'Parameter Settings';
            app.PipelineparametersPanel = uipanel(app.ParameterSettingsPanel);
            app.PipelineparametersPanel.Title = 'Pipeline parameters';
            app.mmLabel_36 = uilabel(app.PipelineparametersPanel);
            app.mmLabel_36.Text = 'mm';
            app.LengthSpinnerLabel = uilabel(app.PipelineparametersPanel);
            app.LengthSpinnerLabel.Text = 'Length:';
            app.LengthSpinner = uispinner(app.PipelineparametersPanel);
            app.LengthSpinner.Limits = [0 Inf];
            app.LengthSpinner.Value = 1000;
            app.mmLabel_37 = uilabel(app.PipelineparametersPanel);
            app.mmLabel_37.Text = 'mm';
            app.RadiusSpinner_2Label = uilabel(app.PipelineparametersPanel);
            app.RadiusSpinner_2Label.Text = 'Radius:';
            app.RadiusSpinner_pipe = uispinner(app.PipelineparametersPanel);
            app.RadiusSpinner_pipe.Limits = [0 Inf];
            app.RadiusSpinner_pipe.Value = 50;
            app.mmLabel_38 = uilabel(app.PipelineparametersPanel);
            app.mmLabel_38.Text = 'mm';
            app.ThicknessSpinnerLabel = uilabel(app.PipelineparametersPanel);
            app.ThicknessSpinnerLabel.Text = 'Thickness:';
            app.ThicknessSpinner = uispinner(app.PipelineparametersPanel);
            app.ThicknessSpinner.Limits = [0 Inf];
            app.ThicknessSpinner.Value = 5;
            app.PitparametersPanel_2 = uipanel(app.ParameterSettingsPanel);
            app.PitparametersPanel_2.Title = 'Pit parameters';
            app.mmLabel_33 = uilabel(app.PitparametersPanel_2);
            app.mmLabel_33.Text = 'mm';
            app.mmLabel_34 = uilabel(app.PitparametersPanel_2);
            app.mmLabel_34.Text = 'mm';
            app.RadiusSpinnerLabel = uilabel(app.PitparametersPanel_2);
            app.RadiusSpinnerLabel.Text = 'Radius:';
            app.RadiusSpinner = uispinner(app.PitparametersPanel_2);
            app.RadiusSpinner.Step = 0.05;
            app.RadiusSpinner.Limits = [0 Inf];
            app.RadiusSpinner.Value = 2;
            app.DepthSpinnerLabel = uilabel(app.PitparametersPanel_2);
            app.DepthSpinnerLabel.Text = 'Depth:';
            app.DepthSpinner = uispinner(app.PitparametersPanel_2);
            app.DepthSpinner.Step = 0.05;
            app.DepthSpinner.Limits = [0 Inf];
            app.DepthSpinner.Value = 3;
            app.CalculateButton = uibutton(app.ParameterSettingsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MatrixparametersPanel = uipanel(app.ParameterSettingsPanel);
            app.MatrixparametersPanel.Title = 'Matrix parameters';
            app.mmLabel_39 = uilabel(app.MatrixparametersPanel);
            app.mmLabel_39.Text = 'mm';
            app.RowsSpinnerLabel = uilabel(app.MatrixparametersPanel);
            app.RowsSpinnerLabel.Text = 'Rows:';
            app.RowsSpinner = uispinner(app.MatrixparametersPanel);
            app.RowsSpinner.Limits = [0 Inf];
            app.RowsSpinner.Value = 5;
            app.mmLabel_40 = uilabel(app.MatrixparametersPanel);
            app.mmLabel_40.Text = 'mm';
            app.ColumnsSpinnerLabel = uilabel(app.MatrixparametersPanel);
            app.ColumnsSpinnerLabel.Text = 'Columns:';
            app.ColumnsSpinner = uispinner(app.MatrixparametersPanel);
            app.ColumnsSpinner.Limits = [0 Inf];
            app.ColumnsSpinner.Value = 8;
            app.mmLabel_41 = uilabel(app.MatrixparametersPanel);
            app.mmLabel_41.Text = 'mm';
            app.SpacingSpinnerLabel = uilabel(app.MatrixparametersPanel);
            app.SpacingSpinnerLabel.Text = 'Spacing:';
            app.SpacingSpinner = uispinner(app.MatrixparametersPanel);
            app.SpacingSpinner.Limits = [0 Inf];
            app.SpacingSpinner.Value = 30;
            app.LayoutDropDownLabel = uilabel(app.MatrixparametersPanel);
            app.LayoutDropDownLabel.Text = 'Layout:';
            app.LayoutDropDown = uidropdown(app.MatrixparametersPanel);
            app.LayoutDropDown.Items = {'Equal', 'Customize'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownValueChanged, true);
            app.LayoutDropDown.Value = 'Equal';
            app.SettingButton_2 = uibutton(app.MatrixparametersPanel, 'state');
            app.SettingButton_2.ValueChangedFcn = createCallbackFcn(app, @SettingButton_2ValueChanged, true);
            app.SettingButton_2.Text = 'Setting';
            app.PhysicalfieldparametersPanel = uipanel(app.ParameterSettingsPanel);
            app.PhysicalfieldparametersPanel.Title = 'Physical field parameters';
            app.SmLabel = uilabel(app.PhysicalfieldparametersPanel);
            app.SmLabel.Text = 'S/m';
            app.ALabel = uilabel(app.PhysicalfieldparametersPanel);
            app.ALabel.Text = 'A';
            app.ConductivitySpinnerLabel = uilabel(app.PhysicalfieldparametersPanel);
            app.ConductivitySpinnerLabel.Text = 'Conductivity:';
            app.ConductivitySpinner = uispinner(app.PhysicalfieldparametersPanel);
            app.ConductivitySpinner.Step = 100;
            app.ConductivitySpinner.Limits = [0 Inf];
            app.ConductivitySpinner.Value = 1450000;
            app.CurrentSpinnerLabel = uilabel(app.PhysicalfieldparametersPanel);
            app.CurrentSpinnerLabel.Text = 'Current:';
            app.CurrentSpinner = uispinner(app.PhysicalfieldparametersPanel);
            app.CurrentSpinner.Step = 0.05;
            app.CurrentSpinner.Limits = [0 Inf];
            app.CurrentSpinner.Value = 30;
            app.MeshsizeDropDownLabel = uilabel(app.PhysicalfieldparametersPanel);
            app.MeshsizeDropDownLabel.Text = 'Mesh size:';
            app.MeshsizeDropDown = uidropdown(app.PhysicalfieldparametersPanel);
            app.MeshsizeDropDown.Items = {'Extremely fine', 'Extra fine', 'Finer', 'Fine', 'Normal', 'Coarse', 'Coarser', 'Extra coarse', 'Extremely coarse'};
            app.MeshsizeDropDown.Value = 'Extremely fine';
            app.ResultsPanel = uipanel(app.SingleModelCalculationTab);
            app.ResultsPanel.Title = 'Results';
            app.PotentialDistributionPanel = uipanel(app.ResultsPanel);
            app.PotentialDistributionPanel.Title = 'Potential Distribution';
            app.UIAxes_SVD = uiaxes(app.PotentialDistributionPanel);
            app.UIAxes_SVD.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CurrentDensityDistributionPanel = uipanel(app.ResultsPanel);
            app.CurrentDensityDistributionPanel.Title = 'Current Density Distribution	';
            app.UIAxes_SMFCD = uiaxes(app.CurrentDensityDistributionPanel);
            app.TabGroup2 = uitabgroup(app.ResultsPanel);
            app.VIntactVTab = uitab(app.TabGroup2);
            app.VIntactVTab.Title = 'V (Intact, μV)';
            app.UITable_Data_SVW = uitable(app.VIntactVTab);
            app.UITable_Data_SVW.ColumnName = '';
            app.UITable_Data_SVW.RowName = {};
            app.VPittedVTab = uitab(app.TabGroup2);
            app.VPittedVTab.Title = 'V (Pitted, μV)';
            app.UITable_Data_SV = uitable(app.VPittedVTab);
            app.UITable_Data_SV.ColumnName = '';
            app.UITable_Data_SV.RowName = {};
            app.FCValuesTab = uitab(app.TabGroup2);
            app.FCValuesTab.Title = 'FC Values';
            app.UITable_Data_SFC = uitable(app.FCValuesTab);
            app.UITable_Data_SFC.ColumnName = '';
            app.UITable_Data_SFC.RowName = {};
            app.FCDistributionPanel_2 = uipanel(app.ResultsPanel);
            app.FCDistributionPanel_2.Title = 'FC Distribution';
            app.UIAxes_SFCD = uiaxes(app.FCDistributionPanel_2);
            app.BatchModelCalculationTab = uitab(app.TabGroup);
            app.BatchModelCalculationTab.Title = 'Batch Model Calculation';
            app.Panel_par1 = uipanel(app.BatchModelCalculationTab);
            app.Panel_par1.Tooltip = {''};
            app.Panel_par1.Title = 'Batch Calculation Parameters';
            app.PipelineparametersPanel_2 = uipanel(app.Panel_par1);
            app.PipelineparametersPanel_2.Title = 'Pipeline parameters';
            app.LengthEditFieldLabel = uilabel(app.PipelineparametersPanel_2);
            app.LengthEditFieldLabel.Text = 'Length: ';
            app.LengthEditField = uieditfield(app.PipelineparametersPanel_2, 'text');
            app.LengthEditField.Value = '1000';
            app.ThicknessEditFieldLabel = uilabel(app.PipelineparametersPanel_2);
            app.ThicknessEditFieldLabel.Text = 'Thickness:';
            app.ThicknessEditField = uieditfield(app.PipelineparametersPanel_2, 'text');
            app.ThicknessEditField.Value = '7';
            app.mmLabel_2 = uilabel(app.PipelineparametersPanel_2);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.PipelineparametersPanel_2);
            app.mmLabel_3.Text = 'mm';
            app.RadiusEditFieldLabel = uilabel(app.PipelineparametersPanel_2);
            app.RadiusEditFieldLabel.Text = 'Radius:';
            app.RadiusEditField = uieditfield(app.PipelineparametersPanel_2, 'text');
            app.RadiusEditField.Value = '50';
            app.mmLabel_29 = uilabel(app.PipelineparametersPanel_2);
            app.mmLabel_29.Text = 'mm';
            app.PitsparametersPanel = uipanel(app.Panel_par1);
            app.PitsparametersPanel.Title = 'Pits parameters';
            app.RadiusEditField_3Label = uilabel(app.PitsparametersPanel);
            app.RadiusEditField_3Label.Text = 'Radius:';
            app.RadiusEditField_3 = uieditfield(app.PitsparametersPanel, 'text');
            app.RadiusEditField_3.Value = '4';
            app.DepthEditFieldLabel = uilabel(app.PitsparametersPanel);
            app.DepthEditFieldLabel.Text = 'Depth:';
            app.DepthEditField = uieditfield(app.PitsparametersPanel, 'text');
            app.DepthEditField.Value = '4';
            app.mmLabel_7 = uilabel(app.PitsparametersPanel);
            app.mmLabel_7.Text = 'mm';
            app.mmLabel_8 = uilabel(app.PitsparametersPanel);
            app.mmLabel_8.Text = 'mm';
            app.EnableScanCheckBox = uicheckbox(app.PitsparametersPanel);
            app.EnableScanCheckBox.Text = 'Enable Scan';
            app.CalculationModeButtonGroup = uibuttongroup(app.Panel_par1);
            app.CalculationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationModeButtonGroupSelectionChanged, true);
            app.CalculationModeButtonGroup.Title = 'Calculation Mode';
            app.FullScanButton = uiradiobutton(app.CalculationModeButtonGroup);
            app.FullScanButton.Text = 'Full Scan';
            app.FullScanButton.Value = true;
            app.CombinationButton = uiradiobutton(app.CalculationModeButtonGroup);
            app.CombinationButton.Text = 'Combination';
            app.Panel_matrix = uipanel(app.Panel_par1);
            app.Panel_matrix.Title = 'Matrix parameters';
            app.RowsLabel = uilabel(app.Panel_matrix);
            app.RowsLabel.Text = 'Rows:';
            app.RowsEditField = uieditfield(app.Panel_matrix, 'numeric');
            app.RowsEditField.Value = 3;
            app.ColumnsEditFieldLabel = uilabel(app.Panel_matrix);
            app.ColumnsEditFieldLabel.Text = 'Columns:';
            app.ColumnsEditField = uieditfield(app.Panel_matrix, 'numeric');
            app.ColumnsEditField.Value = 4;
            app.mmLabel = uilabel(app.Panel_matrix);
            app.mmLabel.Text = 'mm';
            app.SpacingEditFieldLabel = uilabel(app.Panel_matrix);
            app.SpacingEditFieldLabel.Text = 'Spacing:';
            app.SpacingEditField = uieditfield(app.Panel_matrix, 'text');
            app.SpacingEditField.Value = '20:5:30';
            app.LayoutDropDown_2Label = uilabel(app.Panel_matrix);
            app.LayoutDropDown_2Label.Text = 'Layout:';
            app.LayoutDropDown_2 = uidropdown(app.Panel_matrix);
            app.LayoutDropDown_2.Items = {'Equal', 'Customize'};
            app.LayoutDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDown_2ValueChanged, true);
            app.LayoutDropDown_2.Value = 'Equal';
            app.SettingButton = uibutton(app.Panel_matrix, 'state');
            app.SettingButton.ValueChangedFcn = createCallbackFcn(app, @SettingButtonValueChanged, true);
            app.SettingButton.Text = 'Setting';
            app.Button_startCalculate = uibutton(app.Panel_par1, 'push');
            app.Button_startCalculate.ButtonPushedFcn = createCallbackFcn(app, @Button_startCalculatePushed, true);
            app.Button_startCalculate.Text = 'Calculate';
            app.Panel_matrix_2 = uipanel(app.BatchModelCalculationTab);
            app.Panel_matrix_2.Title = 'Result Saving';
            app.DatabaseConfigurationPanel = uipanel(app.Panel_matrix_2);
            app.DatabaseConfigurationPanel.Title = 'Database Configuration';
            app.Button_ConnectMySQL_2 = uibutton(app.DatabaseConfigurationPanel, 'push');
            app.Button_ConnectMySQL_2.ButtonPushedFcn = createCallbackFcn(app, @Button_ConnectMySQL_2Pushed, true);
            app.Button_ConnectMySQL_2.Text = 'Connect';
            app.UsernameLabel = uilabel(app.DatabaseConfigurationPanel);
            app.UsernameLabel.Text = 'Username：';
            app.EditField_user_2 = uieditfield(app.DatabaseConfigurationPanel, 'text');
            app.EditField_user_2.Tooltip = {'数据库登录名称'};
            app.PasswordLabel = uilabel(app.DatabaseConfigurationPanel);
            app.PasswordLabel.Text = 'Password：';
            app.EditField_pwd_2 = uieditfield(app.DatabaseConfigurationPanel, 'text');
            app.EditField_pwd_2.Tooltip = {'数据库登录密码'};
            app.DataSourceLabel = uilabel(app.DatabaseConfigurationPanel);
            app.DataSourceLabel.Text = 'Data Source：';
            app.EditField_connectName_2 = uieditfield(app.DatabaseConfigurationPanel, 'text');
            app.EditField_connectName_2.Tooltip = {'ODCB数据源名称'};
            app.Lamp_2 = uilamp(app.DatabaseConfigurationPanel);
            app.StatusLabel_2 = uilabel(app.DatabaseConfigurationPanel);
            app.StatusLabel_2.Text = 'Status';
            app.ExportOptionsPanel = uipanel(app.Panel_matrix_2);
            app.ExportOptionsPanel.Title = 'Export Options';
            app.ExporttoExcelCheckBox = uicheckbox(app.ExportOptionsPanel);
            app.ExporttoExcelCheckBox.Text = 'Export to Excel';
            app.ExporttoExcelCheckBox.Value = true;
            app.ExporttoMATfileCheckBox = uicheckbox(app.ExportOptionsPanel);
            app.ExporttoMATfileCheckBox.Text = 'Export to MAT-file';
            app.ExporttoMATfileCheckBox.Value = true;
            app.SavetoDatabaseCheckBox = uicheckbox(app.ExportOptionsPanel);
            app.SavetoDatabaseCheckBox.Text = 'Save to Database';
            app.SelectAllCheckBox = uicheckbox(app.ExportOptionsPanel);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.SaveReportCheckBox = uicheckbox(app.ExportOptionsPanel);
            app.SaveReportCheckBox.Text = 'Save Report';
            app.SaveReportCheckBox.Value = true;
            app.FileSavePathPanel = uipanel(app.Panel_matrix_2);
            app.FileSavePathPanel.Title = 'File Save Path';
            app.PathLabel = uilabel(app.FileSavePathPanel);
            app.PathLabel.Text = 'Path：';
            app.EditField_folderPath = uieditfield(app.FileSavePathPanel, 'text');
            app.Button_openFolder = uibutton(app.FileSavePathPanel, 'push');
            app.Button_openFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_openFolderPushed2, true);
            app.Button_openFolder.Tooltip = {'选择FC值保存的文件夹位置'};
            app.Button_openFolder.Text = 'Browse';
            app.FileNameLabel = uilabel(app.FileSavePathPanel);
            app.FileNameLabel.Text = 'File Name：';
            app.EditField_FileName = uieditfield(app.FileSavePathPanel, 'text');
            app.ReportNameLabel = uilabel(app.FileSavePathPanel);
            app.ReportNameLabel.Text = 'Report Name：';
            app.EditField_ReportName = uieditfield(app.FileSavePathPanel, 'text');
            app.ResultsPanel_2 = uipanel(app.BatchModelCalculationTab);
            app.ResultsPanel_2.Title = 'Results';
            app.UITable = uitable(app.ResultsPanel_2);
            app.UITable.ColumnName = {'Total Models'; 'Calculation Time'; 'Max Voltage (Intact)'; 'Max Voltage (Pit)'; 'Max FC'; 'Min FC'};
            app.UITable.RowName = {};
            app.UITable_showalldata = uitable(app.ResultsPanel_2);
            app.UITable_showalldata.ColumnName = '';
            app.UITable_showalldata.RowName = {};
            app.DataAnalysisTab = uitab(app.TabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.DataSelectionPanel = uipanel(app.DataAnalysisTab);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.SourceLabel = uilabel(app.DataSelectionPanel);
            app.SourceLabel.Text = 'Source:';
            app.SourceDropDown = uidropdown(app.DataSelectionPanel);
            app.SourceDropDown.Items = {'Batch', 'Excel', 'Database', 'MAT'};
            app.SourceDropDown.Value = 'Batch';
            app.UITable_showselectdata = uitable(app.DataSelectionPanel);
            app.UITable_showselectdata.ColumnName = '';
            app.UITable_showselectdata.RowName = {};
            app.DataRangeDropDownLabel = uilabel(app.DataSelectionPanel);
            app.DataRangeDropDownLabel.Text = 'DataRange:';
            app.DataRangeDropDown = uidropdown(app.DataSelectionPanel);
            app.DataRangeDropDown.Items = {'Top 10 Rows', 'All'};
            app.DataRangeDropDown.Value = 'Top 10 Rows';
            app.LoadButton = uibutton(app.DataSelectionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.DataSelectionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DataAnalysisPanel = uipanel(app.DataAnalysisTab);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.TabGroup3 = uitabgroup(app.DataAnalysisPanel);
            app.SingleModelDataTab = uitab(app.TabGroup3);
            app.SingleModelDataTab.Title = 'Single Model Data';
            app.SettingsPanel_4 = uipanel(app.SingleModelDataTab);
            app.SettingsPanel_4.Title = 'Settings';
            app.PipeLDropDownLabel = uilabel(app.SettingsPanel_4);
            app.PipeLDropDownLabel.Text = 'PipeL:';
            app.PipeLDropDown = uidropdown(app.SettingsPanel_4);
            app.PipeLDropDown.Items = {};
            app.PipeLDropDown.Value = {};
            app.PipeRDropDownLabel = uilabel(app.SettingsPanel_4);
            app.PipeRDropDownLabel.Text = 'PipeR:';
            app.PipeRDropDown = uidropdown(app.SettingsPanel_4);
            app.PipeRDropDown.Items = {};
            app.PipeRDropDown.Value = {};
            app.PipeTDropDownLabel = uilabel(app.SettingsPanel_4);
            app.PipeTDropDownLabel.Text = 'PipeT:';
            app.PipeTDropDown = uidropdown(app.SettingsPanel_4);
            app.PipeTDropDown.Items = {};
            app.PipeTDropDown.Value = {};
            app.PitrDropDownLabel = uilabel(app.SettingsPanel_4);
            app.PitrDropDownLabel.Text = 'Pitr:';
            app.PitrDropDown = uidropdown(app.SettingsPanel_4);
            app.PitrDropDown.Items = {};
            app.PitrDropDown.Value = {};
            app.PithDropDownLabel = uilabel(app.SettingsPanel_4);
            app.PithDropDownLabel.Text = 'Pith:';
            app.PithDropDown = uidropdown(app.SettingsPanel_4);
            app.PithDropDown.Items = {};
            app.PithDropDown.Value = {};
            app.SpacingDropDownLabel = uilabel(app.SettingsPanel_4);
            app.SpacingDropDownLabel.Text = 'Spacing:';
            app.SpacingDropDown = uidropdown(app.SettingsPanel_4);
            app.SpacingDropDown.Items = {};
            app.SpacingDropDown.Value = {};
            app.OKButton_SMD = uibutton(app.SettingsPanel_4, 'push');
            app.OKButton_SMD.ButtonPushedFcn = createCallbackFcn(app, @OKButton_SMDPushed, true);
            app.OKButton_SMD.Text = 'OK';
            app.ResultsPanel_5 = uipanel(app.SingleModelDataTab);
            app.ResultsPanel_5.Title = 'Results';
            app.UIAxes_smd = uiaxes(app.ResultsPanel_5);
            title(app.UIAxes_smd, 'Title')
            xlabel(app.UIAxes_smd, 'X')
            ylabel(app.UIAxes_smd, 'Y')
            zlabel(app.UIAxes_smd, 'Z')
            app.UITable_smd = uitable(app.ResultsPanel_5);
            app.UITable_smd.ColumnName = '';
            app.UITable_smd.RowName = {};
            app.CorrelationAnalysisTab = uitab(app.TabGroup3);
            app.CorrelationAnalysisTab.Title = 'Correlation Analysis';
            app.SettingsPanel = uipanel(app.CorrelationAnalysisTab);
            app.SettingsPanel.Title = 'Settings';
            app.CorrelationTypeDropDownLabel = uilabel(app.SettingsPanel);
            app.CorrelationTypeDropDownLabel.Text = 'Correlation Type:';
            app.CorrelationTypeDropDown = uidropdown(app.SettingsPanel);
            app.CorrelationTypeDropDown.Items = {'Pearson', 'Spearman'};
            app.CorrelationTypeDropDown.Value = 'Pearson';
            app.InputColumnsPanel = uipanel(app.SettingsPanel);
            app.InputColumnsPanel.Title = 'Input Columns';
            app.Tree_CA = uitree(app.InputColumnsPanel, 'checkbox');
            app.OKButton_CA = uibutton(app.SettingsPanel, 'push');
            app.OKButton_CA.ButtonPushedFcn = createCallbackFcn(app, @OKButton_CAPushed, true);
            app.OKButton_CA.Text = 'OK';
            app.TabGroup5 = uitabgroup(app.CorrelationAnalysisTab);
            app.HeatmapTab = uitab(app.TabGroup5);
            app.HeatmapTab.Title = 'Heatmap';
            app.UIAxes6 = uiaxes(app.HeatmapTab);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.CoefficientTab = uitab(app.TabGroup5);
            app.CoefficientTab.Title = 'Coefficient';
            app.UITable4 = uitable(app.CoefficientTab);
            app.UITable4.ColumnName = '';
            app.UITable4.RowName = {};
            app.UnivariateAnalysisTab = uitab(app.TabGroup3);
            app.UnivariateAnalysisTab.Title = 'Univariate Analysis';
            app.UIAxes3 = uiaxes(app.UnivariateAnalysisTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SettingsPanel_2 = uipanel(app.UnivariateAnalysisTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.XDropDownLabel = uilabel(app.SettingsPanel_2);
            app.XDropDownLabel.Text = 'X:';
            app.XDropDown = uidropdown(app.SettingsPanel_2);
            app.XDropDown.Items = {};
            app.XDropDown.Value = {};
            app.YDropDownLabel = uilabel(app.SettingsPanel_2);
            app.YDropDownLabel.Text = 'Y:';
            app.YDropDown = uidropdown(app.SettingsPanel_2);
            app.YDropDown.Items = {};
            app.YDropDown.Value = {};
            app.CheckBox = uicheckbox(app.SettingsPanel_2);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.FittingMethodDropDownLabel = uilabel(app.SettingsPanel_2);
            app.FittingMethodDropDownLabel.Text = 'Fitting Method:';
            app.FittingMethodDropDown = uidropdown(app.SettingsPanel_2);
            app.FittingMethodDropDown.Items = {'Linear', 'Polynomial'};
            app.FittingMethodDropDown.Value = 'Polynomial';
            app.ProcessingDropDownLabel = uilabel(app.SettingsPanel_2);
            app.ProcessingDropDownLabel.Text = 'Processing:';
            app.ProcessingDropDown = uidropdown(app.SettingsPanel_2);
            app.ProcessingDropDown.Items = {'Raw', 'Logarithm', 'Reciprocal'};
            app.ProcessingDropDown.Value = 'Raw';
            app.FittingResultTextAreaLabel = uilabel(app.SettingsPanel_2);
            app.FittingResultTextAreaLabel.Text = 'Fitting Result：';
            app.FittingResultTextArea = uitextarea(app.SettingsPanel_2);
            app.OKButton_SMD_3 = uibutton(app.SettingsPanel_2, 'push');
            app.OKButton_SMD_3.ButtonPushedFcn = createCallbackFcn(app, @OKButton_SMD_3Pushed, true);
            app.OKButton_SMD_3.Text = 'OK';
            app.MultivariateAnalysisTab = uitab(app.TabGroup3);
            app.MultivariateAnalysisTab.Title = 'Multivariate Analysis';
            app.SettingsPanel_3 = uipanel(app.MultivariateAnalysisTab);
            app.SettingsPanel_3.Title = 'Settings';
            app.X1DropDownLabel = uilabel(app.SettingsPanel_3);
            app.X1DropDownLabel.Text = 'X1:';
            app.X1DropDown = uidropdown(app.SettingsPanel_3);
            app.X1DropDown.Items = {};
            app.X1DropDown.Value = {};
            app.X2DropDownLabel = uilabel(app.SettingsPanel_3);
            app.X2DropDownLabel.Text = 'X2:';
            app.X2DropDown = uidropdown(app.SettingsPanel_3);
            app.X2DropDown.Items = {};
            app.X2DropDown.Value = {};
            app.CheckBox_2 = uicheckbox(app.SettingsPanel_3);
            app.CheckBox_2.Text = '';
            app.FittingMethodDropDown_2Label = uilabel(app.SettingsPanel_3);
            app.FittingMethodDropDown_2Label.Text = 'Fitting Method:';
            app.FittingMethodDropDown_2 = uidropdown(app.SettingsPanel_3);
            app.FittingMethodDropDown_2.Items = {'Linear', 'Polynomial'};
            app.FittingMethodDropDown_2.Value = 'Linear';
            app.ProcessingDropDown_2Label = uilabel(app.SettingsPanel_3);
            app.ProcessingDropDown_2Label.Text = 'Processing:';
            app.ProcessingDropDown_2 = uidropdown(app.SettingsPanel_3);
            app.ProcessingDropDown_2.Items = {'Raw', 'Logarithm', 'Reciprocal'};
            app.ProcessingDropDown_2.Value = 'Raw';
            app.FittingResultTextArea_2Label = uilabel(app.SettingsPanel_3);
            app.FittingResultTextArea_2Label.Text = 'Fitting Result：';
            app.FittingResultTextArea_2 = uitextarea(app.SettingsPanel_3);
            app.YDropDown_3Label = uilabel(app.SettingsPanel_3);
            app.YDropDown_3Label.Text = 'Y:';
            app.YDropDown_3 = uidropdown(app.SettingsPanel_3);
            app.YDropDown_3.Items = {};
            app.YDropDown_3.Value = {};
            app.OKButton_SMD_4 = uibutton(app.SettingsPanel_3, 'push');
            app.OKButton_SMD_4.ButtonPushedFcn = createCallbackFcn(app, @OKButton_SMD_4Pushed, true);
            app.OKButton_SMD_4.Text = 'OK';
            app.ResultsPanel_4 = uipanel(app.MultivariateAnalysisTab);
            app.ResultsPanel_4.Title = 'Results';
            app.UIAxes7 = uiaxes(app.ResultsPanel_4);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.PredictionModelConstructionTab = uitab(app.TabGroup);
            app.PredictionModelConstructionTab.Title = 'Prediction Model Construction';
            app.ResultsPanel_3 = uipanel(app.PredictionModelConstructionTab);
            app.ResultsPanel_3.Title = 'Results';
            app.PredictionResultsPanel = uipanel(app.ResultsPanel_3);
            app.PredictionResultsPanel.Title = 'Prediction Results';
            app.UITable2 = uitable(app.PredictionResultsPanel);
            app.UITable2.ColumnName = {'id'; 'X'; 'Y'};
            app.UITable2.RowName = {};
            app.SaveResultsButton = uibutton(app.PredictionResultsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.Panel_showfitfig = uipanel(app.ResultsPanel_3);
            app.Panel_showfitfig.Title = 'Fit Plot';
            app.MetricResultPanel = uipanel(app.ResultsPanel_3);
            app.MetricResultPanel.Title = 'Metric Result';
            app.UITable2_2 = uitable(app.MetricResultPanel);
            app.UITable2_2.ColumnName = {'Results'; 'Value'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = true;
            app.SaveTrainedModelButton = uibutton(app.MetricResultPanel, 'push');
            app.SaveTrainedModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrainedModelButtonPushed, true);
            app.SaveTrainedModelButton.Text = 'Save Trained Model';
            app.SettingPanel = uipanel(app.PredictionModelConstructionTab);
            app.SettingPanel.Title = 'Setting';
            app.LoadDataPanel = uipanel(app.SettingPanel);
            app.LoadDataPanel.Title = 'Load Data';
            app.OutputDataYPanel = uipanel(app.LoadDataPanel);
            app.OutputDataYPanel.Title = 'Output Data (Y)';
            app.Tree_2 = uitree(app.OutputDataYPanel, 'checkbox');
            app.Tree_2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.InputDataXPanel = uipanel(app.LoadDataPanel);
            app.InputDataXPanel.Title = 'Input Data (X)';
            app.Tree = uitree(app.InputDataXPanel, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.PathEditFieldLabel = uilabel(app.LoadDataPanel);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.LoadDataPanel, 'text');
            app.LoadButton_2 = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.ModelSelectPanel = uipanel(app.SettingPanel);
            app.ModelSelectPanel.Title = 'Model Select';
            app.ModelsDropDownLabel = uilabel(app.ModelSelectPanel);
            app.ModelsDropDownLabel.Text = 'Models:';
            app.ModelsDropDown = uidropdown(app.ModelSelectPanel);
            app.ModelsDropDown.Items = {'BP', 'RF', 'GBDT', 'SER'};
            app.ModelsDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelsDropDownValueChanged, true);
            app.ModelsDropDown.Value = 'BP';
            app.TRatioSpinnerLabel = uilabel(app.ModelSelectPanel);
            app.TRatioSpinnerLabel.Text = 'TRatio:';
            app.TRatioSpinner = uispinner(app.ModelSelectPanel);
            app.TRatioSpinner.Step = 0.05;
            app.TRatioSpinner.Limits = [0 Inf];
            app.TRatioSpinner.Value = 0.8;
            app.TrainButton = uibutton(app.ModelSelectPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.TabGroup6 = uitabgroup(app.ModelSelectPanel);
            app.SettingsTab = uitab(app.TabGroup6);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup4 = uitabgroup(app.SettingsTab);
            app.BPTab = uitab(app.TabGroup4);
            app.BPTab.Title = 'BP';
            app.hiddenNumLabel = uilabel(app.BPTab);
            app.hiddenNumLabel.Text = 'hiddenNum:';
            app.hiddenNumSpinner = uispinner(app.BPTab);
            app.hiddenNumSpinner.Limits = [0 Inf];
            app.hiddenNumSpinner.Value = 10;
            app.lrSpinnerLabel = uilabel(app.BPTab);
            app.lrSpinnerLabel.Text = 'lr:';
            app.lrSpinner = uispinner(app.BPTab);
            app.lrSpinner.Step = 0.01;
            app.lrSpinner.Limits = [0 Inf];
            app.lrSpinner.Value = 0.01;
            app.goalSpinnerLabel = uilabel(app.BPTab);
            app.goalSpinnerLabel.Text = 'goal:';
            app.goalSpinner = uispinner(app.BPTab);
            app.goalSpinner.Step = 0.05;
            app.goalSpinner.Limits = [0 Inf];
            app.goalSpinner.Value = 0.0001;
            app.epochsSpinnerLabel = uilabel(app.BPTab);
            app.epochsSpinnerLabel.Text = 'epochs:';
            app.epochsSpinner = uispinner(app.BPTab);
            app.epochsSpinner.Step = 100;
            app.epochsSpinner.Limits = [0 Inf];
            app.epochsSpinner.Value = 1000;
            app.RFTab = uitab(app.TabGroup4);
            app.RFTab.Title = 'RF';
            app.MinLeafSizeSpinnerLabel = uilabel(app.RFTab);
            app.MinLeafSizeSpinnerLabel.Text = 'MinLeafSize:';
            app.MinLeafSizeSpinner = uispinner(app.RFTab);
            app.MinLeafSizeSpinner.Limits = [0 Inf];
            app.MinLeafSizeSpinner.Value = 5;
            app.nTreesSpinnerLabel = uilabel(app.RFTab);
            app.nTreesSpinnerLabel.Text = 'nTrees:';
            app.nTreesSpinner = uispinner(app.RFTab);
            app.nTreesSpinner.Limits = [0 Inf];
            app.nTreesSpinner.Value = 300;
            app.MaxNumSplitsSpinnerLabel = uilabel(app.RFTab);
            app.MaxNumSplitsSpinnerLabel.Text = 'MaxNumSplits:';
            app.MaxNumSplitsSpinner = uispinner(app.RFTab);
            app.MaxNumSplitsSpinner.Limits = [0 Inf];
            app.MaxNumSplitsSpinner.Value = 10;
            app.GBDTTab_2 = uitab(app.TabGroup4);
            app.GBDTTab_2.Title = 'GBDT';
            app.LearnRateSpinnerLabel = uilabel(app.GBDTTab_2);
            app.LearnRateSpinnerLabel.Text = 'LearnRate:';
            app.LearnRateSpinner = uispinner(app.GBDTTab_2);
            app.LearnRateSpinner.Step = 0.05;
            app.LearnRateSpinner.Limits = [0 1];
            app.LearnRateSpinner.Value = 0.1;
            app.MinLeafSizeSpinner_2Label = uilabel(app.GBDTTab_2);
            app.MinLeafSizeSpinner_2Label.Text = 'MinLeafSize:';
            app.MinLeafSizeSpinner_2 = uispinner(app.GBDTTab_2);
            app.MinLeafSizeSpinner_2.Limits = [0 Inf];
            app.MinLeafSizeSpinner_2.Value = 5;
            app.NumLearningCyclesSpinnerLabel = uilabel(app.GBDTTab_2);
            app.NumLearningCyclesSpinnerLabel.Text = 'NumLearningCycles:';
            app.NumLearningCyclesSpinner = uispinner(app.GBDTTab_2);
            app.NumLearningCyclesSpinner.Step = 100;
            app.NumLearningCyclesSpinner.Limits = [0 Inf];
            app.NumLearningCyclesSpinner.Value = 200;
            app.SERTab = uitab(app.TabGroup4);
            app.SERTab.Title = 'SER';
            app.ProcessingDropDown_3Label = uilabel(app.SERTab);
            app.ProcessingDropDown_3Label.Text = 'Processing:';
            app.ProcessingDropDown_3 = uidropdown(app.SERTab);
            app.ProcessingDropDown_3.Items = {'Raw', 'Logarithm', 'Reciprocal'};
            app.ProcessingDropDown_3.Value = 'Raw';
            app.UITable5 = uitable(app.SERTab);
            app.UITable5.ColumnName = {'Y'; 'X1'; 'X2'};
            app.UITable5.RowName = {};
            app.NumSpinnerLabel = uilabel(app.SERTab);
            app.NumSpinnerLabel.Text = 'Num:';
            app.NumSpinner = uispinner(app.SERTab);
            app.NumSpinner.Limits = [0 Inf];
            app.NumSpinner.ValueChangedFcn = createCallbackFcn(app, @NumSpinnerValueChanged, true);
            app.NumSpinner.Value = 2;
            app.Label = uilabel(app.SettingsTab);
            app.ModelStructureFlowchartTab = uitab(app.TabGroup6);
            app.ModelStructureFlowchartTab.Title = 'Model Structure Flowchart';
            app.ModelAxes = uiaxes(app.ModelStructureFlowchartTab);
            zlabel(app.ModelAxes, 'Z')
            app.SDLabel = uilabel(app.ModelSelectPanel);
            app.SDLabel.Text = 'SD:';
            app.SDSpinner = uispinner(app.ModelSelectPanel);
            app.SDSpinner.Step = 0.03;
            app.SDSpinner.Value = 0.03;
            app.NCheckBox = uicheckbox(app.ModelSelectPanel);
            app.NCheckBox.Text = 'N';
            app.NTrainCheckBox = uicheckbox(app.ModelSelectPanel);
            app.NTrainCheckBox.Text = 'NTrain';
            % Show the figure after all components are created
            app.VDMMSMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VDMMBESP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VDMMSMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VDMMSMUIFigure)
        end
    end
end